classdef Fourier_Filter_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputSignalFilterTabs          matlab.ui.container.TabGroup
        InputSignalTab                 matlab.ui.container.Tab
        UserInputSignalOptionsPanel    matlab.ui.container.Panel
        SignalModulationOptionsPanel   matlab.ui.container.Panel
        CenterFreqMHzEditField         matlab.ui.control.NumericEditField
        ViewCodeButton                 matlab.ui.control.Button
        SignalTypeDropDownLabel        matlab.ui.control.Label
        SignalTypeDropDown             matlab.ui.control.DropDown
        CenterFreqMHzSliderLabel       matlab.ui.control.Label
        CenterFreqMHzSlider            matlab.ui.control.Slider
        TimeDomainPanel                matlab.ui.container.Panel
        TimeDelayuSEditField           matlab.ui.control.NumericEditField
        AmpEditField                   matlab.ui.control.NumericEditField
        TimeDelayuSSliderLabel         matlab.ui.control.Label
        TimeDelayuSSlider              matlab.ui.control.Slider
        AmpSliderLabel                 matlab.ui.control.Label
        AmpSlider                      matlab.ui.control.Slider
        VarSliderLabel                 matlab.ui.control.Label
        VarSlider                      matlab.ui.control.Slider
        VarEditField                   matlab.ui.control.NumericEditField
        FilterTab                      matlab.ui.container.Tab
        UserInputFilterOptionsPanel    matlab.ui.container.Panel
        DropDownLabel                  matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        FilterDesignEditField          matlab.ui.control.EditField
        FourierFrequencyDomainPanel_2  matlab.ui.container.Panel
        CutoffFreqMHzSliderLabel       matlab.ui.control.Label
        CutoffFreqMHzSlider            matlab.ui.control.Slider
        FreqWidthMHzSliderLabel        matlab.ui.control.Label
        FreqWidthMHzSlider             matlab.ui.control.Slider
        CutoffFreqMHzEditField         matlab.ui.control.NumericEditField
        FreqWidthMHzEditField          matlab.ui.control.NumericEditField
        CutoffSlopeSliderLabel         matlab.ui.control.Label
        CutoffSlopeSlider              matlab.ui.control.Slider
        CutoffSlopeEditField           matlab.ui.control.NumericEditField
        FilterAxes                     matlab.ui.control.UIAxes
        TimeInAxes                     matlab.ui.control.UIAxes
        TimeOutAxes                    matlab.ui.control.UIAxes
        FreqInAxes                     matlab.ui.control.UIAxes
        FreqOutAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Fs = 128; %Sampling frequency
        t = 5; %Number of seconds
        L; %length of signal
        dx; %Time dom sampling
        T; %time dom vector
        F; %freq dom vector
        Fplot; %Half Spectrum
        
        Signal;
        Envelope;
        Transform;
        Filter;
    end
    
    methods (Access = private) 
        function lp = lowpass(app,F,Cutoff,Steepness)
            %F = Disretized Frequency Space Vector
            %cutoff = cutoff freq
            %steepness = DB/MHz decay rate for filter
            lp = zeros(1,length(F));
            lp(F<=Cutoff) = 1;
            lp(F>Cutoff) = 10.^(-Steepness*(F(F>Cutoff)-Cutoff)/20);
            flip = fliplr(lp);
            lp = [flip lp];
        end
    
        function hp = highpass(app,F,Cutoff,Steepness)
            %F = Disretized Frequency Space Vector
            %cutoff = cutoff freq
            %steepness = DB/MHz decay rate for filter
            hp = zeros(1,length(F));
            hp(F>=Cutoff) = 1;
            hp(F<Cutoff) = 10.^(Steepness*(F(F<Cutoff)-Cutoff)/20);
            flip = fliplr(hp);
            hp = [flip hp];
        end
        
        function bp = bandpass(app,F,Centerfreq,Steepness,Width)
            %F = Disretized Frequency Space Vector
            %cutoff = cutoff freq
            %steepness = DB/MHz decay rate for filter
            bp = ones(1,length(F));
            bp(F < Centerfreq-Width/2|F > Centerfreq+Width/2) = 0; %Zero outside width
            bp(F>Centerfreq+Width/2) = 10.^(-Steepness*(F(F>Centerfreq+Width/2)-Centerfreq-Width/2)/20); %Right decay
            bp(F<Centerfreq-Width/2) = 10.^(Steepness*(F(F<Centerfreq-Width/2)-Centerfreq+Width/2)/20); %Left Decay
            flip = fliplr(bp);
            bp = [flip bp];
        end
    
        function bs = bandstop(app,F,Centerfreq,Steepness,Width)
            %F = Disretized Frequency Space Vector
            %cutoff = cutoff freq
            %steepness = DB/MHz decay rate for filter
            bs = ones(1,length(F));
            bs(F > Centerfreq-Width/2&F < Centerfreq+Width/2) = 0; %Zero Inside band
            bs(F < Centerfreq+Width/2&F> Centerfreq) = 10.^(Steepness*(F(F < Centerfreq+Width/2&F > Centerfreq)-Centerfreq-Width/2)/20); %Right decay
            bs(F >= Centerfreq-Width/2&F < Centerfreq) = 10.^(-Steepness*(F(F >= Centerfreq-Width/2&F < Centerfreq)-Centerfreq+Width/2)/20);%Left Decay
            flip = fliplr(bs);
            bs = [flip bs];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Generate necessary properties
            app.L = app.Fs*app.t;
            app.dx = 1/app.Fs;
            app.T = (0:app.L-1)*app.dx;
            app.F = app.Fs*(-app.L/2:app.L/2-1)/app.L;
            app.Fplot = app.Fs*(0:app.L/2-1)/app.L; %Only plotting pos frequencies
            app.Filter = ones(size(app.F));
            
            %Shorthand equations
            Ft = @(x) fftshift(fft(x)); %Normalized fft
            iF = @(x) ifft(ifftshift(x));
            S = @(f) cos(f*2*pi*app.T); %Tone Burst
            
            app.TimeInAxes.reset  
            %Plot Time Domain with two y axes -> One linear scaling, the other in DB for the envelop
            f = app.CenterFreqMHzSlider.Value;
            app.Signal = S(f);
            app.Envelope = mag2db(abs(hilbert(app.Signal)));
            app.Transform = Ft(app.Signal);
            plotscale = max(abs(app.Transform));
            
            yyaxis(app.TimeInAxes,'left')
            plot(app.TimeInAxes,app.T,app.Signal,'b');
            title(app.TimeInAxes,'Time Domain')
            xlabel(app.TimeInAxes,'Time (uS)')
            ylabel(app.TimeInAxes,'Amplitude')
            set(app.TimeInAxes,{'ycolor'},{'b'})
            
            yyaxis(app.TimeInAxes,'right')
            plot(app.T,app.Envelope,'Color','r','LineStyle','--','Parent',app.TimeInAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeInAxes,'Decibel','Color','r')
            set(app.TimeInAxes,'YLim',[-60 0])
            set(app.TimeInAxes,{'ycolor'},{'r'})
            legend(app.TimeInAxes,'Signal', 'Envelope')
            
            %Input Fourier Spectrum
            plot(app.F,mag2db(abs(app.Transform)./plotscale), 'Parent', app.FreqInAxes,'Color','b')
            line(app.FreqInAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqInAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqInAxes,'Fourier Transform Input')
            xlabel(app.FreqInAxes,'Frequency (MHz)')
            ylabel(app.FreqInAxes,'Decibel')
            set(app.FreqInAxes,{'ycolor'},{'b'})
            legend(app.FreqInAxes,'Transform','-6DB')
            
            %Plot Filter
            filter = app.Filter;
            plotfilt = mag2db(filter);
            plot(app.FilterAxes,app.F,plotfilt)
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            %Output Fourier Spectrum
            Fout = abs(app.Transform) .* app.Filter;
            plot(app.F,mag2db(Fout./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            Rec = iF(Fout);
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            set(app.TimeOutAxes,'YLim',[-1 1])
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end

        % Value changed function: CenterFreqMHzSlider
        function CenterFreqMHzSliderValueChanged(app, event)
        f = app.CenterFreqMHzSlider.Value;
        Ft = @(x) fftshift(fft(x))./app.L; %Normalized fft
        iF = @(x) ifft(ifftshift(x)).*app.L;
        S = @(f) cos(f*2*pi*app.T); %Tone Burst
        
        %Relevant Calculations
        if app.SignalTypeDropDown.Value(1) == 'T'
            %Determine all outputs besides filter
            app.Signal = S(f);
            app.Envelope = mag2db(abs(hilbert(app.Signal)));
            app.Transform = Ft(app.Signal);
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            app.TimeInAxes.reset  
        end
            
        %Now Do plotting
        app.TimeInAxes.reset;
        yyaxis(app.TimeInAxes,'left')
        plot(app.TimeInAxes,app.T,app.Signal,'b');
        title(app.TimeInAxes,'Time Domain')
        xlabel(app.TimeInAxes,'Time (uS)')
        ylabel(app.TimeInAxes,'Amplitude')
        set(app.TimeInAxes,{'ycolor'},{'b'})
        
        yyaxis(app.TimeInAxes,'right')
        plot(app.T,app.Envelope,'Color','r','LineStyle','--','Parent',app.TimeInAxes);
        %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
        ylabel(app.TimeInAxes,'Decibel','Color','r')
        set(app.TimeInAxes,'YLim',[-60 0])
        set(app.TimeInAxes,{'ycolor'},{'r'})
        legend(app.TimeInAxes,'Signal', 'Envelope')
        
        %Input Fourier Spectrum
        app.FreqInAxes.reset;
        plot(app.F,mag2db(abs(app.Transform)), 'Parent', app.FreqInAxes,'Color','b')
        line(app.FreqInAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
        axis(app.FreqInAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FreqInAxes,'Fourier Transform Input')
        xlabel(app.FreqInAxes,'Frequency (MHz)')
        ylabel(app.FreqInAxes,'Decibel')
        set(app.FreqInAxes,{'ycolor'},{'b'})
        legend(app.FreqInAxes,'Transform','-6DB')
        
        %Plot Filter
        app.FilterAxes.reset;
        plot(app.FilterAxes,app.F,mag2db(app.Filter))
        axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FilterAxes,'Filter Design')
        xlabel(app.FilterAxes,'Frequency (MHz)')
        ylabel(app.FilterAxes,'Decibel')
        set(app.FilterAxes,{'ycolor'},{'b'})
        
        %Output Fourier Spectrum
        app.FreqOutAxes.reset;
        plot(app.F,mag2db(abs(Fout)), 'Parent', app.FreqOutAxes,'Color','b')
        line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
        axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FreqOutAxes,'Fourier Transform Input')
        xlabel(app.FreqOutAxes,'Frequency (MHz)')
        ylabel(app.FreqOutAxes,'Decibel')
        set(app.FreqOutAxes,{'ycolor'},{'b'})
        legend(app.FreqOutAxes,'Transform','-6DB')
        
        %Plot Output Time Signal
        app.TimeOutAxes.reset;
        yyaxis(app.TimeOutAxes,'left')
        plot(app.TimeOutAxes,app.T,real(Rec),'b');
        title(app.TimeOutAxes,'Time Domain')
        xlabel(app.TimeOutAxes,'Time (uS)')
        ylabel(app.TimeOutAxes,'Amplitude')
        set(app.TimeOutAxes,{'ycolor'},{'b'})
        
        yyaxis(app.TimeOutAxes,'right')
        plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
        %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
        ylabel(app.TimeOutAxes,'Decibel','Color','r')
        set(app.TimeOutAxes,'YLim',[-60 0])
        set(app.TimeOutAxes,{'ycolor'},{'r'})
        legend(app.TimeOutAxes,'Signal', 'Envelope')
        
        %Update Edit Field
        app.CenterFreqMHzEditField.Value = f;
        end

        % Value changed function: CenterFreqMHzEditField
        function CenterFreqMHzEditFieldValueChanged(app, event)
        f = app.CenterFreqMHzEditField.Value;
       %Relevant Calculations
        if app.SignalTypeDropDown.Value(1) == 'T'
            %Determine all outputs besides filter
            app.Signal = S(f);
            app.Envelope = mag2db(abs(hilbert(app.Signal)));
            app.Transform = Ft(app.Signal);
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            app.TimeInAxes.reset  
        end
            
        %Now Do plotting
        app.TimeInAxes.reset;
        yyaxis(app.TimeInAxes,'left')
        plot(app.TimeInAxes,app.T,app.Signal,'b');
        title(app.TimeInAxes,'Time Domain')
        xlabel(app.TimeInAxes,'Time (uS)')
        ylabel(app.TimeInAxes,'Amplitude')
        set(app.TimeInAxes,{'ycolor'},{'b'})
        
        yyaxis(app.TimeInAxes,'right')
        plot(app.T,app.Envelope,'Color','r','LineStyle','--','Parent',app.TimeInAxes);
        %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
        ylabel(app.TimeInAxes,'Decibel','Color','r')
        set(app.TimeInAxes,'YLim',[-60 0])
        set(app.TimeInAxes,{'ycolor'},{'r'})
        legend(app.TimeInAxes,'Signal', 'Envelope')
        
        %Input Fourier Spectrum
        app.FreqInAxes.reset;
        plot(app.F,mag2db(abs(app.Transform)), 'Parent', app.FreqInAxes,'Color','b')
        line(app.FreqInAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
        axis(app.FreqInAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FreqInAxes,'Fourier Transform Input')
        xlabel(app.FreqInAxes,'Frequency (MHz)')
        ylabel(app.FreqInAxes,'Decibel')
        set(app.FreqInAxes,{'ycolor'},{'b'})
        legend(app.FreqInAxes,'Transform','-6DB')
        
        %Plot Filter
        app.FilterAxes.reset;
        plot(app.FilterAxes,app.F,mag2db(app.Filter))
        axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FilterAxes,'Filter Design')
        xlabel(app.FilterAxes,'Frequency (MHz)')
        ylabel(app.FilterAxes,'Decibel')
        set(app.FilterAxes,{'ycolor'},{'b'})
        
        %Output Fourier Spectrum
        app.FreqOutAxes.reset;
        plot(app.F,mag2db(abs(Fout)), 'Parent', app.FreqOutAxes,'Color','b')
        line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
        axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
        title(app.FreqOutAxes,'Fourier Transform Input')
        xlabel(app.FreqOutAxes,'Frequency (MHz)')
        ylabel(app.FreqOutAxes,'Decibel')
        set(app.FreqOutAxes,{'ycolor'},{'b'})
        legend(app.FreqOutAxes,'Transform','-6DB')
        
        %Plot Output Time Signal
        app.TimeOutAxes.reset;
        yyaxis(app.TimeOutAxes,'left')
        plot(app.TimeOutAxes,app.T,real(Rec),'b');
        title(app.TimeOutAxes,'Time Domain')
        xlabel(app.TimeOutAxes,'Time (uS)')
        ylabel(app.TimeOutAxes,'Amplitude')
        set(app.TimeOutAxes,{'ycolor'},{'b'})
        set(app.TimeOutAxes,'YLim',[-1 1])
        
        yyaxis(app.TimeOutAxes,'right')
        plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
        %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
        ylabel(app.TimeOutAxes,'Decibel','Color','r')
        set(app.TimeOutAxes,'YLim',[-60 0])
        set(app.TimeOutAxes,{'ycolor'},{'r'})
        legend(app.TimeOutAxes,'Signal', 'Envelope')
            
        %Update Slider
        app.CenterFreqMHzSlider.Value = f;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            c = app.CutoffFreqMHzSlider.Value;
            s = app.CutoffSlopeSlider.Value;
            w = app.FreqWidthMHzSlider.Value;
            iF = @(x) ifft(ifftshift(x));
            
            %Turn everything off
            app.CutoffFreqMHzSlider.Enable = 'off';
            app.CutoffSlopeSlider.Enable = 'off';
            app.CutoffFreqMHzEditField.Enable = 'off';
            app.CutoffSlopeEditField.Enable = 'off';
            app.FreqWidthMHzSlider.Enable = 'off';
            app.FreqWidthMHzEditField.Enable = 'off';
            
            %Make Filter
            if value(1:6) == ("Low Pa")
                app.Filter = app.lowpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("High P")
                app.Filter = app.highpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band P")
                app.Filter = app.bandpass(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band S")
                app.Filter = app.bandstop(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            end
            
            plotscale = max(abs(app.Transform));
            %Plot Filter
            plot(app.FilterAxes,app.F,mag2db(app.Filter))
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            
            %Output Fourier Spectrum
            app.FreqOutAxes.reset;
            plot(app.F,mag2db(abs(Fout)./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            app.TimeOutAxes.reset;
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            set(app.TimeOutAxes,'YLim',[-1 1])
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end

        % Value changed function: CutoffFreqMHzSlider
        function CutoffFreqMHzSliderValueChanged(app, event)
            value = app.DropDown.Value;
            c = app.CutoffFreqMHzSlider.Value;
            s = app.CutoffSlopeSlider.Value;
            w = app.FreqWidthMHzSlider.Value;
            iF = @(x) ifft(ifftshift(x));
            
            %Turn everything off
            app.CutoffFreqMHzSlider.Enable = 'off';
            app.CutoffSlopeSlider.Enable = 'off';
            app.CutoffFreqMHzEditField.Enable = 'off';
            app.CutoffSlopeEditField.Enable = 'off';
            app.FreqWidthMHzSlider.Enable = 'off';
            app.FreqWidthMHzEditField.Enable = 'off';
            
            %Make Filter
            if value(1:6) == ("Low Pa")
                app.Filter = app.lowpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("High P")
                app.Filter = app.highpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band P")
                app.Filter = app.bandpass(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band S")
                app.Filter = app.bandstop(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            end
            
            plotscale = max(abs(app.Transform));
            %Plot Filter
            plot(app.FilterAxes,app.F,mag2db(app.Filter))
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            
            %Output Fourier Spectrum
            app.FreqOutAxes.reset;
            plot(app.F,mag2db(abs(Fout)./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            app.TimeOutAxes.reset;
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            set(app.TimeOutAxes,'YLim',[-1 1])
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end

        % Value changing function: CutoffFreqMHzSlider
        function CutoffFreqMHzSliderValueChanging(app, event)
            %Nope
        end

        % Value changed function: FreqWidthMHzSlider
        function FreqWidthMHzSliderValueChanged(app, event)
            value = app.DropDown.Value;
            c = app.CutoffFreqMHzSlider.Value;
            s = app.CutoffSlopeSlider.Value;
            w = app.FreqWidthMHzSlider.Value;
            iF = @(x) ifft(ifftshift(x));
            
            %Turn everything off
            app.CutoffFreqMHzSlider.Enable = 'off';
            app.CutoffSlopeSlider.Enable = 'off';
            app.CutoffFreqMHzEditField.Enable = 'off';
            app.CutoffSlopeEditField.Enable = 'off';
            app.FreqWidthMHzSlider.Enable = 'off';
            app.FreqWidthMHzEditField.Enable = 'off';
            
            %Make Filter
            if value(1:6) == ("Low Pa")
                app.Filter = app.lowpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("High P")
                app.Filter = app.highpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band P")
                app.Filter = app.bandpass(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band S")
                app.Filter = app.bandstop(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            end
            
             plotscale = max(abs(app.Transform));
            %Plot Filter
            plot(app.FilterAxes,app.F,mag2db(app.Filter))
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            
            %Output Fourier Spectrum
            app.FreqOutAxes.reset;
            plot(app.F,mag2db(abs(Fout)./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            app.TimeOutAxes.reset;
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            set(app.TimeOutAxes,'YLim',[-1 1])
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end

        % Value changed function: CutoffSlopeSlider
        function CutoffSlopeSliderValueChanged(app, event)
            value = app.DropDown.Value;
            c = app.CutoffFreqMHzSlider.Value;
            s = app.CutoffSlopeSlider.Value;
            w = app.FreqWidthMHzSlider.Value;
            iF = @(x) ifft(ifftshift(x));
            
            %Turn everything off
            app.CutoffFreqMHzSlider.Enable = 'off';
            app.CutoffSlopeSlider.Enable = 'off';
            app.CutoffFreqMHzEditField.Enable = 'off';
            app.CutoffSlopeEditField.Enable = 'off';
            app.FreqWidthMHzSlider.Enable = 'off';
            app.FreqWidthMHzEditField.Enable = 'off';
            
            %Make Filter
            if value(1:6) == ("Low Pa")
                app.Filter = app.lowpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("High P")
                app.Filter = app.highpass(app.Fplot,c,s);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band P")
                app.Filter = app.bandpass(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            elseif value(1:6) == ("Band S")
                app.Filter = app.bandstop(app.Fplot,c,s,w);
                %Turn on neccesary options
                app.CutoffFreqMHzSlider.Enable = 'on';
                app.CutoffSlopeSlider.Enable = 'on';
                app.CutoffFreqMHzEditField.Enable = 'on';
                app.CutoffSlopeEditField.Enable = 'on';
                app.FreqWidthMHzSlider.Enable = 'on';
                app.FreqWidthMHzEditField.Enable = 'on';
                
            end
            
             plotscale = max(abs(app.Transform));
            %Plot Filter
            plot(app.FilterAxes,app.F,mag2db(app.Filter))
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            Fout = app.Transform.*app.Filter;
            Rec = iF(Fout);
            
            %Output Fourier Spectrum
            app.FreqOutAxes.reset;
            plot(app.F,mag2db(abs(Fout)./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            app.TimeOutAxes.reset;
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            set(app.TimeOutAxes,'YLim',[-1 1])
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end

        % Value changed function: SignalTypeDropDown
        function SignalTypeDropDownValueChanged(app, event)
            value = app.SignalTypeDropDown.Value;
            
            f = app.CenterFreqMHzSlider.Value;
            v = app.VarSlider.Value;
            o = 4; %Order always fixed for super gaussian
            del = app.TimeDelayuSSlider.Value;
            
            Ft = @(x) fftshift(fft(x)); %Normalized fft
            iF = @(x) ifft(ifftshift(x));
            S = @(f) cos(f*2*pi*app.T); %Tone Burst
            G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*2*pi*app.T);
            eG = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2); %Envelope
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(o))).*cos(f*2*pi*app.T);
            eSG =  @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(o)));

            %Relevant Calculations
            if value(1) == 'T'
                %Determine all outputs besides filter
                app.Signal = S(f);
                app.Envelope = mag2db(abs(hilbert(app.Signal)));
                app.Transform = Ft(app.Signal);
                Fout = app.Transform.*app.Filter;
                Rec = iF(Fout);
                
            elseif value(1) == 'G'
                app.Signal = G(v,f);
                app.Envelope = mag2db(abs(hilbert(app.Signal)));
                app.Transform = Ft(app.Signal);
                Fout = app.Transform.*app.Filter;
                Rec = iF(Fout);
           
           elseif value(1) == 'S'
                app.Signal = SG(v,o,f);
                app.Envelope = mag2db(abs(hilbert(app.Signal)));
                app.Transform = Ft(app.Signal);
                Fout = app.Transform.*app.Filter;
                Rec = iF(Fout);
                
          elseif value(1) == 'I'
              %From Fourier Transform Script
                transform = zeros(1,app.L);
                [minValPos, ClosestIndexPos] = min(abs(app.T-del*ones(size(app.T))));
                transform(1,[ClosestIndexPos]) = 1;
                app.Signal = transform;
                app.Envelope = mag2db(abs(hilbert(app.Signal)));
                app.Transform = Ft(app.Signal);
                Fout = app.Transform.*app.Filter;
                Rec = iF(Fout);
            end
            plotscale = max(abs(app.Transform));   
            %Now Do plotting
            app.TimeInAxes.reset;
            yyaxis(app.TimeInAxes,'left')
            plot(app.TimeInAxes,app.T,app.Signal,'b');
            title(app.TimeInAxes,'Time Domain')
            xlabel(app.TimeInAxes,'Time (uS)')
            ylabel(app.TimeInAxes,'Amplitude')
            set(app.TimeInAxes,{'ycolor'},{'b'})
            
            yyaxis(app.TimeInAxes,'right')
            plot(app.T,app.Envelope,'Color','r','LineStyle','--','Parent',app.TimeInAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeInAxes,'Decibel','Color','r')
            set(app.TimeInAxes,'YLim',[-60 0])
            set(app.TimeInAxes,{'ycolor'},{'r'})
            legend(app.TimeInAxes,'Signal', 'Envelope')
            
            %Input Fourier Spectrum
            app.FreqInAxes.reset;
            plot(app.F,mag2db(abs(app.Transform)./plotscale), 'Parent', app.FreqInAxes,'Color','b')
            line(app.FreqInAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqInAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqInAxes,'Fourier Transform Input')
            xlabel(app.FreqInAxes,'Frequency (MHz)')
            ylabel(app.FreqInAxes,'Decibel')
            set(app.FreqInAxes,{'ycolor'},{'b'})
            legend(app.FreqInAxes,'Transform','-6DB')
            
            %Plot Filter
            app.FilterAxes.reset;
            plot(app.FilterAxes,app.F,mag2db(app.Filter))
            axis(app.FilterAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FilterAxes,'Filter Design')
            xlabel(app.FilterAxes,'Frequency (MHz)')
            ylabel(app.FilterAxes,'Decibel')
            set(app.FilterAxes,{'ycolor'},{'b'})
            
            %Output Fourier Spectrum
            app.FreqOutAxes.reset;
            plot(app.F,mag2db(abs(Fout)./plotscale), 'Parent', app.FreqOutAxes,'Color','b')
            line(app.FreqOutAxes,[0,16],[-6,-6],'Color','k','LineStyle','--')
            axis(app.FreqOutAxes,[0 20 -60 5]) %Only Plotting positive values
            title(app.FreqOutAxes,'Fourier Transform Input')
            xlabel(app.FreqOutAxes,'Frequency (MHz)')
            ylabel(app.FreqOutAxes,'Decibel')
            set(app.FreqOutAxes,{'ycolor'},{'b'})
            legend(app.FreqOutAxes,'Transform','-6DB')
            
            %Plot Output Time Signal
            app.TimeOutAxes.reset;
            yyaxis(app.TimeOutAxes,'left')
            plot(app.TimeOutAxes,app.T,real(Rec),'b');
            title(app.TimeOutAxes,'Time Domain')
            xlabel(app.TimeOutAxes,'Time (uS)')
            ylabel(app.TimeOutAxes,'Amplitude')
            set(app.TimeOutAxes,{'ycolor'},{'b'})
            
            yyaxis(app.TimeOutAxes,'right')
            plot(app.T,mag2db(abs(hilbert(Rec))),'Color','r','LineStyle','--','Parent',app.TimeOutAxes);
            %line(app.TimeInAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeOutAxes,'Decibel','Color','r')
            set(app.TimeOutAxes,'YLim',[-60 0])
            set(app.TimeOutAxes,{'ycolor'},{'r'})
            legend(app.TimeOutAxes,'Signal', 'Envelope')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputSignalFilterTabs = uitabgroup(app.UIFigure);
            app.InputSignalTab = uitab(app.InputSignalFilterTabs);
            app.InputSignalTab.Title = 'Input Signal';
            app.UserInputSignalOptionsPanel = uipanel(app.InputSignalTab);
            app.UserInputSignalOptionsPanel.Title = 'User Input Signal Options';
            app.SignalModulationOptionsPanel = uipanel(app.UserInputSignalOptionsPanel);
            app.SignalModulationOptionsPanel.Title = 'Signal Modulation Options';
            app.CenterFreqMHzEditField = uieditfield(app.SignalModulationOptionsPanel, 'numeric');
            app.CenterFreqMHzEditField.Limits = [1 10];
            app.CenterFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFreqMHzEditFieldValueChanged, true);
            app.CenterFreqMHzEditField.Value = 5;
            app.ViewCodeButton = uibutton(app.SignalModulationOptionsPanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
            app.SignalTypeDropDownLabel = uilabel(app.UserInputSignalOptionsPanel);
            app.SignalTypeDropDownLabel.Text = 'Signal Type';
            app.SignalTypeDropDown = uidropdown(app.UserInputSignalOptionsPanel);
            app.SignalTypeDropDown.Items = {'Tone Burst', 'Gaussian', 'Super-Gaussian', 'Impulse'};
            app.SignalTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalTypeDropDownValueChanged, true);
            app.SignalTypeDropDown.Value = 'Tone Burst';
            app.CenterFreqMHzSliderLabel = uilabel(app.UserInputSignalOptionsPanel);
            app.CenterFreqMHzSliderLabel.Text = {'Center'; 'Freq.'; '(MHz)'};
            app.CenterFreqMHzSlider = uislider(app.UserInputSignalOptionsPanel);
            app.CenterFreqMHzSlider.Limits = [1 10];
            app.CenterFreqMHzSlider.ValueChangedFcn = createCallbackFcn(app, @CenterFreqMHzSliderValueChanged, true);
            app.CenterFreqMHzSlider.Value = 5;
            app.TimeDomainPanel = uipanel(app.InputSignalTab);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.TimeDelayuSEditField = uieditfield(app.TimeDomainPanel, 'numeric');
            app.TimeDelayuSEditField.Value = 5;
            app.AmpEditField = uieditfield(app.TimeDomainPanel, 'numeric');
            app.AmpEditField.Value = 1;
            app.TimeDelayuSSliderLabel = uilabel(app.TimeDomainPanel);
            app.TimeDelayuSSliderLabel.Text = {'Time'; 'Delay'; '(uS)'};
            app.TimeDelayuSSlider = uislider(app.TimeDomainPanel);
            app.TimeDelayuSSlider.Limits = [1 5];
            app.TimeDelayuSSlider.Value = 2.5;
            app.AmpSliderLabel = uilabel(app.TimeDomainPanel);
            app.AmpSliderLabel.Text = 'Amp';
            app.AmpSlider = uislider(app.TimeDomainPanel);
            app.AmpSlider.Limits = [1 10];
            app.AmpSlider.Value = 1;
            app.VarSliderLabel = uilabel(app.TimeDomainPanel);
            app.VarSliderLabel.Text = 'Var';
            app.VarSlider = uislider(app.TimeDomainPanel);
            app.VarSlider.Limits = [0.25 1];
            app.VarSlider.Enable = 'off';
            app.VarSlider.Value = 0.25;
            app.VarEditField = uieditfield(app.TimeDomainPanel, 'numeric');
            app.VarEditField.Enable = 'off';
            app.VarEditField.Value = 0.25;
            app.FilterTab = uitab(app.InputSignalFilterTabs);
            app.FilterTab.Title = 'Filter Type';
            app.UserInputFilterOptionsPanel = uipanel(app.FilterTab);
            app.UserInputFilterOptionsPanel.Title = 'User Input Filter Options';
            app.DropDownLabel = uilabel(app.UserInputFilterOptionsPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UserInputFilterOptionsPanel);
            app.DropDown.Items = {'No Filter', 'Low Pass', 'High Pass', 'Band Pass', 'Band Stop', 'Gaussian', ''};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'No Filter';
            app.FilterDesignEditField = uieditfield(app.UserInputFilterOptionsPanel, 'text');
            app.FilterDesignEditField.Editable = 'off';
            app.FilterDesignEditField.Value = 'Filter Design';
            app.FourierFrequencyDomainPanel_2 = uipanel(app.FilterTab);
            app.FourierFrequencyDomainPanel_2.Title = 'Fourier (Frequency) Domain';
            app.CutoffFreqMHzSliderLabel = uilabel(app.FourierFrequencyDomainPanel_2);
            app.CutoffFreqMHzSliderLabel.Text = {'Cutoff'; 'Freq.'; '(MHz)'};
            app.CutoffFreqMHzSlider = uislider(app.FourierFrequencyDomainPanel_2);
            app.CutoffFreqMHzSlider.Limits = [1 20];
            app.CutoffFreqMHzSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFreqMHzSliderValueChanged, true);
            app.CutoffFreqMHzSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffFreqMHzSliderValueChanging, true);
            app.CutoffFreqMHzSlider.Enable = 'off';
            app.CutoffFreqMHzSlider.Value = 1;
            app.FreqWidthMHzSliderLabel = uilabel(app.FourierFrequencyDomainPanel_2);
            app.FreqWidthMHzSliderLabel.Text = {'Freq.'; 'Width'; '(MHz)'};
            app.FreqWidthMHzSlider = uislider(app.FourierFrequencyDomainPanel_2);
            app.FreqWidthMHzSlider.Limits = [1 20];
            app.FreqWidthMHzSlider.ValueChangedFcn = createCallbackFcn(app, @FreqWidthMHzSliderValueChanged, true);
            app.FreqWidthMHzSlider.Enable = 'off';
            app.FreqWidthMHzSlider.Value = 1;
            app.CutoffFreqMHzEditField = uieditfield(app.FourierFrequencyDomainPanel_2, 'numeric');
            app.CutoffFreqMHzEditField.Enable = 'off';
            app.CutoffFreqMHzEditField.Value = 1;
            app.FreqWidthMHzEditField = uieditfield(app.FourierFrequencyDomainPanel_2, 'numeric');
            app.FreqWidthMHzEditField.Enable = 'off';
            app.FreqWidthMHzEditField.Value = 1;
            app.CutoffSlopeSliderLabel = uilabel(app.FourierFrequencyDomainPanel_2);
            app.CutoffSlopeSliderLabel.Text = {'Cutoff'; 'Slope'};
            app.CutoffSlopeSlider = uislider(app.FourierFrequencyDomainPanel_2);
            app.CutoffSlopeSlider.Limits = [5 20];
            app.CutoffSlopeSlider.ValueChangedFcn = createCallbackFcn(app, @CutoffSlopeSliderValueChanged, true);
            app.CutoffSlopeSlider.Enable = 'off';
            app.CutoffSlopeSlider.Value = 5;
            app.CutoffSlopeEditField = uieditfield(app.FourierFrequencyDomainPanel_2, 'numeric');
            app.CutoffSlopeEditField.Enable = 'off';
            app.CutoffSlopeEditField.Value = 1;
            app.FilterAxes = uiaxes(app.FourierFrequencyDomainPanel_2);
            title(app.FilterAxes, 'Filter Design')
            xlabel(app.FilterAxes, 'Frequency')
            ylabel(app.FilterAxes, 'Decibel')
            app.TimeInAxes = uiaxes(app.UIFigure);
            title(app.TimeInAxes, {'Time Signal: Input'; ''})
            xlabel(app.TimeInAxes, 'Time (uS)')
            ylabel(app.TimeInAxes, 'Amplitude')
            app.TimeOutAxes = uiaxes(app.UIFigure);
            title(app.TimeOutAxes, 'Time Signal: Output')
            xlabel(app.TimeOutAxes, 'Time (uS)')
            ylabel(app.TimeOutAxes, 'Amplitude')
            app.FreqInAxes = uiaxes(app.UIFigure);
            title(app.FreqInAxes, {'Freq. Domain: Input'; ''})
            xlabel(app.FreqInAxes, 'Frequency (MHz)')
            ylabel(app.FreqInAxes, 'Decibel')
            app.FreqOutAxes = uiaxes(app.UIFigure);
            title(app.FreqOutAxes, 'Freq. Domain: Output')
            xlabel(app.FreqOutAxes, 'Frequency (MHz)')
            ylabel(app.FreqOutAxes, 'Decibel')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fourier_Filter_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end