classdef SigFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        outputtimegraph             matlab.ui.control.UIAxes
        filtergraph                 matlab.ui.control.UIAxes
        inputtimegraph              matlab.ui.control.UIAxes
        outputFgraph                matlab.ui.control.UIAxes
        filterFgraph                matlab.ui.control.UIAxes
        inputFgraph                 matlab.ui.control.UIAxes
        TabGroup                    matlab.ui.container.TabGroup
        InputSignalTab              matlab.ui.container.Tab
        WaveforrmDropDownLabel      matlab.ui.control.Label
        WaveforrmDropDown           matlab.ui.control.DropDown
        fcSliderLabel               matlab.ui.control.Label
        fcSlider                    matlab.ui.control.Slider
        BrustLengthSliderLabel      matlab.ui.control.Label
        BrustLengthSlider           matlab.ui.control.Slider
        AmplitudeSliderLabel        matlab.ui.control.Label
        AmplitudeSlider             matlab.ui.control.Slider
        EditFieldfc                 matlab.ui.control.NumericEditField
        EditFieldamp                matlab.ui.control.NumericEditField
        EditFieldBrust              matlab.ui.control.NumericEditField
        MoreInfoInSig               matlab.ui.control.StateButton
        FilterTab                   matlab.ui.container.Tab
        FilterTypeDropDownLabel     matlab.ui.control.Label
        FilterTypeDropDown          matlab.ui.control.DropDown
        LowSliderLabel              matlab.ui.control.Label
        LowSlider                   matlab.ui.control.Slider
        HighSliderLabel             matlab.ui.control.Label
        HighSlider                  matlab.ui.control.Slider
        FilterDesignEditFieldLabel  matlab.ui.control.Label
        FilterDesignEditField       matlab.ui.control.EditField
        WidthSliderLabel            matlab.ui.control.Label
        WidthSlider                 matlab.ui.control.Slider
        FilterInfoButton            matlab.ui.control.Button
        SpanSliderLabel             matlab.ui.control.Label
        SpanSlider                  matlab.ui.control.Slider
        SamplesSliderLabel          matlab.ui.control.Label
        SamplesSlider               matlab.ui.control.Slider
        EditFieldLowF               matlab.ui.control.NumericEditField
        EditFieldSpan               matlab.ui.control.NumericEditField
        EditFieldHiF                matlab.ui.control.NumericEditField
        EditFieldSamples            matlab.ui.control.NumericEditField
        EditFieldWidth              matlab.ui.control.NumericEditField
        dBLabel_8                   matlab.ui.control.Label
        dBi6EditField               matlab.ui.control.NumericEditField
        dBLabel_9                   matlab.ui.control.Label
        dBi10EditField              matlab.ui.control.NumericEditField
        dBLabel_10                  matlab.ui.control.Label
        dBi20EditField              matlab.ui.control.NumericEditField
        ENVPLLabel_3                matlab.ui.control.Label
        dBLabel_11                  matlab.ui.control.Label
        dBf6EditField               matlab.ui.control.NumericEditField
        dBLabel_12                  matlab.ui.control.Label
        dBf10EditField              matlab.ui.control.NumericEditField
        dBLabel_13                  matlab.ui.control.Label
        dBf20EditField              matlab.ui.control.NumericEditField
        dBLabel_3                   matlab.ui.control.Label
        Edito6Field                 matlab.ui.control.NumericEditField
        dBLabel_2                   matlab.ui.control.Label
        Edito10Field                matlab.ui.control.NumericEditField
        dBLabel                     matlab.ui.control.Label
        Edito20Field                matlab.ui.control.NumericEditField
        dBLabel_4                   matlab.ui.control.Label
        Edito6FieldF                matlab.ui.control.NumericEditField
        dBLabel_5                   matlab.ui.control.Label
        Edito10FieldF               matlab.ui.control.NumericEditField
        dBLabel_7                   matlab.ui.control.Label
        Edito20FieldF               matlab.ui.control.NumericEditField
        ENVPLLabel_2                matlab.ui.control.Label
        ENVPLLabel_4                matlab.ui.control.Label
        dBBWLabel_2                 matlab.ui.control.Label
        dBi6BWperc                  matlab.ui.control.NumericEditField
        dBfcLabel_2                 matlab.ui.control.Label
        dBi6fc                      matlab.ui.control.NumericEditField
        SpectralMaxLabel_2          matlab.ui.control.Label
        dBiSpecMax                  matlab.ui.control.NumericEditField
        dBBWLabel_3                 matlab.ui.control.Label
        dBf6BWperc                  matlab.ui.control.NumericEditField
        dBfcLabel_3                 matlab.ui.control.Label
        dBf6fc                      matlab.ui.control.NumericEditField
        SpectralMaxLabel_3          matlab.ui.control.Label
        dBfSpecMax                  matlab.ui.control.NumericEditField
        dBBWLabel_4                 matlab.ui.control.Label
        dBo6BWperc                  matlab.ui.control.NumericEditField
        dBfcLabel_4                 matlab.ui.control.Label
        dBo6fc                      matlab.ui.control.NumericEditField
        SpectralMaxLabel_4          matlab.ui.control.Label
        dBoSpecMax                  matlab.ui.control.NumericEditField
        dBLabel_14                  matlab.ui.control.Label
        dBi6EditFieldF              matlab.ui.control.NumericEditField
        dBLabel_15                  matlab.ui.control.Label
        dBi10EditFieldF             matlab.ui.control.NumericEditField
        dBLabel_16                  matlab.ui.control.Label
        dBi20EditFieldF             matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        t
        fc = 5
        Amp = 1;
        f = 5;
        delt = 0.01;
        fs = 10*5;
        rate = 10
        
        isAngle = false;
        pulselen = 1;
        filterMode = 'Low Pass';
        inputMode = 'Tone';
        tabMode = 'Input Signal';
        InSig
        groupdelay
        InFreq
        InSigModeInfo
        SpecMax = 0;
        %filters
        h
        hcoeff = 1
        LowF = 4
        HiF = 6
        filtermag
        filterf
        % Gaussian Filter
        bt=0.3
        span=16
        sps=8
        
        % Outlook
        VisibleNonInSig_PosCol % save a collection of positions
        backuphandlefunc % saves a collection of handles
    end
    
    methods (Access = public)
        
        function RefreashTab(app)
            switch app.tabMode
                case 'Input Signal'
                    Refreash(app)
                case 'Filter'
                    RefreashFilter(app)
            end
        end
        
        function Refreash(app)
            setFc(app,app.fc);
            switch app.inputMode
                case 'Tone'
                    fcSliderVisible(app,'on')
                    BrustLengthSliderVisible(app,'off')
                    ExponentSliderVisible(app,'off')
                    toneInSig(app);
                case 'Tone Burst'
                    fcSliderVisible(app,'on')
                    BrustLengthSliderVisible(app,'on')
                    ExponentSliderVisible(app,'off')
                    toneBurstInSig(app);
                case 'Gaussian Pulse'
                    fcSliderVisible(app,'on')
                    BrustLengthSliderVisible(app,'off')
                    ExponentSliderVisible(app,'off')
                    GaussianPulse(app);
                case 'Impulse'
                    fcSliderVisible(app,'off')
                    BrustLengthSliderVisible(app,'off')
                    ExponentSliderVisible(app,'off')
                    impulseInSig(app);
                case 'Super Gaussian'
                    fcSliderVisible(app,'on')
                    BrustLengthSliderVisible(app,'off')
                    ExponentSliderVisible(app,'on')
                    superInSig(app);
            end
        end
        
        function BrustLengthSliderVisible(app,isEnable)
            app.BrustLengthSlider.Visible = isEnable;
            app.BrustLengthSliderLabel.Visible = isEnable;
            app.EditFieldBrust.Visible = isEnable;
        end
        
        function ExponentSliderVisible(app,isEnable)
            % use amplitude as pulse order
            if (isEnable=="on")
                app.AmplitudeSliderLabel.Text = "Pulse Order";
            else
                app.AmplitudeSliderLabel.Text = "Amplitude";
            end
        end
        
        function fcSliderVisible(app,isEnable)
            app.fcSlider.Visible = isEnable;
            app.fcSliderLabel.Visible = isEnable;
            app.EditFieldfc.Visible = isEnable;
        end
        
        function HighSliderVisible(app,isEnable)
            app.HighSlider.Visible = isEnable;
            app.HighSliderLabel.Visible = isEnable;
            app.EditFieldHiF.Visible = isEnable;
        end
        
        function LowSliderVisible(app,isEnable)
            app.LowSlider.Visible = isEnable;
            app.LowSliderLabel.Visible = isEnable;
            app.EditFieldLowF.Visible = isEnable;
        end
        
        function GaussianVisible(app,isVisible)
            app.WidthSlider.Visible = isVisible;
            app.WidthSliderLabel.Visible = isVisible;
            app.EditFieldWidth.Visible = isVisible;
            app.SpanSliderLabel.Visible = isVisible;
            app.SpanSlider.Visible = isVisible;
            app.EditFieldSpan.Visible = isVisible;
            
            %             app.SamplesSlider.Visible = isVisible;
            %             app.SamplesSliderLabel.Visible = isVisible;
            %             app.EditFieldSamples.Visible = isVisible;
        end
        
        function setFc(app,freq)
            app.fc = InRange(app, freq, app.fcSlider.Limits, app.fc);
            app.fcSlider.Value = app.fc;
            app.EditFieldfc.Value = app.fc;
        end
        
        function setLowF(app,freq)
            app.LowF = InRange(app, freq, app.LowSlider.Limits, app.LowF);
            app.LowSlider.Value = app.LowF;
            app.EditFieldLowF.Value = app.LowF;
        end
        
        function setHiF(app,freq)
            app.HiF = InRange(app, freq, app.HighSlider.Limits, app.HiF);
            app.HighSlider.Value = app.HiF;
            app.EditFieldHiF.Value = app.HiF;
        end
        
        function RefreashFilter(app)
            if isempty(app.InSig)
                toneInSig(app);
            end
            setFc(app,app.fc)
            switch app.filterMode
                case 'Low Pass'
                    LowSliderVisible(app,'on')
                    HighSliderVisible(app,'off')
                    GaussianVisible(app,'off')
                    app.LowSlider.Limits = [0, ceil(app.fc*2)];
                    setLowF(app,app.LowF)
                    lowpassFilter(app)
                    GraphFilter(app)
                case 'High Pass'
                    LowSliderVisible(app,'off')
                    HighSliderVisible(app,'on')
                    GaussianVisible(app,'off')
                    app.HighSlider.Limits = [ceil(app.fc*2)*0.25, ceil(app.fc*2)];
                    setHiF(app,app.HiF)
                    highpassFilter(app)
                    GraphFilter(app)
                case 'Band Pass'
                    LowSliderVisible(app,'on')
                    HighSliderVisible(app,'on')
                    GaussianVisible(app,'off')
                    app.LowSlider.Limits = [ceil(app.fc*2)*0.25, ceil(app.fc*2)];
                    app.HighSlider.Limits = [ceil(app.fc*2)*0.25, ceil(app.fc*2)];
                    bandpassFilter(app)
                    GraphFilter(app)
                case 'Band Stop'
                    LowSliderVisible(app,'on')
                    HighSliderVisible(app,'on')
                    GaussianVisible(app,'off')
                    app.LowSlider.Limits = [0, ceil(app.fc*2)];
                    app.HighSlider.Limits = [ceil(app.fc*2)*0.25, ceil(app.fc*2)];
                    bandstopFilter(app)
                    GraphFilter(app)
                case 'Gaussian'
                    LowSliderVisible(app,'off')
                    HighSliderVisible(app,'off')
                    GaussianVisible(app,'on')
                    GaussianFilter(app)
                    GraphFilter(app)
            end
        end
        
        function toneInSig(app)
            if (app.fc > 0)
                nt = round(app.fc/app.delt);
                app.t = linspace(-1,5,nt+1);
                app.fs = nt+1/6;
                app.f = linspace(-nt/2,nt/2,nt+1)*1/6;
                app.InSig=app.Amp*sin(2*pi*app.fc*app.t); %  continuous tone waveform
                %app.Infreq =
                
                GraphInputSig(app)
                
                app.inputtimegraph.XLim = [0,4];
                yyaxis(app.inputtimegraph,'right')
                %                 app.inputtimegraph.YLim = [-1,1];
            end
        end
        
        
        function toneBurstInSig(app)
            if (app.fc > 1) % ban below 1 in GUI --------- GUI Action required
                ps = app.pulselen*3/(4*1.87); % adj value to match GUI 3/(4*2) 4->app.f 3->pulse2 2->constant
                nt = ps*app.fc/app.delt;
                NoP = ps/app.fc; % # of pulse
                app.t = linspace(0,4*NoP,nt+1);
                app.f = linspace(-nt/2,nt/2,nt+1)*1/(4*NoP);
                pulse1=app.Amp*cos(2*pi*app.t*app.fc); %  cw source waveform
                pulse2=rectangularPulse(0.5*NoP,3*NoP,app.t);
                app.InSig = pulse2.*pulse1;
                GraphInputSig(app)
            end
        end
        
        function GaussianPulse(app)
            if (app.fc > 0)
                a=20.0;
                nt = round(app.fc/app.delt);
                app.t = linspace(0,3,nt+1);
                app.f = linspace(-nt/2,nt/2,nt+1)*1/(3);
                tdelay=1.5;
                td=tdelay*ones(1,nt+1);
                tt=app.t-td;
                app.InSig=(app.Amp*exp(-a*tt.*tt).*cos(2*pi*app.fc*tt));
                GraphInputSig(app)
            end
        end
        
        
        function impulseInSig(app)
            setFc(app,3)
            nt = round(app.fc/app.delt);
            app.t = linspace(0,3,nt+1);
            app.f = linspace(-nt/2,nt/2,nt+1)*1/(3);
            app.InSig=zeros(1,nt+1);
            app.InSig(150)=app.Amp;
            cla(app.inputtimegraph)
            yyaxis(app.inputtimegraph,'left')
            plot(app.inputtimegraph,app.t,app.InSig,'DisplayName','Input Signal');
            hold(app.inputtimegraph,'on')
            yyaxis(app.inputtimegraph,'right')
            % Prof edited envelope is the same as the input signal
            % although its same for just the real part
            plot(app.inputtimegraph,app.t,mag2db(real(hilbert(app.InSig))),'DisplayName','Envelope');
            app.inputtimegraph.YLim = [-30, 5];
            hold(app.inputtimegraph,'off')
            %legend(app.inputtimegraph)
            %             if(~app.isAngle)
            a5=mag2db(abs(fftshift(fft(app.delt*app.InSig))));
            app.inputFgraph.XLim = [-2*app.fc,2*app.fc];
            ytickformat(app.inputFgraph,'%.1f')
            ylabel(app.inputFgraph,"Spectral Mag (dB)");
            %app.inputFgraph.YLim = [0,abs((app.Amp+1)/50)];
            %             else
            %                 a5=angle(fftshift(fft(app.delt*app.InSig)));
            %                 app.inputFgraph.XLim = [-2*app.fc,2*app.fc];
            %                 app.inputFgraph.YLimMode = 'auto';
            %                 ylabel(app.inputFgraph,"Phase (rad)");
            %             end
            plot(app.inputFgraph,app.f,a5);
            GraphResult(app)
        end
        
        function superInSig(app)
            if (app.fc>0)
                %app.fc = 3;
                nt = round(app.fc/app.delt);
                app.t = linspace(0,3,nt);
                app.f = linspace(-nt/2,nt/2,nt)*1/(3);
                %                 tdelay=nt*app.delt/2.0;                   %  shift to middle of range
                %                 td=tdelay*ones(1,nt);                  %  create delay array
                %                 tt=app.t-td;
                m=app.Amp;
                T0 = 1/(2*(log(2)^(1/(2*m))));
                app.InSig = abs(exp(-((1 + 1j)/2)*(app.t/T0).^(2*m)));
                app.InSig = [fliplr(app.InSig(1:end/2)),app.InSig(1:end/2)].^2;
                %                 app.InSig=mag2db(exp(-app.Amp*tt.*tt));
                GraphInputSig(app)
            end
        end
        
        
        function [perc,dbfc,smax] = spectralAnalysis(app,x,y)
            [perc,dbfc,smax] = deal(0);
            if (app.inputMode == "Tone" || min(y)>-6)
                return
            end
            xx = x(x>=0);
            yy = y(x>=0);
            bwdb = -6;
            if (min(yy)<bwdb && max(yy)>bwdb)
                smax = max(yy)-min(yy);
                if (isnan(smax))
                    smax = 0;
                end
                [XL,XR] = BW(app,xx,yy,bwdb);
                if(XR~=0)
                    dbfc = XL+(XR-XL)/2;
                    if (isnan(dbfc))
                        dbfc = 0;
                    end
                    perc = 100*(XR-XL)/dbfc;
                    if (isnan(perc))
                        perc = 0;
                    end
                end
            end
        end
        function BWidth = beamwidth(app,x,y)
            % input are all single row array
            %             fc6db = 0;
            %             Percent6db = 0;
            BWidth = zeros(1,3);
            if ( min(y)>-6 )
                return
            end
            
            bwdb = [-6; -10; -20];
            
            
            if (min(y)<bwdb(1) && max(y)>bwdb(1))
                % first -6dB on left (X)
                % we consider first -6db on the left of a peak
                [X6L,X6R] = BW(app,x,y,bwdb(1));
                if(X6R~=0)
                    BWidth(1) = X6R-X6L;
                    if isnan(BWidth(1))
                        BWidth(1) = 0;
                    end
                end
                %                 % -6db fc = low+(hi - low)/2
                %                 fc6db = X6L+(X6R-X6L)/2;
                %
                %                 % -6dB % = Delta f / -6db fc * 100
                %                 Percent6db = BWidth(1)/fc6db*100;
            end
            if (min(y)<bwdb(2) && max(y)>bwdb(2))
                [X10L,X10R] = BW(app,x,y,bwdb(2));
                if(X10R~=0)
                    BWidth(2) = X10R-X10L;
                    if isnan(BWidth(2))
                        BWidth(2) = 0;
                    end
                end
            end
            if (min(y)<bwdb(3) && max(y)>bwdb(3))
                [X20L,X20R] = BW(app,x,y,bwdb(3));
                if(X20R~=0)
                    BWidth(3) = X20R-X20L;
                    if isnan(BWidth(3))
                        BWidth(3) = 0;
                    end
                end
            end
        end
        
        function [XL,XR] = BW(~,x,y,bwdb)
            [~,ymaxi] = max(y);
            [~,fst1] = max(fliplr(y(1:ymaxi)<bwdb)); % get the (right count) index of first value less than -6dB
            % if (ymaxi>1) this is automatically satisfied by > -6db
            fst1 = ymaxi-fst1+1;
            fst0 = fst1+1;
            diffs = interp1(x,y,[x(fst0),x(fst1)]);
            XL = interp1(diffs,[x(fst0),x(fst1)],bwdb);
            
            % end
            % last -6dB on right (X)
            [~,fst1a] = max(fliplr(y(ymaxi:end)<bwdb));
            [~,fst1b] = max(fliplr(y(ymaxi:end)>bwdb));
            fst1 = max(fst1a,fst1b);
            fst1 = length(y)-fst1+1;
            fst0 = fst1+1;
            if(fst0<length(x))
                diffs = interp1(x,y,[x(fst0),x(fst1)]);
                XR = interp1(diffs,[x(fst0),x(fst1)],bwdb);
            else
                XR = 0;
            end
            
        end
        
        function lowpassFilter(app)
            if (app.fc > 0 && app.LowF>0)
                %margin = 0.1;
                %                 if (app.LowF+0.1>=1)
                %                     margin = 0.99-app.LowF;
                %                 end
                %margin = app.LowF*0.1;
                app.h = designfilt('lowpassfir','PassbandFrequency',app.LowF, ...
                    'StopbandFrequency',app.LowF*1.05, ...
                    'StopbandAttenuation',60,'DesignMethod','kaiserwin', ...
                    'SampleRate',1/(app.t(2)-app.t(1)));
                %                 [~, app.h] = lowpass(app.InSig,app.LowF,1/(app.t(2)-app.t(1)),'Steepness',0.95 ,'ImpulseResponse','fir','StopbandAttenuation',60);
                %                 app.h = designfilt('lowpassfir','PassbandFrequency',app.LowF/4, ...
                %                     'StopbandFrequency',app.LowF*1.1/4,'PassbandRipple',0.5, ...
                %                     'StopbandAttenuation',65,'DesignMethod','kaiserwin','SampleRate',max([app.fc, app.LowF])*4);%app.LowSlider.Limits(2)*2.2);
                app.hcoeff = app.h.Coefficients;
                app.FilterDesignEditField.Value = app.h.DesignMethod; %'Kaiser Window';
                %plot(filter(app.h,app.InSig))
                % GraphFilter(app)
            end
        end
        
        function FilterFreqRange(app)
            % editing the Low Frequency and limit
            if(app.filterMode=="Low Pass")
                app.LowSlider.Limits = [0, 2*app.fc];
                app.LowSlider.Value = app.fc*0.9;
            elseif(app.filterMode == "High Pass")
                app.HighSlider.Limits = [2*app.fc*0.25, 2*app.fc];
                app.LowSlider.Value = app.fc*1.1;
            elseif (app.filterMode == "Band Pass" || app.filterMode=="Band Stop")
                app.LowSlider.Limits=[0, 1.1*app.fc];
                app.HighSlider.Limits=[0.9*app.fc, 2*app.fc];
            end
        end
        
        function highpassFilter(app)
            %             app.HiF = app.HiF/3;
            if (app.fc > 0)
                %                 [~, app.h] = highpass(app.InSig,app.HiF,1/(app.t(2)-app.t(1)),'Steepness',0.80 ...
                %                     ,'ImpulseResponse','fir','StopbandAttenuation',60);
                %                 margin = 0.1;
                %                 if (app.HiF-0.1<=0)
                %                     margin = app.HiF - 0.01;
                %                 end
                app.h = designfilt('highpassfir','PassbandFrequency',app.HiF, ...
                    'StopbandFrequency',app.HiF*0.95, ...
                    'StopbandAttenuation',60,'DesignMethod','kaiserwin', ...
                    'SampleRate',1/(app.t(2)-app.t(1)));
                %                 midt = floor(length(app.t)/2);
                %                 midh = floor(length(app.h.Coefficients)/2);
                %                 app.hcoeff = app.h.Coefficients(midh-midt:midh+midt);
                app.hcoeff = app.h.Coefficients;
                app.FilterDesignEditField.Value = app.h.DesignMethod;%'Kaiser Window';
                %GraphFilter(app)
            end
        end
        
        function bandpassFilter(app)
            %             HiLoSlide(app)
            if (app.fc > 0 && app.HiF>0 && app.HiF>app.LowF && app.LowF>0)
                
                %                 margin = 0.1;
                %                 if (app.HiF-0.1<=0)
                %                     margin = app.HiF - 0.01;
                %                 end
                %                 if (app.LowF+0.1>=1)
                %                     margin = 0.99-app.LowF;
                %                 end
                %                 if (app.LowF-0.1<=0)
                %                     margin = app.LowF - 0.01;
                %                 end
                %                 if (app.HiF+0.1>=1)
                %                     margin = 0.99-app.HiF;
                %                 end
                %                 [~, app.h] = bandpass(app.InSig,[app.LowF,app.HiF],1/(app.t(2)-app.t(1))...%,'Steepness',0.95...
                %                     ,'ImpulseResponse','fir','StopbandAttenuation',60);
                app.h = designfilt('bandpassfir', ...       % Response type
                    'StopbandFrequency1',app.LowF*0.95, ...    % Frequency constraints
                    'PassbandFrequency1',app.LowF, ...
                    'PassbandFrequency2',app.HiF, ...
                    'StopbandFrequency2',app.HiF*1.05, ...
                    'DesignMethod','kaiserwin','SampleRate',1/(app.t(2)-app.t(1)));
                app.FilterDesignEditField.Value = app.h.DesignMethod; %'Least Squares';
                app.hcoeff = app.h.Coefficients;
                %GraphFilter(app)
            end
        end
        
        function bandstopFilter(app)
            gap = app.HiF-app.LowF; % Requested gap for bandpassing fir filter
            tol = 1; % the smallest gap possible for bandpassing fir filter
            if(gap<tol)
                if(app.LowF+tol>app.HighSlider.Limits(2))
                    % we expand the lowSlider
                    setLowF(app,app.LowF-(tol-gap));
                else
                    % we expand the HighSlider
                    setHiF(app,app.HiF+(tol-gap));
                end
            end
            if (app.fc > 0 && app.HiF>0 && app.HiF>app.LowF && app.LowF>0)
                %                 margin = 0.1;
                %                 if (app.HiF-0.1<=0)
                %                     margin = app.HiF - 0.01;
                %                 end
                %                 if (app.LowF+0.1>=1)
                %                     margin = 0.99-app.LowF;
                %                 end
                %                 if (app.LowF-0.1<=0)
                %                     margin = app.LowF - 0.01;
                %                 end
                %                 if (app.HiF+0.1>=1)
                %                     margin = 0.99-app.HiF;
                %                 end
                %                 app.h = designfilt('bandstopfir', ...       % Response type
                %                     'FilterOrder',86, ...            % Filter order
                %                     'PassbandFrequency1',app.LowF-margin, ...    % Frequency constraints
                %                     'StopbandFrequency1',app.LowF, ...
                %                     'StopbandFrequency2',app.HiF, ...
                %                     'PassbandFrequency2',app.HiF+margin, ...
                %                     'DesignMethod','ls', ...         % Design method
                %                     'PassbandWeight1',1, ...         % Design method options
                %                     'StopbandWeight', 3, ...
                %                     'PassbandWeight2',5, ...
                %                     'SampleRate',1000);
                app.h = designfilt('bandstopfir', ...       % Response type
                    'PassbandFrequency1',app.LowF, ...    % Frequency constraints
                    'StopbandFrequency1',app.LowF*1.05, ...
                    'StopbandFrequency2',app.HiF*0.95, ...
                    'PassbandFrequency2',app.HiF, ...
                    'DesignMethod','kaiserwin','SampleRate',1/(app.t(2)-app.t(1)));
                %                 [~, app.h] = bandstop(app.InSig,[app.LowF,app.HiF],1/(app.t(2)-app.t(1))...%,'Steepness',0.95 ...
                %                     ,'ImpulseResponse','fir','StopbandAttenuation',60);
                app.FilterDesignEditField.Value = app.h.DesignMethod; %'Least Squares';
                app.hcoeff = app.h.Coefficients;
                %GraphFilter(app)
            end
        end
        
        function GaussianFilter(app)
            if (app.bt>0)
                app.sps = length(app.InSig)*2/app.span;
                if (mod(floor(app.span),2)~=0 && mod(floor(app.sps),2)~=0)
                    app.span = app.span+1;
                end
                
                app.h = gaussdesign(app.bt,floor(app.span),floor(app.sps));
                app.hcoeff = app.h;
                app.FilterDesignEditField.Value = 'Gaussian';
            end
        end
        
        function GraphFilter(app)
            delta = app.t(2)-app.t(1);
            htime = (0:delta:delta*(length(app.hcoeff)-1));
            cla(app.filtergraph)
            yyaxis(app.filtergraph,'left')
            stem(app.filtergraph, htime, app.hcoeff,'DisplayName','Filter')
            app.filtergraph.XLim = [0, htime(end)];
            app.groupdelay = htime(end)/2;
            hold(app.filtergraph,'on')
            yyaxis(app.filtergraph,'right')
            ylabel(app.filtergraph,'Envelope (dB)')
            fToutput = mag2db(envelope(app.hcoeff));
            plot(app.filtergraph, htime, fToutput,'DisplayName','Envelope')
            hBW= beamwidth(app,htime,fToutput);
            showfilterBeamWidth(app,hBW)
            hold(app.filtergraph,'off')
            %legend(app.filtergraph,' Location','best')
            [a,b] = freqz(app.h);
            if (app.filterMode == "Gaussian")
                b = b*app.sps/(2*pi);
            else
                b = b*app.h.SampleRate/(2*pi);
            end
            %fvtool(app.h)
            cla(app.filterFgraph)
            yyaxis(app.filterFgraph,'left')
            app.filterFgraph.XLim = [min(b),floor(app.fc*2.2)];
            app.filterFgraph.YLimMode = 'auto';
            if (app.filterMode ~= "Gaussian")
                app.filterFgraph.YLim = [-70 5];
            end
            app.filtermag = abs(a);
            app.filterf = b;
            plot(app.filterFgraph,b,mag2db(abs(a)))
            yyaxis(app.filterFgraph,'right')
            hold(app.filterFgraph,'on')
            ylabel(app.filterFgraph,'Phase (rad)')
            plot(app.filterFgraph,b,unwrap(angle(a)))
            hold(app.filterFgraph,'off')
            GraphResult(app)
        end
        
        function GraphResult(app)
            if (~isempty(app.h)) % Disabled with false
                cla(app.outputtimegraph)
                if(app.inputMode == "Tone")
                    out = app.InSig*interp1(app.filterf,app.filtermag,app.fc);
                else
                    out = conv(app.hcoeff,app.InSig);
                end
                marg = app.t(2)-app.t(1);
                tt = 0:marg:marg*(length(out)-1);% increase time for delay
                yyaxis(app.outputtimegraph,'left')
                plot(app.outputtimegraph,tt, out,'DisplayName','Output Signal');
                hold(app.outputtimegraph,'on')
                yyaxis(app.outputtimegraph,'right')
                app.outputtimegraph.YLim = [-30, 5];
                ylabel(app.outputtimegraph,'Envelope (dB)')
                evnout = mag2db(envelope(out));
                plot(app.outputtimegraph,tt,evnout,'DisplayName','Real Envelope');
                app.outputtimegraph.XLim = [tt(1),tt(end)];
                hBW= beamwidth(app,tt,evnout);
                showOutBeamWidth(app,hBW)
                %legend(app.outputtimegraph,'Location','best')
                hold(app.outputtimegraph,'off')
                %                 [a,b] = freqz(out,1);
                %                 plot(app.outputFgraph, b/pi,mag2db(abs(a)))
                %if (~app.isOAngle)
                
                freq=abs(fftshift(fft(app.delt*out)));
                
                app.outputFgraph.XLim = [-2*app.fc,2*app.fc];
                if(app.inputMode == "Tone")
                    tonetemp = interp1(app.filterf,app.filtermag,app.fc);
                    freq(freq==max(freq)) = inf;
                    freq(freq<max(freq)) = 1e-3*tonetemp;
                    freq(freq==max(freq)) = tonetemp*db2mag(app.SpecMax);
                end
                if (length(app.f)<length(freq))
                    
                    freq = freq(floor(length(app.hcoeff)/2)+1:floor(length(app.hcoeff)/2)+length(app.f));
                    
                end
                %ff = (-length(freq)/2:length(freq)/2-1)/(marg*length(freq));
                [~,qfix] = max(freq);% quick fix ....
                qfix = app.f(qfix)/app.fc;
                %plot(app.f/qfix,mag2db(freq)-app.SpecMax)
                
                if(app.inputMode~="Impulse" && app.inputMode~="Tone")
                    app.outputFgraph.YLim = [-60, 5];
                else
                    qfix = 1;
                    mid = floor(max(mag2db(freq))-app.SpecMax);
                    if isinf(mid)
                        mid = 0;
                    end
                    app.outputFgraph.YLim = [mid-20,mid+20];
                    if (app.inputMode=="Impulse")
                        freq(1:end) = max(freq);
                    end
                end
                plot(app.outputFgraph,app.f/qfix,mag2db(freq)-app.SpecMax);
                %else
                %                 freq=angle(fftshift(fft(app.delt*app.InSig)));
                %                 app.inputFgraph.XLim = [-4*app.fc,4*app.fc];
                %                 plot(app.inputFgraph,app.f,freq);
                % end
                if(app.inputMode~="Impulse" && app.inputMode~="Super Gaussian")
                    hBW= beamwidth(app,app.f(app.f>=0)/qfix,mag2db(freq(app.f>=0))-app.SpecMax);

                    showoutputFBeamWidth(app,hBW)
                    [perc,dbfc,smax] = spectralAnalysis(app,app.f/qfix,mag2db(freq)-app.SpecMax);
                    app.dBo6BWperc.Value = perc;
                    app.dBo6fc.Value = dbfc;
                    app.dBoSpecMax.Value = smax;
                end
            end
        end
        function showOutBeamWidth(app,bw)
            app.Edito6Field.Value = bw(1);
            app.Edito10Field.Value = bw(2);
            app.Edito20Field.Value = bw(3);
        end
        function showinputBeamWidth(app, bw)
            app.dBi6EditField.Value = bw(1);
            app.dBi10EditField.Value = bw(2);
            app.dBi20EditField.Value = bw(3);
        end
        function showfilterBeamWidth(app,bw)
            app.dBf6EditField.Value = bw(1);
            app.dBf10EditField.Value = bw(2);
            app.dBf20EditField.Value = bw(3);
        end
        function showinputFBeamWidth(app,bw)
            app.dBi6EditFieldF.Value = bw(1);
            app.dBi10EditFieldF.Value = bw(2);
            app.dBi20EditFieldF.Value = bw(3);
        end
        function showoutputFBeamWidth(app,bw)
            app.Edito6FieldF.Value = bw(1);
            app.Edito10FieldF.Value = bw(2);
            app.Edito20FieldF.Value = bw(3);
        end
        
        function GraphInputSig(app)
            app.inputtimegraph.XLimMode = 'auto';
            app.inputtimegraph.YLimMode = 'auto';
            app.inputFgraph.YLimMode = 'auto';
            cla(app.inputtimegraph)
            cla(app.inputFgraph)
            yyaxis(app.inputtimegraph,'left')
            plot(app.inputtimegraph,app.t(1:length(app.InSig)),app.InSig,'DisplayName','Input Signal');
            hold(app.inputtimegraph,'on')
            yyaxis(app.inputtimegraph,'right')
            ylabel(app.inputtimegraph,'Envelope (dB)')
            app.inputtimegraph.YLim = [-30, 5];
            
            if(app.inputMode == "Tone") % INput sig not
                plot(app.inputtimegraph,app.t(1:length(app.InSig)),mag2db(envelope(app.InSig)),'DisplayName','Envelope');
            else
                plot(app.inputtimegraph,app.t(1:length(app.InSig)),mag2db(envelope(app.InSig)),'DisplayName','Envelope');
                hBW = beamwidth(app,app.t,mag2db(envelope(app.InSig)));
                showinputBeamWidth(app,hBW);
            end
            %legend(app.inputtimegraph,'Location','best')
            hold(app.inputtimegraph,'off')
            
            app.inputFgraph.YLim = [-60, 5];
            freq=abs(fftshift(fft(app.delt*app.InSig)));
            app.inputFgraph.XLim = [-2*app.fc,2*app.fc];
            % determining spectral max
            app.SpecMax = max(mag2db(freq));
            app.dBiSpecMax.Value = app.SpecMax;
            if(app.inputMode == "Tone")
                freq(freq==max(freq)) = inf;
                freq(freq<max(freq)) = 1e-3;
                freq(freq==max(freq)) = db2mag(app.SpecMax);
            end
            ff = app.f(1:length(freq));
            plot(app.inputFgraph,ff,mag2db(freq)-app.SpecMax);
            ylabel(app.inputFgraph,"Spectral Mag (dB)");
            hBW= beamwidth(app,ff(ff>=0),mag2db(freq(ff>=0))-app.SpecMax);
            showinputFBeamWidth(app,hBW);
            %             if (app.isAngle)
            %                 freq=angle(fftshift(fft(app.delt*app.InSig)));
            %                 app.inputFgraph.XLim = [-4*app.fc,4*app.fc];
            %                 app.inputFgraph.YLimMode = 'auto';
            %                 ylabel(app.inputFgraph,"Phase (rad)");
            %                 plot(app.inputFgraph,app.f,freq);
            %             end
            
            
            if(app.inputMode~="Super Gaussian")
                [perc,dbfc,smax] = spectralAnalysis(app,app.f,mag2db(freq)-app.SpecMax);
                
                app.dBi6BWperc.Value = perc;
                app.dBi6fc.Value = dbfc;
                app.dBiSpecMax.Value = smax;
            end
            
            
            GraphResult(app)
        end
        
        function out = InRange(~,Val,Limits, OriginalVal)
            %             if(Val>=Limits(1) && Val<=Limits(2))
            %                 out = Val;
            %             else
            %                 out = OriginalVal;
            %             end
            if(Val<Limits(1))
                out = Limits(1);
            elseif( Val>Limits(2))
                out = Limits(2);
            else
                out = Val;
            end
        end
        
        % Insig mode help function
        function VisibleNonInSig(app,isEnabled)
            % pased after time and frequency graph is repositioned
            app.dBoSpecMax.Visible = isEnabled;
            app.dBo6fc.Visible = isEnabled;
            app.dBo6BWperc.Visible = isEnabled;
            %app.dBfSpecMax.Visible = isEnabled;
            %app.dBf6fc.Visible = isEnabled;
            %app.dBf6BWperc.Visible = isEnabled;
            app.ENVPLLabel_4.Visible = isEnabled;
            app.ENVPLLabel_2.Visible = isEnabled;
            app.ENVPLLabel_2.Visible = isEnabled;
            app.Edito20FieldF.Visible = isEnabled;
            app.Edito20FieldF.Visible = isEnabled;
            app.Edito10FieldF.Visible = isEnabled;
            app.dBLabel_5.Visible = isEnabled;
            app.Edito6FieldF.Visible = isEnabled;
            app.dBLabel_4.Visible = isEnabled;
            app.Edito20Field.Visible = isEnabled;
            app.Edito10Field.Visible = isEnabled;
            app.Edito6Field.Visible = isEnabled;
            app.dBf20EditField.Visible = isEnabled;
            app.dBf10EditField.Visible = isEnabled;
            app.dBf6EditField.Visible = isEnabled;
            app.outputtimegraph.Visible = isEnabled;
            app.filtergraph.Visible = isEnabled;
            app.outputFgraph.Visible = isEnabled;
            app.filterFgraph.Visible = isEnabled;
            app.dBLabel_11.Visible = isEnabled;
            app.dBLabel_12.Visible = isEnabled;
            app.dBLabel_13.Visible = isEnabled;
            app.dBLabel_3.Visible = isEnabled;
            app.dBLabel_2.Visible = isEnabled;
            app.dBLabel.Visible = isEnabled;
            app.dBLabel_4.Visible = isEnabled;
            app.dBLabel_5.Visible = isEnabled;
            app.dBLabel_7.Visible = isEnabled;
            %app.dBBWLabel_3.Visible = isEnabled;
            %app.dBfcLabel_3.Visible = isEnabled;
            %app.SpectralMaxLabel_3.Visible = isEnabled;
            app.dBBWLabel_4.Visible = isEnabled;
            app.dBfcLabel_4.Visible = isEnabled;
            app.SpectralMaxLabel_4.Visible = isEnabled;
            app.dBLabel_14.Visible = isEnabled;
            app.dBi6EditFieldF.Visible = isEnabled;
            app.dBLabel_15.Visible = isEnabled;
            app.dBi10EditFieldF.Visible = isEnabled;
            app.dBLabel_16.Visible = isEnabled;
            app.dBi20EditFieldF.Visible = isEnabled;


            if (isEnabled=="off")% learn old position
                app.VisibleNonInSig_PosCol = [app.ENVPLLabel_3.Position;
                    app.dBLabel_8.Position;
                    app.dBi6EditField.Position;
                    app.dBLabel_9.Position;
                    app.dBi10EditField.Position;
                    app.dBLabel_10.Position;
                    app.dBi20EditField.Position;
                    app.dBBWLabel_2.Position;
                    app.dBi6BWperc.Position;
                    app.dBfcLabel_2.Position;
                    app.dBi6fc.Position;
                    app.SpectralMaxLabel_2.Position;
                    app.dBiSpecMax.Position;
                    ];
                % change pos
                hpos = app.UIFigure.Position(3)/12;
                vpos = app.ENVPLLabel_3.Position(3);
                app.ENVPLLabel_3.Position = [hpos,
                    vpos,
                    app.ENVPLLabel_3.Position(3),
                    app.ENVPLLabel_3.Position(4)];
                
                app.dBLabel_8.Position = [2*hpos,
                    vpos,
                    app.dBLabel_8.Position(3),
                    app.dBLabel_8.Position(4)];
                app.dBi6EditField.Position = [2*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.dBi6EditField.Position(3),
                    app.dBi6EditField.Position(4)];
                
                app.dBLabel_9.Position = [3*hpos,
                    vpos,
                    app.dBLabel_9.Position(3),
                    app.dBLabel_9.Position(4)];
                app.dBi10EditField.Position = [3*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.dBi10EditField.Position(3),
                    app.dBi10EditField.Position(4)];
                
                app.dBLabel_10.Position = [4*hpos,
                    vpos,
                    app.dBLabel_10.Position(3),
                    app.dBLabel_10.Position(4)];
                app.dBi20EditField.Position = [4*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.dBi20EditField.Position(3),
                    app.dBi20EditField.Position(4)];
                
                
                app.dBBWLabel_2.Position = [7*hpos,
                    vpos,
                    app.dBBWLabel_2.Position(3),
                    app.dBBWLabel_2.Position(4)];
                app.dBi6BWperc.Position = [7*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.dBBWLabel_2.Position(3),
                    app.dBi6BWperc.Position(4)];
                
                app.dBfcLabel_2.Position = [9*hpos,
                    vpos,
                    app.dBfcLabel_2.Position(3),
                    app.dBfcLabel_2.Position(4)];
                app.dBi6fc.Position = [9*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.dBfcLabel_2.Position(3),
                    app.dBi6fc.Position(4)];
                
                app.SpectralMaxLabel_2.Position = [10*hpos,
                    vpos,
                    app.SpectralMaxLabel_2.Position(3),
                    app.SpectralMaxLabel_2.Position(4)];
                app.dBiSpecMax.Position = [10*hpos,
                    vpos-app.dBLabel_10.Position(4),
                    app.SpectralMaxLabel_2.Position(3),
                    app.dBiSpecMax.Position(4)];
                
                
                temp = app.filterFgraph.Position;
                app.filterFgraph.Position = [temp(1)+temp(3),temp(2),temp(3),temp(4)];
            else
                app.ENVPLLabel_3.Position = app.VisibleNonInSig_PosCol(1,:);
                app.dBLabel_8.Position = app.VisibleNonInSig_PosCol(2,:);
                app.dBi6EditField.Position = app.VisibleNonInSig_PosCol(3,:);
                app.dBLabel_9.Position = app.VisibleNonInSig_PosCol(4,:);
                app.dBi10EditField.Position = app.VisibleNonInSig_PosCol(5,:);
                app.dBLabel_10.Position = app.VisibleNonInSig_PosCol(6,:);
                app.dBi20EditField.Position = app.VisibleNonInSig_PosCol(7,:);
                app.dBBWLabel_2.Position = app.VisibleNonInSig_PosCol(8,:);
                app.dBi6BWperc.Position = app.VisibleNonInSig_PosCol(9,:);
                app.dBfcLabel_2.Position = app.VisibleNonInSig_PosCol(10,:);
                app.dBi6fc.Position= app.VisibleNonInSig_PosCol(11,:);
                app.SpectralMaxLabel_2.Position= app.VisibleNonInSig_PosCol(12,:);
                app.dBiSpecMax.Position = app.VisibleNonInSig_PosCol(13,:);
                
                
                temp = app.filterFgraph.Position;
                app.filterFgraph.Position = [temp(1)-temp(3),temp(2),temp(3),temp(4)];
            end
        end
        
    end
    

    methods (Access = private)

        % Value changed function: WaveforrmDropDown
        function WaveforrmDropDownValueChanged(app, event)
            app.inputMode = app.WaveforrmDropDown.Value;
            cla(app.inputtimegraph)
            cla(app.inputFgraph)
            Refreash(app);
        end

        % Value changed function: FilterTypeDropDown
        function FilterTypeDropDownValueChanged(app, event)
            app.filterMode = app.FilterTypeDropDown.Value;
            RefreashFilter(app)
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.tabMode = app.TabGroup.SelectedTab.Title;
            RefreashTab(app)
        end

        % Button pushed function: FilterInfoButton
        function FilterInfoButtonPushed(app, event)
            fvtool(app.h)
        end

        % Value changed function: EditFieldSpan, SpanSlider
        function SpanSliderValueChanged(app, event)
            app.span = InRange(app,event.Value,app.SpanSlider.Limits,app.span);
            %             app.sps = length(app.InSig)*2/app.span;
            app.EditFieldSpan.Value = app.span;
            app.SpanSlider.Value = app.span;
            RefreashFilter(app)
            %             app.sps = InRange(app, event.Value, app.SamplesSlider.Limits, app.sps);
            %             app.SamplesSlider.Value = app.sps;
            %             app.EditFieldSamples.Value = app.sps;
            %             RefreashFilter(app)
        end

        % Value changed function: EditFieldWidth, WidthSlider
        function WidthSliderValueChanged(app, event)
            app.bt = InRange(app,event.Value, app.WidthSlider.Limits,app.bt);
            app.EditFieldWidth.Value = app.bt;
            app.WidthSlider.Value = app.bt;
            RefreashFilter(app)
            
        end

        % Value changed function: EditFieldfc, fcSlider
        function fcSliderValueChanged(app, event)
            setFc(app, event.Value);
            %FilterFreqRange(app);
            Refreash(app)
        end

        % Value changed function: AmplitudeSlider, EditFieldamp
        function AmplitudeSliderValueChanged(app, event)
            app.Amp = InRange(app, event.Value, app.AmplitudeSlider.Limits, app.Amp);
            app.AmplitudeSlider.Value = app.Amp;
            app.EditFieldamp.Value = app.Amp;
            Refreash(app);
        end

        % Value changed function: EditFieldHiF, HighSlider
        function HighSliderValueChanged(app, event)
            app.HiF = InRange(app,event.Value, app.HighSlider.Limits, app.HiF);
            app.HighSlider.Value = app.HiF;
            app.EditFieldHiF.Value = app.HiF;
            RefreashFilter(app)
        end

        % Value changed function: EditFieldLowF, LowSlider
        function LowSliderValueChanged(app, event)
            app.LowF = InRange(app,event.Value, app.LowSlider.Limits, app.LowF);
            app.LowSlider.Value = app.LowF;
            app.EditFieldLowF.Value = app.LowF;
            RefreashFilter(app)
        end

        % Value changed function: BrustLengthSlider, EditFieldBrust
        function BrustLengthSliderValueChanged(app, event)
            app.pulselen = InRange(app,event.Value, app.BrustLengthSlider.Limits, app.pulselen);
            app.BrustLengthSlider.Value = app.pulselen;
            app.EditFieldBrust.Value = app.pulselen;
            Refreash(app);
        end

        % Value changed function: MoreInfoInSig
        function MoreInfoInSigValueChanged(app, event)
            function doNothing(tabgroup, eventData)
                tabgroup.SelectedTab = eventData.OldValue;
            end
            isInSigMode = app.MoreInfoInSig.Value;
            if (isInSigMode)
                
                pos = app.UIFigure.OuterPosition;
                app.InSigModeInfo = [app.inputtimegraph.OuterPosition;app.inputFgraph.OuterPosition];
                
                app.inputtimegraph.OuterPosition = [0,0,pos(3)/2,pos(4)];
                app.inputFgraph.OuterPosition = [pos(3)/2,0,pos(3)/2,pos(4)];
                app.backuphandlefunc = app.TabGroup.SelectionChangedFcn;
                app.TabGroup.SelectionChangedFcn = @doNothing;
                VisibleNonInSig(app,'off')
                
                
            else
                app.TabGroup.SelectionChangedFcn = app.backuphandlefunc;
                app.inputtimegraph.OuterPosition = app.InSigModeInfo(1,:);
                app.inputFgraph.OuterPosition = app.InSigModeInfo(2,:);
                VisibleNonInSig(app,'on')
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.outputtimegraph = uiaxes(app.UIFigure);
            title(app.outputtimegraph, 'Output: Time Domain')
            xlabel(app.outputtimegraph, 'Time (\mus)')
            ylabel(app.outputtimegraph, 'Amplitude')
            app.filtergraph = uiaxes(app.UIFigure);
            title(app.filtergraph, 'Filter: Impulse Response')
            xlabel(app.filtergraph, 'Time (\mus)')
            ylabel(app.filtergraph, 'Amplitude')
            app.inputtimegraph = uiaxes(app.UIFigure);
            title(app.inputtimegraph, 'Input Signal: Time Domain')
            xlabel(app.inputtimegraph, 'Time (\mus)')
            ylabel(app.inputtimegraph, 'Amplitude')
            app.outputFgraph = uiaxes(app.UIFigure);
            title(app.outputFgraph, 'Output: Frequency Domain')
            xlabel(app.outputFgraph, 'Frequency (MHz)')
            ylabel(app.outputFgraph, 'Spectral Mag (dB)')
            app.filterFgraph = uiaxes(app.UIFigure);
            title(app.filterFgraph, 'Filter: Mag and Phase Response')
            xlabel(app.filterFgraph, 'Frequency (MHz)')
            ylabel(app.filterFgraph, 'Spectral Mag (dB)')
            app.inputFgraph = uiaxes(app.UIFigure);
            title(app.inputFgraph, 'Input Signal: Frequency Domain')
            xlabel(app.inputFgraph, 'Frequency (MHz)')
            ylabel(app.inputFgraph, 'Spectral Mag (dB)')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.InputSignalTab = uitab(app.TabGroup);
            app.InputSignalTab.Title = 'Input Signal';
            app.WaveforrmDropDownLabel = uilabel(app.InputSignalTab);
            app.WaveforrmDropDownLabel.Text = 'Waveforrm';
            app.WaveforrmDropDown = uidropdown(app.InputSignalTab);
            app.WaveforrmDropDown.Items = {'Tone', 'Tone Burst', 'Gaussian Pulse', 'Impulse', 'Super Gaussian'};
            app.WaveforrmDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveforrmDropDownValueChanged, true);
            app.WaveforrmDropDown.Value = 'Tone';
            app.fcSliderLabel = uilabel(app.InputSignalTab);
            app.fcSliderLabel.Text = 'fc';
            app.fcSlider = uislider(app.InputSignalTab);
            app.fcSlider.Limits = [0 20];
            app.fcSlider.ValueChangedFcn = createCallbackFcn(app, @fcSliderValueChanged, true);
            app.BrustLengthSliderLabel = uilabel(app.InputSignalTab);
            app.BrustLengthSliderLabel.Visible = 'off';
            app.BrustLengthSliderLabel.Text = 'Brust Length';
            app.BrustLengthSlider = uislider(app.InputSignalTab);
            app.BrustLengthSlider.Limits = [1 20];
            app.BrustLengthSlider.ValueChangedFcn = createCallbackFcn(app, @BrustLengthSliderValueChanged, true);
            app.BrustLengthSlider.Visible = 'off';
            app.BrustLengthSlider.Value = 1;
            app.AmplitudeSliderLabel = uilabel(app.InputSignalTab);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.InputSignalTab);
            app.AmplitudeSlider.Limits = [0 50];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.AmplitudeSlider.Value = 1;
            app.EditFieldfc = uieditfield(app.InputSignalTab, 'numeric');
            app.EditFieldfc.ValueChangedFcn = createCallbackFcn(app, @fcSliderValueChanged, true);
            app.EditFieldamp = uieditfield(app.InputSignalTab, 'numeric');
            app.EditFieldamp.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.EditFieldamp.Value = 1;
            app.EditFieldBrust = uieditfield(app.InputSignalTab, 'numeric');
            app.EditFieldBrust.Limits = [1 20];
            app.EditFieldBrust.ValueChangedFcn = createCallbackFcn(app, @BrustLengthSliderValueChanged, true);
            app.EditFieldBrust.Visible = 'off';
            app.EditFieldBrust.Value = 1;
            app.MoreInfoInSig = uibutton(app.InputSignalTab, 'state');
            app.MoreInfoInSig.ValueChangedFcn = createCallbackFcn(app, @MoreInfoInSigValueChanged, true);
            app.MoreInfoInSig.Text = 'Input Signal Only';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.FilterTypeDropDownLabel = uilabel(app.FilterTab);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterTab);
            app.FilterTypeDropDown.Items = {'Low Pass', 'High Pass', 'Band Pass', 'Band Stop', 'Gaussian'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterTypeDropDownValueChanged, true);
            app.FilterTypeDropDown.Value = 'Low Pass';
            app.LowSliderLabel = uilabel(app.FilterTab);
            app.LowSliderLabel.Text = 'Low';
            app.LowSlider = uislider(app.FilterTab);
            app.LowSlider.Limits = [0 10];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @LowSliderValueChanged, true);
            app.LowSlider.Value = 4;
            app.HighSliderLabel = uilabel(app.FilterTab);
            app.HighSliderLabel.Visible = 'off';
            app.HighSliderLabel.Text = 'High';
            app.HighSlider = uislider(app.FilterTab);
            app.HighSlider.Limits = [0 10];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.HighSlider.Visible = 'off';
            app.HighSlider.Value = 6;
            app.FilterDesignEditFieldLabel = uilabel(app.FilterTab);
            app.FilterDesignEditFieldLabel.Text = 'Filter Design';
            app.FilterDesignEditField = uieditfield(app.FilterTab, 'text');
            app.FilterDesignEditField.Editable = 'off';
            app.WidthSliderLabel = uilabel(app.FilterTab);
            app.WidthSliderLabel.Visible = 'off';
            app.WidthSliderLabel.Text = 'Width';
            app.WidthSlider = uislider(app.FilterTab);
            app.WidthSlider.Limits = [0 20];
            app.WidthSlider.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.WidthSlider.Visible = 'off';
            app.WidthSlider.Value = 4;
            app.FilterInfoButton = uibutton(app.FilterTab, 'push');
            app.FilterInfoButton.ButtonPushedFcn = createCallbackFcn(app, @FilterInfoButtonPushed, true);
            app.FilterInfoButton.Text = 'Filter Info';
            app.SpanSliderLabel = uilabel(app.FilterTab);
            app.SpanSliderLabel.Visible = 'off';
            app.SpanSliderLabel.Text = 'Span';
            app.SpanSlider = uislider(app.FilterTab);
            app.SpanSlider.Limits = [1 20];
            app.SpanSlider.ValueChangedFcn = createCallbackFcn(app, @SpanSliderValueChanged, true);
            app.SpanSlider.Visible = 'off';
            app.SpanSlider.Value = 1;
            app.SamplesSliderLabel = uilabel(app.FilterTab);
            app.SamplesSliderLabel.Enable = 'off';
            app.SamplesSliderLabel.Visible = 'off';
            app.SamplesSliderLabel.Text = 'Samples';
            app.SamplesSlider = uislider(app.FilterTab);
            app.SamplesSlider.Limits = [1 20];
            app.SamplesSlider.Enable = 'off';
            app.SamplesSlider.Visible = 'off';
            app.SamplesSlider.Value = 1;
            app.EditFieldLowF = uieditfield(app.FilterTab, 'numeric');
            app.EditFieldLowF.ValueChangedFcn = createCallbackFcn(app, @LowSliderValueChanged, true);
            app.EditFieldSpan = uieditfield(app.FilterTab, 'numeric');
            app.EditFieldSpan.ValueChangedFcn = createCallbackFcn(app, @SpanSliderValueChanged, true);
            app.EditFieldSpan.Visible = 'off';
            app.EditFieldHiF = uieditfield(app.FilterTab, 'numeric');
            app.EditFieldHiF.ValueChangedFcn = createCallbackFcn(app, @HighSliderValueChanged, true);
            app.EditFieldHiF.Visible = 'off';
            app.EditFieldSamples = uieditfield(app.FilterTab, 'numeric');
            app.EditFieldSamples.Visible = 'off';
            app.EditFieldWidth = uieditfield(app.FilterTab, 'numeric');
            app.EditFieldWidth.ValueChangedFcn = createCallbackFcn(app, @WidthSliderValueChanged, true);
            app.EditFieldWidth.Visible = 'off';
            app.dBLabel_8 = uilabel(app.UIFigure);
            app.dBLabel_8.Text = '-6 dB';
            app.dBi6EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBi6EditField.Editable = 'off';
            app.dBLabel_9 = uilabel(app.UIFigure);
            app.dBLabel_9.Text = '-10 dB';
            app.dBi10EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBi10EditField.Editable = 'off';
            app.dBLabel_10 = uilabel(app.UIFigure);
            app.dBLabel_10.Text = '-20 dB';
            app.dBi20EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBi20EditField.Editable = 'off';
            app.ENVPLLabel_3 = uilabel(app.UIFigure);
            app.ENVPLLabel_3.Text = 'ENV PL';
            app.dBLabel_11 = uilabel(app.UIFigure);
            app.dBLabel_11.Text = '-6 dB';
            app.dBf6EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBf6EditField.Editable = 'off';
            app.dBLabel_12 = uilabel(app.UIFigure);
            app.dBLabel_12.Text = '-10 dB';
            app.dBf10EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBf10EditField.Editable = 'off';
            app.dBLabel_13 = uilabel(app.UIFigure);
            app.dBLabel_13.Text = '-20 dB';
            app.dBf20EditField = uieditfield(app.UIFigure, 'numeric');
            app.dBf20EditField.Editable = 'off';
            app.dBLabel_3 = uilabel(app.UIFigure);
            app.dBLabel_3.Text = '-6 dB';
            app.Edito6Field = uieditfield(app.UIFigure, 'numeric');
            app.Edito6Field.Editable = 'off';
            app.dBLabel_2 = uilabel(app.UIFigure);
            app.dBLabel_2.Text = '-10 dB';
            app.Edito10Field = uieditfield(app.UIFigure, 'numeric');
            app.Edito10Field.Editable = 'off';
            app.dBLabel = uilabel(app.UIFigure);
            app.dBLabel.Text = '-20 dB';
            app.Edito20Field = uieditfield(app.UIFigure, 'numeric');
            app.Edito20Field.Editable = 'off';
            app.dBLabel_4 = uilabel(app.UIFigure);
            app.dBLabel_4.Text = '-6 dB';
            app.Edito6FieldF = uieditfield(app.UIFigure, 'numeric');
            app.Edito6FieldF.Editable = 'off';
            app.dBLabel_5 = uilabel(app.UIFigure);
            app.dBLabel_5.Text = '-10 dB';
            app.Edito10FieldF = uieditfield(app.UIFigure, 'numeric');
            app.Edito10FieldF.Editable = 'off';
            app.dBLabel_7 = uilabel(app.UIFigure);
            app.dBLabel_7.Text = '-20 dB';
            app.Edito20FieldF = uieditfield(app.UIFigure, 'numeric');
            app.Edito20FieldF.Editable = 'off';
            app.ENVPLLabel_2 = uilabel(app.UIFigure);
            app.ENVPLLabel_2.Text = 'ENV PL';
            app.ENVPLLabel_4 = uilabel(app.UIFigure);
            app.ENVPLLabel_4.Text = 'ENV PL';
            app.dBBWLabel_2 = uilabel(app.UIFigure);
            app.dBBWLabel_2.Text = '-6 dB BW %';
            app.dBi6BWperc = uieditfield(app.UIFigure, 'numeric');
            app.dBi6BWperc.Editable = 'off';
            app.dBfcLabel_2 = uilabel(app.UIFigure);
            app.dBfcLabel_2.Text = '-6 dB fc';
            app.dBi6fc = uieditfield(app.UIFigure, 'numeric');
            app.dBi6fc.Editable = 'off';
            app.SpectralMaxLabel_2 = uilabel(app.UIFigure);
            app.SpectralMaxLabel_2.Text = 'Spectral Max';
            app.dBiSpecMax = uieditfield(app.UIFigure, 'numeric');
            app.dBiSpecMax.Editable = 'off';
            app.dBBWLabel_3 = uilabel(app.UIFigure);
            app.dBBWLabel_3.Visible = 'off';
            app.dBBWLabel_3.Text = '-6 dB BW %';
            app.dBf6BWperc = uieditfield(app.UIFigure, 'numeric');
            app.dBf6BWperc.Editable = 'off';
            app.dBf6BWperc.Visible = 'off';
            app.dBfcLabel_3 = uilabel(app.UIFigure);
            app.dBfcLabel_3.Visible = 'off';
            app.dBfcLabel_3.Text = '-6 dB fc';
            app.dBf6fc = uieditfield(app.UIFigure, 'numeric');
            app.dBf6fc.Editable = 'off';
            app.dBf6fc.Visible = 'off';
            app.SpectralMaxLabel_3 = uilabel(app.UIFigure);
            app.SpectralMaxLabel_3.Visible = 'off';
            app.SpectralMaxLabel_3.Text = 'Spectral Max';
            app.dBfSpecMax = uieditfield(app.UIFigure, 'numeric');
            app.dBfSpecMax.Editable = 'off';
            app.dBfSpecMax.Visible = 'off';
            app.dBBWLabel_4 = uilabel(app.UIFigure);
            app.dBBWLabel_4.Text = '-6 dB BW %';
            app.dBo6BWperc = uieditfield(app.UIFigure, 'numeric');
            app.dBo6BWperc.Editable = 'off';
            app.dBfcLabel_4 = uilabel(app.UIFigure);
            app.dBfcLabel_4.Text = '-6 dB fc';
            app.dBo6fc = uieditfield(app.UIFigure, 'numeric');
            app.dBo6fc.Editable = 'off';
            app.SpectralMaxLabel_4 = uilabel(app.UIFigure);
            app.SpectralMaxLabel_4.Text = 'Spectral Max';
            app.dBoSpecMax = uieditfield(app.UIFigure, 'numeric');
            app.dBoSpecMax.Editable = 'off';
            app.dBLabel_14 = uilabel(app.UIFigure);
            app.dBLabel_14.Text = '-6 dB';
            app.dBi6EditFieldF = uieditfield(app.UIFigure, 'numeric');
            app.dBi6EditFieldF.Editable = 'off';
            app.dBLabel_15 = uilabel(app.UIFigure);
            app.dBLabel_15.Text = '-10 dB';
            app.dBi10EditFieldF = uieditfield(app.UIFigure, 'numeric');
            app.dBi10EditFieldF.Editable = 'off';
            app.dBLabel_16 = uilabel(app.UIFigure);
            app.dBLabel_16.Text = '-20 dB';
            app.dBi20EditFieldF = uieditfield(app.UIFigure, 'numeric');
            app.dBi20EditFieldF.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SigFilter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end