classdef Oblique_App_With_Shear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PanelLabel                      matlab.ui.container.Panel
        LongitudinalSpeedEditFieldLabel  matlab.ui.control.Label
        LongitudinalSpeedEditField      matlab.ui.control.NumericEditField
        LongImpedanceEditFieldLabel     matlab.ui.control.Label
        LongImpedanceEditField          matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        MRaylsLabel                     matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        MRaylsLabel_3                   matlab.ui.control.Label
        ShearSpeedEditFieldLabel        matlab.ui.control.Label
        ShearSpeedEditField             matlab.ui.control.NumericEditField
        ShearImpedanceEditFieldLabel    matlab.ui.control.Label
        ShearImpedanceEditField         matlab.ui.control.NumericEditField
        Panel                           matlab.ui.container.Panel
        InputParametersLabel            matlab.ui.control.Label
        SelectValueDropDownLabel        matlab.ui.control.Label
        MediumSelect1                   matlab.ui.control.DropDown
        PanelLabel_2                    matlab.ui.container.Panel
        SoundSpeedzEditField_2          matlab.ui.control.NumericEditField
        ImpedancezEditField_2           matlab.ui.control.NumericEditField
        SoundSpeedxEditField_2          matlab.ui.control.NumericEditField
        ImpedancexEditField_2           matlab.ui.control.NumericEditField
        LongitudinalSpeedEditFieldLabel_2  matlab.ui.control.Label
        msLabel_4                       matlab.ui.control.Label
        LongImpedanceEditFieldLabel_2   matlab.ui.control.Label
        MRaylsLabel_4                   matlab.ui.control.Label
        ShearSpeedEditFieldLabel_2      matlab.ui.control.Label
        msLabel_5                       matlab.ui.control.Label
        ShearImpedanceEditFieldLabel_2  matlab.ui.control.Label
        MRaylsLabel_5                   matlab.ui.control.Label
        SelectValueDropDown_2Label      matlab.ui.control.Label
        MediumSelect2                   matlab.ui.control.DropDown
        AngleofIncidenceDegreesPanel    matlab.ui.container.Panel
        EditField                       matlab.ui.control.NumericEditField
        Slider                          matlab.ui.control.Slider
        Panel_2                         matlab.ui.container.Panel
        ReflectedAngleEditFieldLabel    matlab.ui.control.Label
        ReflectedAngleEditField         matlab.ui.control.NumericEditField
        ReflectedEditFieldLabel         matlab.ui.control.Label
        ReflectedEditField              matlab.ui.control.NumericEditField
        LongAngleLabel                  matlab.ui.control.Label
        LongAngleEditField              matlab.ui.control.NumericEditField
        LongEditFieldLabel              matlab.ui.control.Label
        LongEditField                   matlab.ui.control.NumericEditField
        ShearedLabel                    matlab.ui.control.Label
        ShearedEditField                matlab.ui.control.NumericEditField
        ShearAngleEditFieldLabel        matlab.ui.control.Label
        ShearAngleEditField             matlab.ui.control.NumericEditField
        ViewCodeButton                  matlab.ui.control.Button
        Panel_3                         matlab.ui.container.Panel
        OutputsLabel                    matlab.ui.control.Label
        ImAxe                           matlab.ui.control.UIAxes
        ShearLabel                      matlab.ui.control.Label
        LongitudinalLabel               matlab.ui.control.Label
        LegendLabel                     matlab.ui.control.Label
    end


    %This Version of the app uses the new spreadsheet to determine shear waves as well as longitudinal waves in the fluid/solid-solid interface
    properties (Access = private)
        cMapLong 
        zMapLong
        cMapShear 
        zMapShear
        degIn = 0;
        degOutLong = 0;
        degOutShear = 0;
        Rf = 0; %Reflection
        Tf_l = 0; %Transmitted in Longitudinal Direction (z)
        Tf_s = 0;%Transmitted in Shear Horizontal Direction (x)
        
        % Color Map Use
            jetColor = [0         0    0.5625;...
                     0         0    0.6250; ...
                     0         0    0.6875;...
                     0         0    0.7500;...
                     0         0    0.8125;...
                     0         0    0.8750;...
                     0         0    0.9375;...
                     0         0    1.0000;...
                     0    0.0625    1.0000;...
                     0    0.1250    1.0000;...
                     0    0.1875    1.0000;...
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                0.5000         0         0];
    end

    methods (Access = private)
        
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
  %Subfunctions: Coefficients/Draw Graph
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %Determines Reflected/Transmitted Intensities/AngleOut
        function Generate_Coefficients(app)
            c1 = app.LongitudinalSpeedEditField.Value; %Only longitudinal wave input
            c2Long = app.SoundSpeedzEditField_2.Value;
            c2Shear = app.SoundSpeedxEditField_2.Value;
            
            z1Long = app.LongImpedanceEditField.Value;
            z2Long = app.ImpedancezEditField_2.Value;
            z2Shear = app.ImpedancexEditField_2.Value;

            %Only check 1 parameter from each medium to ensure two are selected
            if (c1>0) && (c2Long>0)      
                transLong = sind(app.degIn)/c1*c2Long;
                transShear = sind(app.degIn)/c1*c2Shear;
                %First Critical Angle
                if transLong >= 1 && transShear < 1
                   app.degOutLong = 90; %No Longitudinal Wave Transmitted
                   app.degOutShear = asind(transShear); %Still a Shear Wave
                   nShear = 2*(1/c2Long)^2/(1/c2Shear)^2; %2*pi*f factor in each cancels
                   nLong = 1-nShear;
                   z1LongTheta = z1Long/cosd(app.degIn);
                   z2LongTheta = 0; %No Longitudinal Impedance
                   z2ShearTheta = z2Shear/cosd(app.degOutShear);
                   zIn = nLong^2*z2LongTheta+nShear^2*z2ShearTheta;
                    
                   %Reflection and Transmission
                   app.Rf = (zIn-z1LongTheta)^2/(zIn+z1LongTheta)^2;
                   app.Tf_l = 0;
                   app.Tf_s = 4*z1LongTheta*nShear^2*z2ShearTheta/(z1LongTheta+zIn)^2; 
                
                %Second Critical Angle -> Total Reflection Occurs
                elseif transShear >= 1
                    app.Rf = 1;
                    app.Tf_l = 0;
                    app.Tf_s = 0;
                    app.degOutLong = 90;
                    app.degOutShear = 90;
                    
                %No Critical Angle
                else 
                    %Calculate new angles and Theta dependant impedances
                    app.degOutLong = asind(transLong);
                    app.degOutShear = asind(transShear);
                    nShear = 2*(1/c2Long)^2/(1/c2Shear)^2; %2*pi*f factor in each cancels
                    nLong = 1-nShear;
                    z1LongTheta = z1Long/cosd(app.degIn);
                    z2LongTheta = z2Long/cosd(app.degOutLong);
                    z2ShearTheta = z2Shear/cosd(app.degOutShear);
                    zIn = nLong^2*z2LongTheta+nShear^2*z2ShearTheta;
                    
                    %Reflection and Transmission
                    app.Rf = (zIn-z1LongTheta)^2/(zIn+z1LongTheta)^2;
                    app.Tf_l = 4*z1LongTheta*nLong^2*z2LongTheta/(z1LongTheta+zIn)^2;
                    app.Tf_s = 4*z1LongTheta*nShear^2*z2ShearTheta/(z1LongTheta+zIn)^2;
                end
                %Update Appropriate Fields
                app.ReflectedEditField.Value = abs(double(app.Rf))*100;
                app.LongEditField.Value = abs(double(app.Tf_l))*100;
                app.ShearedEditField.Value = abs(double(app.Tf_s))*100;
                app.ReflectedAngleEditField.Value = abs(double(app.degIn));
                app.LongAngleEditField.Value = abs(double(app.degOutLong));
                app.ShearAngleEditField.Value = abs(double(app.degOutShear));
            end
        end
        
        %Draws Lines to be used as Axes for the App
        function Draw_Axes(app)
            % Draw Lines on ImAxe
            plot(app.ImAxe,1,1);
            axis(app.ImAxe,'off');
            axis(app.ImAxe,[-1 1 -1 1]);
            line(app.ImAxe,[-1 1], [0 0],'LineWidth',2,'Color','k');
            line(app.ImAxe,[0 0], [-1 1],'LineWidth',2,'LineStyle','--');
            colormap(app.ImAxe,'jet');
            colorbar(app.ImAxe);
        end
        
        %Generates Arrows Based on Reflection and Transmission
        function Draw_Arrows(app)
            %Calculate incident and reflected angle
            inc = 90+app.degIn; %Incident Angle->second quadrant
            rfl = 90-app.degIn; %Reflected Angle->first quadrant
            rfr = 270+app.degOutLong;%Refracted Angle->fourth quadrant
            shear = 270+app.degOutShear;
            rflRGB = app.jetColor(round(app.Rf*63)+1,:);
            rfrRGB = app.jetColor(round(app.Tf_l*63)+1,:);
            shearRGB = app.jetColor(round(app.Tf_s*63)+1,:);
            
            hold(app.ImAxe,'on')
            %Draw Incident Arrow
            %Black Outline
             quiver(app.ImAxe, cosd(inc), sind(inc), -cosd(inc), -sind(inc), ...
                'Color','k', ...
                'LineWidth',7, ...
                'MaxHeadSize',1);
            %Arrow
            quiver(app.ImAxe, cosd(inc), sind(inc), -cosd(inc), -sind(inc), ...
                'Color',app.jetColor(end,:), ...
                'LineWidth',5, ...
                'MaxHeadSize',1);
            
            %Draw Reflected Angle
            if app.Rf > 0.01
                quiver(app.ImAxe, 0, 0, cosd(rfl), sind(rfl), ...
                'Color','k', ...
                'LineWidth',7, ...
                'MaxHeadSize',1);
            
                quiver(app.ImAxe, 0, 0, cosd(rfl), sind(rfl), ...
                'Color',rflRGB, ...
                'LineWidth',5, ...
                'MaxHeadSize',1);
            end
            
            if app.Tf_l > 0.01
                quiver(app.ImAxe, 0, 0, cosd(rfr), sind(rfr), ...
                'Color','k', ...
                'LineWidth',7, ...
                'MaxHeadSize',1);
                 
                 
                quiver(app.ImAxe, 0, 0, cosd(rfr), sind(rfr), ...
                'Color',rfrRGB, ...
                'LineWidth',5, ...
                'MaxHeadSize',1);
            end
            
            if app.Tf_s > 0.01
                 h = quiver(app.ImAxe, 0, 0, cosd(shear), sind(shear), ...
                'Color',shearRGB, ...
                'LineWidth',5, ...
                'MaxHeadSize',1, ...
                'LineStyle','--');
                 h.Head.LineStyle = 'solid'; %Change arrow head from dahed to solid
            end
        end
        
        function Draw_Angle(app)
            %Calculate incident and reflected angle
            inc = 90+app.degIn; %Incident Angle->second quadrant
            rfl = 90-app.degIn; %Reflected Angle->first quadrant
            rfr = 270+app.degOutLong;%Refracted Angle->fourth quadrant
            shear = 270+app.degOutShear;
            r = 0.5;
            %Angle between Incident and Reflected
            theta_rfl = linspace(rfl,inc,100);
            theta_rfr = linspace(270,rfr,100);
            theta_shear = linspace(270,shear,100);
            if app.Rf > 0.01
                plot(app.ImAxe,r*cosd(theta_rfl),r*sind(theta_rfl),'LineWidth',2,'Color','r');
            end
            
            if app.Tf_l > 0.01
                plot(app.ImAxe,r*cosd(theta_rfr),r*sind(theta_rfr),'LineWidth',2,'Color','b');
            end
            
            if app.Tf_s > 0.01
                plot(app.ImAxe,(r+0.15)*cosd(theta_shear),(r+0.15)*sind(theta_shear),'LineWidth',2,'Color','k');
            end
            
        end
        
        function Make_Legend(app)
            %Draw arrows indicating which arrow type reflects which wave type
               h = quiver(app.ImAxe, -1, -0.7, 0.4, 0, ...
                'Color','k', ...
                'LineWidth',5, ...
                'MaxHeadSize',1, ...
                'LineStyle','--');
                 h.Head.LineStyle = 'solid'; %Change arrow head from dahed to solid
                 
                quiver(app.ImAxe, -1, -0.9, 0.4, 0, ...
                'Color','k', ...
                'LineWidth',5, ...
                'MaxHeadSize',1);
                hold(app.ImAxe,'off'); 
            
        end
 %%%%%%%%%%%%%%%%%%
 %Private Functions
 %%%%%%%%%%%%%%%%%%
        function Generate_Graph(app)
           app.Generate_Coefficients();
           app.Draw_Axes();
           app.Draw_Arrows();
           app.Draw_Angle();
           app.Make_Legend();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Import data from excel, propegate drop down menus
            [num,txt,raw] = xlsread('./Acoustic Mirror2.xlsx');
            materials = txt(2:end,2);
            cLong = num(:,3);  %Speed of sound longitudinal direction of materials
            cShear = num(:,4);
            density = num(:,5);
            
            %Calculate Densities
            zLong = cLong.*density;
            zShear = cShear.*density;
            app.MediumSelect1.Items = {'Select Medium 1', materials{1:end,:}};
            app.MediumSelect2.Items = {'Select Medium 2', materials{1:end-2,:}};
            
            %Define maps containing indecies and their properties for future lookup
            app.cMapLong = containers.Map(materials,cLong);
            app.zMapLong = containers.Map(materials,zLong);
            app.cMapShear = containers.Map(materials,cShear);
            app.zMapShear = containers.Map(materials,zShear);
            
            %Load blank graph as default (shows gui working)
            app.Generate_Graph();
        end

        % Value changed function: MediumSelect1
        function MediumSelect1ValueChanged(app, event)
            %Update numberic edit fields based on user selection of first media
            m1 = app.MediumSelect1.Value;
            if ~strcmp(m1,'Select Medium 1')
                app.LongitudinalSpeedEditField.Value = app.cMapLong(m1)*10^4;
                app.LongImpedanceEditField.Value = app.zMapLong(m1);
                app.ShearSpeedEditField.Value = 0; %Fluids do not support shear waves
                app.ShearImpedanceEditField.Value = 0;
                %app.Generate_Graph();
            end
        end

        % Value changed function: MediumSelect2
        function MediumSelect2ValueChanged(app, event)
          %Updates based on user preference for media 2
            m2 = app.MediumSelect2.Value;
            if ~strcmp(m2,'Select Medium 2')
               app.SoundSpeedzEditField_2.Value = app.cMapLong(m2)*10^4; 
               app.ImpedancezEditField_2.Value = app.zMapLong(m2);
               app.SoundSpeedxEditField_2.Value = app.cMapShear(m2)*10^4;
               app.ImpedancexEditField_2.Value = app.zMapShear(m2);
               app.Generate_Graph();
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if app.Slider.Value == 90
                app.degIn = 89.9;
                app.EditField.Value = 90;
                app.Generate_Graph();
            else
                app.degIn = app.Slider.Value;
                app.EditField.Value = app.degIn;
                app.Generate_Graph();
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %Changing the displayed value will change the slider accordingly
            num = app.EditField.Value;
            if num >= 90
                num = 89.9;
            end
            
            app.Slider.Value = num;
            app.EditField.Value = num;
            app.degIn = app.Slider.Value;
            app.Generate_Graph();
        end

        % Button pushed function: ViewCodeButton
        function ViewCodeButtonPushed(app, event)
            open('Oblique_Live_Editor.mlx');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PanelLabel = uipanel(app.UIFigure);
            app.PanelLabel.Title = 'Medium 1 (Fluid)';
            app.LongitudinalSpeedEditFieldLabel = uilabel(app.PanelLabel);
            app.LongitudinalSpeedEditFieldLabel.Text = 'Longitudinal Speed';
            app.LongitudinalSpeedEditField = uieditfield(app.PanelLabel, 'numeric');
            app.LongitudinalSpeedEditField.Editable = 'off';
            app.LongImpedanceEditFieldLabel = uilabel(app.PanelLabel);
            app.LongImpedanceEditFieldLabel.Text = 'Long. Impedance';
            app.LongImpedanceEditField = uieditfield(app.PanelLabel, 'numeric');
            app.LongImpedanceEditField.Editable = 'off';
            app.msLabel = uilabel(app.PanelLabel);
            app.msLabel.Text = 'm/s';
            app.MRaylsLabel = uilabel(app.PanelLabel);
            app.MRaylsLabel.Text = 'MRayls';
            app.msLabel_3 = uilabel(app.PanelLabel);
            app.msLabel_3.Text = 'm/s';
            app.MRaylsLabel_3 = uilabel(app.PanelLabel);
            app.MRaylsLabel_3.Text = 'MRayls';
            app.ShearSpeedEditFieldLabel = uilabel(app.PanelLabel);
            app.ShearSpeedEditFieldLabel.Text = 'Shear Speed';
            app.ShearSpeedEditField = uieditfield(app.PanelLabel, 'numeric');
            app.ShearSpeedEditField.Editable = 'off';
            app.ShearImpedanceEditFieldLabel = uilabel(app.PanelLabel);
            app.ShearImpedanceEditFieldLabel.Text = 'Shear Impedance';
            app.ShearImpedanceEditField = uieditfield(app.PanelLabel, 'numeric');
            app.ShearImpedanceEditField.Editable = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.InputParametersLabel = uilabel(app.Panel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.SelectValueDropDownLabel = uilabel(app.UIFigure);
            app.SelectValueDropDownLabel.Text = 'Select Value';
            app.MediumSelect1 = uidropdown(app.UIFigure);
            app.MediumSelect1.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9'};
            app.MediumSelect1.ValueChangedFcn = createCallbackFcn(app, @MediumSelect1ValueChanged, true);
            app.PanelLabel_2 = uipanel(app.UIFigure);
            app.PanelLabel_2.Title = 'Medium 2 (Solid)';
            app.SoundSpeedzEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.SoundSpeedzEditField_2.Editable = 'off';
            app.ImpedancezEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.ImpedancezEditField_2.Editable = 'off';
            app.SoundSpeedxEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.SoundSpeedxEditField_2.Editable = 'off';
            app.ImpedancexEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.ImpedancexEditField_2.Editable = 'off';
            app.LongitudinalSpeedEditFieldLabel_2 = uilabel(app.PanelLabel_2);
            app.LongitudinalSpeedEditFieldLabel_2.Text = 'Longitudinal Speed';
            app.msLabel_4 = uilabel(app.PanelLabel_2);
            app.msLabel_4.Text = 'm/s';
            app.LongImpedanceEditFieldLabel_2 = uilabel(app.PanelLabel_2);
            app.LongImpedanceEditFieldLabel_2.Text = 'Long. Impedance';
            app.MRaylsLabel_4 = uilabel(app.PanelLabel_2);
            app.MRaylsLabel_4.Text = 'MRayls';
            app.ShearSpeedEditFieldLabel_2 = uilabel(app.PanelLabel_2);
            app.ShearSpeedEditFieldLabel_2.Text = 'Shear Speed';
            app.msLabel_5 = uilabel(app.PanelLabel_2);
            app.msLabel_5.Text = 'm/s';
            app.ShearImpedanceEditFieldLabel_2 = uilabel(app.PanelLabel_2);
            app.ShearImpedanceEditFieldLabel_2.Text = 'Shear Impedance';
            app.MRaylsLabel_5 = uilabel(app.PanelLabel_2);
            app.MRaylsLabel_5.Text = 'MRayls';
            app.SelectValueDropDown_2Label = uilabel(app.UIFigure);
            app.SelectValueDropDown_2Label.Text = 'Select Value';
            app.MediumSelect2 = uidropdown(app.UIFigure);
            app.MediumSelect2.Items = {'Medium 2', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9'};
            app.MediumSelect2.ValueChangedFcn = createCallbackFcn(app, @MediumSelect2ValueChanged, true);
            app.MediumSelect2.Value = 'Medium 2';
            app.AngleofIncidenceDegreesPanel = uipanel(app.UIFigure);
            app.AngleofIncidenceDegreesPanel.Title = 'Angle of Incidence (Degrees)';
            app.EditField = uieditfield(app.AngleofIncidenceDegreesPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.ReflectedAngleEditFieldLabel = uilabel(app.Panel_2);
            app.ReflectedAngleEditFieldLabel.Text = 'Reflected Angle';
            app.ReflectedAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ReflectedAngleEditField.Editable = 'off';
            app.ReflectedEditFieldLabel = uilabel(app.Panel_2);
            app.ReflectedEditFieldLabel.Text = '% Reflected';
            app.ReflectedEditField = uieditfield(app.Panel_2, 'numeric');
            app.ReflectedEditField.Editable = 'off';
            app.LongAngleLabel = uilabel(app.Panel_2);
            app.LongAngleLabel.Text = 'Long. Angle';
            app.LongAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.LongAngleEditField.Editable = 'off';
            app.LongEditFieldLabel = uilabel(app.Panel_2);
            app.LongEditFieldLabel.Text = '% Long.';
            app.LongEditField = uieditfield(app.Panel_2, 'numeric');
            app.LongEditField.Editable = 'off';
            app.ShearedLabel = uilabel(app.Panel_2);
            app.ShearedLabel.Text = '% Sheared ';
            app.ShearedEditField = uieditfield(app.Panel_2, 'numeric');
            app.ShearedEditField.Editable = 'off';
            app.ShearAngleEditFieldLabel = uilabel(app.Panel_2);
            app.ShearAngleEditFieldLabel.Text = 'Shear Angle';
            app.ShearAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ShearAngleEditField.Editable = 'off';
            app.ViewCodeButton = uibutton(app.Panel_2, 'push');
            app.ViewCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCodeButtonPushed, true);
            app.ViewCodeButton.Text = 'View Code';
            app.Panel_3 = uipanel(app.UIFigure);
            app.OutputsLabel = uilabel(app.Panel_3);
            app.OutputsLabel.Text = 'Outputs';
            app.ImAxe = uiaxes(app.UIFigure);
            xlabel(app.ImAxe, 'Z')
            ylabel(app.ImAxe, 'X')
            app.ImAxe.XLim = [0 1];
            app.ImAxe.YLim = [0 1];
            app.ImAxe.ZLim = [0 1];
            app.ImAxe.CLim = [0 1];
            app.ImAxe.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ImAxe.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImAxe.ZTick = [0 0.5 1];
            app.ShearLabel = uilabel(app.UIFigure);
            app.ShearLabel.Text = 'Shear';
            app.LongitudinalLabel = uilabel(app.UIFigure);
            app.LongitudinalLabel.Text = 'Longitudinal';
            app.LegendLabel = uilabel(app.UIFigure);
            app.LegendLabel.Text = 'Legend';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Oblique_App_With_Shear

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end