classdef joeOvliqueapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PanelLabel                    matlab.ui.container.Panel
        SoundSpeedzEditFieldLabel     matlab.ui.control.Label
        SoundSpeedzEditField          matlab.ui.control.NumericEditField
        ImpedancezEditFieldLabel      matlab.ui.control.Label
        ImpedancezEditField           matlab.ui.control.NumericEditField
        msLabel                       matlab.ui.control.Label
        MRaylsLabel                   matlab.ui.control.Label
        msLabel_3                     matlab.ui.control.Label
        MRaylsLabel_3                 matlab.ui.control.Label
        SoundSpeedxEditFieldLabel     matlab.ui.control.Label
        SoundSpeedxEditField          matlab.ui.control.NumericEditField
        ImpedancexEditField_2Label    matlab.ui.control.Label
        ImpedancexEditField_2         matlab.ui.control.NumericEditField
        Panel                         matlab.ui.container.Panel
        InputParametersLabel          matlab.ui.control.Label
        SelectValueDropDownLabel      matlab.ui.control.Label
        MediumSelect1                 matlab.ui.control.DropDown
        PanelLabel_2                  matlab.ui.container.Panel
        SoundSpeedzEditField_2Label   matlab.ui.control.Label
        SoundSpeedzEditField_2        matlab.ui.control.NumericEditField
        ImpedancezEditField_2Label    matlab.ui.control.Label
        ImpedancezEditField_2         matlab.ui.control.NumericEditField
        msLabel_2                     matlab.ui.control.Label
        MRaylsLabel_2                 matlab.ui.control.Label
        msLabel_4                     matlab.ui.control.Label
        MRaylsLabel_4                 matlab.ui.control.Label
        SoundSpeedxEditFieldLabel_2   matlab.ui.control.Label
        SoundSpeedxEditField_2        matlab.ui.control.NumericEditField
        ImpedancexEditField_2Label_2  matlab.ui.control.Label
        ImpedancexEditField_3         matlab.ui.control.NumericEditField
        SelectValueDropDown_2Label    matlab.ui.control.Label
        MediumSelect2                 matlab.ui.control.DropDown
        AngleofIncidenceDegreesPanel  matlab.ui.container.Panel
        EditField                     matlab.ui.control.NumericEditField
        Slider                        matlab.ui.control.Slider
        Panel_2                       matlab.ui.container.Panel
        ReflectedAngleEditFieldLabel  matlab.ui.control.Label
        ReflectedAngleEditField       matlab.ui.control.NumericEditField
        ReflectedEditFieldLabel       matlab.ui.control.Label
        ReflectedEditField            matlab.ui.control.NumericEditField
        TransAngleEditFieldLabel      matlab.ui.control.Label
        TransAngleEditField           matlab.ui.control.NumericEditField
        TransEditFieldLabel           matlab.ui.control.Label
        TransEditField                matlab.ui.control.NumericEditField
        ShearedLabel                  matlab.ui.control.Label
        ShearedEditField              matlab.ui.control.NumericEditField
        ShearAngleEditFieldLabel      matlab.ui.control.Label
        ShearAngleEditField           matlab.ui.control.NumericEditField
        ViewCodeButton                matlab.ui.control.Button
        Panel_3                       matlab.ui.container.Panel
        OutputsLabel                  matlab.ui.control.Label
        ImAxe                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        cMap 
        zMap
        deg_in = 0;
        deg_out = 0;
        Rf = 0; %Reflection
        Tf = 0; %Transmitted
        Sw = 0; %Shear
        
        % Color Map Use
            jetColor = [0         0    0.5625;...
                     0         0    0.6250; ...
                     0         0    0.6875;...
                     0         0    0.7500;...
                     0         0    0.8125;...
                     0         0    0.8750;...
                     0         0    0.9375;...
                     0         0    1.0000;...
                     0    0.0625    1.0000;...
                     0    0.1250    1.0000;...
                     0    0.1875    1.0000;...
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                0.5000         0         0];
    end

    methods (Access = private)
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
  %Subfunctions: Coefficients/Draw Graph
  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %Determines Reflected/Transmitted Intensities/AngleOut
        function Generate_Coefficients(app)
            c1 = app.SoundSpeedzEditField.Value;
            c2 = app.SoundSpeedzEditField_2.Value;
            Z1 = app.ImpedancezEditField.Value;
            Z2 = app.ImpedancezEditField_2.Value;
            
            %Calculate Reflection, Transmitted Intensities
            if (c1>0) && (c2>0) && (Z1>0) && (Z2>0)       
            val = sind(app.deg_in)/c1*c2;
                if val>1
                    app.deg_out = 90;
                    app.Rf = 1;
                    app.Tf = 0;
                else
                    app.deg_out = asind(val);
                    Z2theta1 = Z2*cosd( app.deg_in );
                    Z1theta2 = Z1*cosd( app.deg_out );
                    app.Rf = (Z2theta1 - Z1theta2).^2/(Z2theta1 + Z1theta2).^2;
                    % app.Tf = 4*(Z2theta1 * Z1theta2)/(Z2theta1 + Z1theta2).^2;
                    app.Tf = 1 - app.Rf;
                end
                %Update Numeric Fields
                if app.Rf > 0.01
                    app.ReflectedAngleEditField.Value = app.deg_in;
                else
                    app.ReflectedAngleEditField.Value = 0;
                end 
                
                if app.Tf > 0.01
                    app.TransAngleEditField.Value = abs(double(app.deg_out));
                else
                    app.TransAngleEditField.Value = 0;
                end
                
                app.ReflectedEditField.Value = abs(double(app.Rf));
                app.TransEditField.Value = abs(double(app.Tf));
            end    
        end
        
        %Draws Lines to be used as Axes for the App
        function Draw_Axes(app)
            % Draw Lines on ImAxe
            plot(app.ImAxe,1,1);
            axis(app.ImAxe,'off');
            axis(app.ImAxe,[-1 1 -1 1]);
            line(app.ImAxe,[-1 1], [0 0],'LineWidth',2,'Color','k');
            line(app.ImAxe,[0 0], [-1 1],'LineWidth',2,'LineStyle','--');
            colormap(app.ImAxe,'jet');
            colorbar(app.ImAxe);
        end
        
        %Generates Arrows Based on Reflection and Transmission
        function Draw_Arrows(app)
            %Calculate incident and reflected angle
            inc = 90+app.deg_in; %Incident Angle->second quadrant
            rfl = 90-app.deg_in; %Reflected Angle->first quadrant
            rfr = 270+app.deg_out;%Refracted Angle->fourth quadrant
            rflRGB = app.jetColor(round(app.Rf*63)+1,:);
            rfrRGB = app.jetColor(round(app.Tf*63)+1,:);
            
            hold(app.ImAxe,'on')
            %Draw Incident Arrow
            quiver(app.ImAxe, cosd(inc), sind(inc), -cosd(inc), -sind(inc), ...
                'Color',app.jetColor(end,:), ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            %Draw Reflected Angle
            if app.Rf > 0.01
                quiver(app.ImAxe, 0, 0, cosd(rfl), sind(rfl), ...
                'Color',rflRGB, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
            
            if app.Tf > 0.01
                quiver(app.ImAxe, 0, 0, cosd(rfr), sind(rfr), ...
                'Color',rfrRGB, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
        end
        
        function Draw_Angle(app)
            %Calculate incident and reflected angle
            inc = 90+app.deg_in; %Incident Angle->second quadrant
            rfl = 90-app.deg_in; %Reflected Angle->first quadrant
            rfr = 270+app.deg_out;%Refracted Angle->fourth quadrant
            r = 0.5;
            %Angle between Incident and Reflected
            theta_rfl = linspace(rfl,inc,100);
            theta_rfr = linspace(270,rfr,100);
            if app.Rf > 0.01
                plot(app.ImAxe,r*cosd(theta_rfl),r*sind(theta_rfl),'LineWidth',2,'Color','r');
            end
            
            if app.Tf > 0.01
                plot(app.ImAxe,r*cosd(theta_rfr),r*sind(theta_rfr),'LineWidth',2,'Color','b');
            end
            hold(app.ImAxe,'off');
        end
 %%%%%%%%%%%%%%%%%%
 %Private Functions
 %%%%%%%%%%%%%%%%%%
        function Generate_Graph(app)
           app.Generate_Coefficients();
           app.Draw_Axes();
           app.Draw_Arrows();
           app.Draw_Angle();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Import data from excel, propegate drop down menus
            [num,txt,raw] = xlsread('./3_oblique/resources/data.xlsx','Tissue');
            tissues = txt(2:end,1);
            app.MediumSelect1.Items = {'Select Medium 1', tissues{:}};
            app.MediumSelect2.Items = {'Select Medium 2', tissues{:}};
            
            %Define maps containing indecies and their properties for future lookup
            keyset = txt(2:end,1);
            cVal = num(:,1);
            zVal = num(:,2);
            app.cMap = containers.Map(keyset,cVal);
            app.zMap = containers.Map(keyset,zVal);
        end

        % Value changed function: MediumSelect1
        function MediumSelect1ValueChanged(app, event)
            %Update numberic edit fields based on user selection of first media
            m1 = app.MediumSelect1.Value;
            if ~strcmp(m1,'Select Medium')
                app.SoundSpeedzEditField.Value = app.cMap(m1);
                app.ImpedancezEditField.Value = app.zMap(m1);
                app.Generate_Graph();
            end
        end

        % Value changed function: MediumSelect2
        function MediumSelect2ValueChanged(app, event)
          %Updates based on user preference for media 2
            m2 = app.MediumSelect2.Value;
            if ~strcmp(m2,'Select Medium')
               app.SoundSpeedzEditField_2.Value = app.cMap(m2); 
               app.ImpedancezEditField_2.Value = app.zMap(m2);
               app.Generate_Graph();
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.deg_in = app.Slider.Value;
            app.EditField.Value = app.deg_in;
            app.Generate_Graph();
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            %Changing the displayed value will change the slider accordingly
            app.Slider.Value = app.EditField.Value;
            app.deg_in = app.Slider.Value;
            app.Generate_Graph();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PanelLabel = uipanel(app.UIFigure);
            app.PanelLabel.Title = 'Medium 1';
            app.SoundSpeedzEditFieldLabel = uilabel(app.PanelLabel);
            app.SoundSpeedzEditFieldLabel.Text = 'Sound Speed(z)';
            app.SoundSpeedzEditField = uieditfield(app.PanelLabel, 'numeric');
            app.ImpedancezEditFieldLabel = uilabel(app.PanelLabel);
            app.ImpedancezEditFieldLabel.Text = 'Impedance(z)';
            app.ImpedancezEditField = uieditfield(app.PanelLabel, 'numeric');
            app.msLabel = uilabel(app.PanelLabel);
            app.msLabel.Text = 'm/s';
            app.MRaylsLabel = uilabel(app.PanelLabel);
            app.MRaylsLabel.Text = 'MRayls';
            app.msLabel_3 = uilabel(app.PanelLabel);
            app.msLabel_3.Text = 'm/s';
            app.MRaylsLabel_3 = uilabel(app.PanelLabel);
            app.MRaylsLabel_3.Text = 'MRayls';
            app.SoundSpeedxEditFieldLabel = uilabel(app.PanelLabel);
            app.SoundSpeedxEditFieldLabel.Text = 'Sound Speed(x)';
            app.SoundSpeedxEditField = uieditfield(app.PanelLabel, 'numeric');
            app.ImpedancexEditField_2Label = uilabel(app.PanelLabel);
            app.ImpedancexEditField_2Label.Text = 'Impedance(x)';
            app.ImpedancexEditField_2 = uieditfield(app.PanelLabel, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.InputParametersLabel = uilabel(app.Panel);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.SelectValueDropDownLabel = uilabel(app.UIFigure);
            app.SelectValueDropDownLabel.Text = 'Select Value';
            app.MediumSelect1 = uidropdown(app.UIFigure);
            app.MediumSelect1.Items = {'Option 1', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9'};
            app.MediumSelect1.ValueChangedFcn = createCallbackFcn(app, @MediumSelect1ValueChanged, true);
            app.PanelLabel_2 = uipanel(app.UIFigure);
            app.PanelLabel_2.Title = 'Medium 2';
            app.SoundSpeedzEditField_2Label = uilabel(app.PanelLabel_2);
            app.SoundSpeedzEditField_2Label.Text = 'Sound Speed(z)';
            app.SoundSpeedzEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.ImpedancezEditField_2Label = uilabel(app.PanelLabel_2);
            app.ImpedancezEditField_2Label.Text = 'Impedance(z)';
            app.ImpedancezEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.msLabel_2 = uilabel(app.PanelLabel_2);
            app.msLabel_2.Text = 'm/s';
            app.MRaylsLabel_2 = uilabel(app.PanelLabel_2);
            app.MRaylsLabel_2.Text = 'MRayls';
            app.msLabel_4 = uilabel(app.PanelLabel_2);
            app.msLabel_4.Text = 'm/s';
            app.MRaylsLabel_4 = uilabel(app.PanelLabel_2);
            app.MRaylsLabel_4.Text = 'MRayls';
            app.SoundSpeedxEditFieldLabel_2 = uilabel(app.PanelLabel_2);
            app.SoundSpeedxEditFieldLabel_2.Text = 'Sound Speed(x)';
            app.SoundSpeedxEditField_2 = uieditfield(app.PanelLabel_2, 'numeric');
            app.ImpedancexEditField_2Label_2 = uilabel(app.PanelLabel_2);
            app.ImpedancexEditField_2Label_2.Text = 'Impedance(x)';
            app.ImpedancexEditField_3 = uieditfield(app.PanelLabel_2, 'numeric');
            app.SelectValueDropDown_2Label = uilabel(app.UIFigure);
            app.SelectValueDropDown_2Label.Text = 'Select Value';
            app.MediumSelect2 = uidropdown(app.UIFigure);
            app.MediumSelect2.Items = {'Medium 2', 'Option 2', 'Option 3', 'Option 4', 'Option 5', 'Option 6', 'Option 7', 'Option 8', 'Option 9'};
            app.MediumSelect2.ValueChangedFcn = createCallbackFcn(app, @MediumSelect2ValueChanged, true);
            app.MediumSelect2.Value = 'Medium 2';
            app.AngleofIncidenceDegreesPanel = uipanel(app.UIFigure);
            app.AngleofIncidenceDegreesPanel.Title = 'Angle of Incidence (Degrees)';
            app.EditField = uieditfield(app.AngleofIncidenceDegreesPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 90];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.ReflectedAngleEditFieldLabel = uilabel(app.Panel_2);
            app.ReflectedAngleEditFieldLabel.Text = 'Reflected Angle';
            app.ReflectedAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ReflectedEditFieldLabel = uilabel(app.Panel_2);
            app.ReflectedEditFieldLabel.Text = '% Reflected';
            app.ReflectedEditField = uieditfield(app.Panel_2, 'numeric');
            app.TransAngleEditFieldLabel = uilabel(app.Panel_2);
            app.TransAngleEditFieldLabel.Text = 'Trans. Angle';
            app.TransAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.TransEditFieldLabel = uilabel(app.Panel_2);
            app.TransEditFieldLabel.Text = '% Trans.';
            app.TransEditField = uieditfield(app.Panel_2, 'numeric');
            app.ShearedLabel = uilabel(app.Panel_2);
            app.ShearedLabel.Text = '% Sheared ';
            app.ShearedEditField = uieditfield(app.Panel_2, 'numeric');
            app.ShearAngleEditFieldLabel = uilabel(app.Panel_2);
            app.ShearAngleEditFieldLabel.Text = 'Shear Angle';
            app.ShearAngleEditField = uieditfield(app.Panel_2, 'numeric');
            app.ViewCodeButton = uibutton(app.Panel_2, 'push');
            app.ViewCodeButton.Text = 'View Code';
            app.Panel_3 = uipanel(app.UIFigure);
            app.OutputsLabel = uilabel(app.Panel_3);
            app.OutputsLabel.Text = 'Outputs';
            app.ImAxe = uiaxes(app.UIFigure);
            xlabel(app.ImAxe, 'Z')
            ylabel(app.ImAxe, 'X')
            app.ImAxe.XLim = [0 1];
            app.ImAxe.YLim = [0 1];
            app.ImAxe.ZLim = [0 1];
            app.ImAxe.CLim = [0 1];
            app.ImAxe.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ImAxe.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImAxe.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = joeOvliqueapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end