classdef MultiLayerReflection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UserInputsPanel           matlab.ui.container.Panel
        NumberMediaDropDownLabel  matlab.ui.control.Label
        NumberMediaDropDown       matlab.ui.control.DropDown
        PlaySwitchLabel           matlab.ui.control.Label
        PlaySwitch                matlab.ui.control.Switch
        AngleInKnobLabel          matlab.ui.control.Label
        AngleInKnob               matlab.ui.control.DiscreteKnob
        SelectMediaPanel          matlab.ui.container.Panel
        Media1DropDownLabel       matlab.ui.control.Label
        MediumSelect1             matlab.ui.control.DropDown
        LongImpedanceEditField    matlab.ui.control.NumericEditField
        MRaylsLabel               matlab.ui.control.Label
        MRaylsLabel_2             matlab.ui.control.Label
        LongImpedanceEditField_2  matlab.ui.control.NumericEditField
        MediumSelect2             matlab.ui.control.DropDown
        Media1DropDownLabel_2     matlab.ui.control.Label
        ImAxe                     matlab.ui.control.UIAxes
        OutputsPanel              matlab.ui.container.Panel
        O1GaugeLabel              matlab.ui.control.Label
        O1Gauge                   matlab.ui.control.NinetyDegreeGauge
        O2GaugeLabel              matlab.ui.control.Label
        O2Gauge                   matlab.ui.control.NinetyDegreeGauge
    end


    properties (Access = private)
        cMapLong 
        zMapLong
        cMapShear 
        zMapShear
        degIn = 0;
        degOut = 0;
        %degOutShear = 0;
        Rf = 0; %Reflection
        Tf = 0; %Transmitted in Longitudinal Direction (z)
        %Tf_s = 0;%Transmitted in Shear Horizontal Direction (x)
        
        % Color Map Use
            jetColor = [0         0    0.5625;...
                     0         0    0.6250; ...
                     0         0    0.6875;...
                     0         0    0.7500;...
                     0         0    0.8125;...
                     0         0    0.8750;...
                     0         0    0.9375;...
                     0         0    1.0000;...
                     0    0.0625    1.0000;...
                     0    0.1250    1.0000;...
                     0    0.1875    1.0000;...
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                0.5000         0         0];
    end

    methods (Access = private)
    
        function Draw_Line(app)
            % Split Axes into divisions based on number of desired media
            div = str2num(app.NumberMediaDropDown.Value);
            
            %No need to draw a line if only 1 media!
            if div == 1
                plot(app.ImAxe,1,1);
                axis(app.ImAxe,'off');
                axis(app.ImAxe,[0 1 0 1]);
                return
            end 
            
            %Start plot, define axis range
            plot(app.ImAxe,1,1);
            axis(app.ImAxe,'off');
            axis(app.ImAxe,[0 1 0 1]);
            
            %Draw Lines
            for i = 1:div-1
                line(app.ImAxe,[0 1], [i/div i/div],'LineWidth',2,'Color','k');
            end
            %Define color scheme used by MATLAB moving forward (note our jetColor property colors this!)
            colormap(app.ImAxe,'jet');
            colorbar(app.ImAxe);
        end
        
        function drawOne(app)
            
        end
        
        function Generate_Coefficients(app)
            Z1 = app.LongImpedanceEditField.Value;
            Z2 = app.LongImpedanceEditField_2.Value;
             %Calculate Reflection, Transmitted Intensities
            if (Z1>0) && (Z2>0) 
                c1 = app.cMapLong(app.MediumSelect1.Value)*10^4;
                c2 = app.cMapLong(app.MediumSelect2.Value)*10^4;    
            val = sind(app.degIn)/c1*c2;
            
                if val>1
                    app.degOut = 90;
                    app.Rf = 1;
                    app.Tf = 0;
                else
                    app.degOut = asind(val);
                    Z2theta1 = Z2*cosd( app.degIn );
                    Z1theta2 = Z1*cosd( app.degOut );
                    app.Rf = (Z2theta1 - Z1theta2).^2/(Z2theta1 + Z1theta2).^2;
                    % app.Tf = 4*(Z2theta1 * Z1theta2)/(Z2theta1 + Z1theta2).^2;
                    app.Tf = 1 - app.Rf;
                end
            end    
        end
        
        function Propegate(app)
            angle = 45;
            step = 0.01; %Size of line I draw after each time interval
            colorStart = app.jetColor(round(1*63)+1,:);
            x2 = 0;
            y2 = 0;
            rflRGB = app.jetColor(round(app.Rf*63)+1,:);
            rfrRGB = app.jetColor(round(app.Tf*63)+1,:);
            MediumMet = false;
            
            while(y2 <= 0.5)
                x1 = x2;
                y1 = y2;
                x2 = x1+step*cosd(angle);
                y2 = y1+step*sin(angle);
                line(app.ImAxe,[x1 x2], [y1 y2],'LineWidth',2,'Color',colorStart);
                pause(0.075)
            end   
            
            angleRfr = app.degOut;
            x1Rfl = x2;
            y1Rfl = y2;
            x2Rfl = x1Rfl+step*cosd(-angle);
            y2Rfl = y1Rfl+step*sind(-angle); 
            
            x1Rfr = x2;
            y1Rfr = y2;
            x2Rfr = x1Rfr+step*cosd(angleRfr);
            y2Rfr = y1Rfr+step*sind(angleRfr);  
            
            while(y2Rfl  >= 0)
                line(app.ImAxe,[x1Rfl x2Rfl], [y1Rfl y2Rfl],'LineWidth',2,'Color',rflRGB);
                line(app.ImAxe,[x1Rfr x2Rfr], [y1Rfr y2Rfr],'LineWidth',2,'Color',rfrRGB);
                
                x1Rfl = x2Rfl;
                y1Rfl = y2Rfl;
                x2Rfl = x1Rfl+step*cosd(-angle);
                y2Rfl = y1Rfl+step*sind(-angle);
                
                x1Rfr = x2Rfr;
                y1Rfr = y2Rfr;
                x2Rfr = x1Rfr+step*cosd(angleRfr);
                y2Rfr = y1Rfr+step*sind(angleRfr);
                pause(0.075)
                
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Create a default graph users may use           
            app.Draw_Line();
            %Load Excel File containing media info
            %Import data from excel, propegate drop down menus
            [num,txt,raw] = xlsread('./MultiLayerReflection_Medias.xlsx');
            materials = txt(2:end,1);
            cLong = num(:,1);  %Speed of sound longitudinal direction of materials
            cShear = num(:,2);
            density = num(:,3);
            
            %Calculate Densities
            zLong = cLong.*density;
            zShear = cShear.*density;
            app.MediumSelect1.Items = {'Select Medium 1', materials{1:end,:}};
            app.MediumSelect2.Items = {'Select Medium 2', materials{1:end,:}};
            
            %Define maps containing indecies and their properties for future lookup
            app.cMapLong = containers.Map(materials,cLong);
            app.zMapLong = containers.Map(materials,zLong);
            app.cMapShear = containers.Map(materials,cShear);
            app.zMapShear = containers.Map(materials,zShear);
        end

        % Value changed function: NumberMediaDropDown
        function NumberMediaDropDownValueChanged(app, event)
           %When the dropdown changes, draw lines for the media divides
            app.Draw_Line()  
        end

        % Value changed function: PlaySwitch
        function PlaySwitchValueChanged(app, event)
            if app.PlaySwitch.Value == 'On'
                app.Generate_Coefficients();
                app.Propegate();
            end
        end

        % Value changed function: LongImpedanceEditField
        function LongImpedanceEditFieldValueChanged(app, event)
            
        end

        % Value changed function: LongImpedanceEditField_2
        function LongImpedanceEditField_2ValueChanged(app, event)

        end

        % Value changed function: MediumSelect1
        function MediumSelect1ValueChanged(app, event)
             %Update numberic edit fields based on user selection of first media
            m1 = app.MediumSelect1.Value;
            if ~strcmp(m1,'Select Medium 1')
                app.LongImpedanceEditField.Value = app.zMapLong(m1);
            end
        end

        % Value changed function: MediumSelect2
        function MediumSelect2ValueChanged(app, event)
              %Update numberic edit fields based on user selection of first media
            m2 = app.MediumSelect2.Value;
            if ~strcmp(m2,'Select Medium 1')
                app.LongImpedanceEditField_2.Value = app.zMapLong(m2);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.NumberMediaDropDownLabel = uilabel(app.UserInputsPanel);
            app.NumberMediaDropDownLabel.Text = 'Number Media';
            app.NumberMediaDropDown = uidropdown(app.UserInputsPanel);
            app.NumberMediaDropDown.Items = {'2', '1', '3', '4'};
            app.NumberMediaDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberMediaDropDownValueChanged, true);
            app.NumberMediaDropDown.Value = '2';
            app.PlaySwitchLabel = uilabel(app.UserInputsPanel);
            app.PlaySwitchLabel.Text = 'Play';
            app.PlaySwitch = uiswitch(app.UserInputsPanel, 'slider');
            app.PlaySwitch.ValueChangedFcn = createCallbackFcn(app, @PlaySwitchValueChanged, true);
            app.AngleInKnobLabel = uilabel(app.UserInputsPanel);
            app.AngleInKnobLabel.Text = 'Angle In';
            app.AngleInKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.AngleInKnob.Items = {'0', '30', '60', '90'};
            app.AngleInKnob.Value = '0';
            app.SelectMediaPanel = uipanel(app.UIFigure);
            app.SelectMediaPanel.Title = 'Select Media';
            app.Media1DropDownLabel = uilabel(app.SelectMediaPanel);
            app.Media1DropDownLabel.Text = 'Media 1';
            app.MediumSelect1 = uidropdown(app.SelectMediaPanel);
            app.MediumSelect1.ValueChangedFcn = createCallbackFcn(app, @MediumSelect1ValueChanged, true);
            app.LongImpedanceEditField = uieditfield(app.SelectMediaPanel, 'numeric');
            app.LongImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @LongImpedanceEditFieldValueChanged, true);
            app.MRaylsLabel = uilabel(app.SelectMediaPanel);
            app.MRaylsLabel.Text = 'MRayls';
            app.MRaylsLabel_2 = uilabel(app.SelectMediaPanel);
            app.MRaylsLabel_2.Text = 'MRayls';
            app.LongImpedanceEditField_2 = uieditfield(app.SelectMediaPanel, 'numeric');
            app.LongImpedanceEditField_2.ValueChangedFcn = createCallbackFcn(app, @LongImpedanceEditField_2ValueChanged, true);
            app.MediumSelect2 = uidropdown(app.SelectMediaPanel);
            app.MediumSelect2.ValueChangedFcn = createCallbackFcn(app, @MediumSelect2ValueChanged, true);
            app.Media1DropDownLabel_2 = uilabel(app.SelectMediaPanel);
            app.Media1DropDownLabel_2.Text = 'Media 2';
            app.ImAxe = uiaxes(app.UIFigure);
            title(app.ImAxe, 'Title')
            xlabel(app.ImAxe, 'X')
            ylabel(app.ImAxe, 'Y')
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.O1GaugeLabel = uilabel(app.OutputsPanel);
            app.O1GaugeLabel.Text = 'O1';
            app.O1Gauge = uigauge(app.OutputsPanel, 'ninetydegree');
            app.O1Gauge.Limits = [0 90];
            app.O2GaugeLabel = uilabel(app.OutputsPanel);
            app.O2GaugeLabel.Text = 'O2';
            app.O2Gauge = uigauge(app.OutputsPanel, 'ninetydegree');
        end
    end

    methods (Access = public)

        % Construct app
        function app = MultiLayerReflection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end