classdef ThreeLayerPressure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UserInputsPanel            matlab.ui.container.Panel
        ImpedanceLayer1MRaylsSliderLabel  matlab.ui.control.Label
        Impedance1Slider           matlab.ui.control.Slider
        ImpedanceLayer2MRaylsSliderLabel  matlab.ui.control.Label
        Impedance2Slider           matlab.ui.control.Slider
        ImpedanceLayer3MRaylsSliderLabel  matlab.ui.control.Label
        Impedance3Slider           matlab.ui.control.Slider
        Label                      matlab.ui.control.Label
        Impedance1EditField        matlab.ui.control.NumericEditField
        Impedance2EditField        matlab.ui.control.NumericEditField
        Impedance3EditField        matlab.ui.control.NumericEditField
        IncidentAngleKnobLabel     matlab.ui.control.Label
        IncidentAngleKnob          matlab.ui.control.Knob
        Thicness2Label             matlab.ui.control.Label
        Thickness2Knob             matlab.ui.control.Knob
        FrequencyLabel             matlab.ui.control.Label
        FrequencyKnob              matlab.ui.control.Knob
        IncidentAngleEditField     matlab.ui.control.NumericEditField
        Thickness2EditField        matlab.ui.control.NumericEditField
        FrequencyEditField         matlab.ui.control.NumericEditField
        SpeedofSoundLayer2msLabel  matlab.ui.control.Label
        SpeedofSound2Slider        matlab.ui.control.Slider
        SpeedofSound2EditField     matlab.ui.control.NumericEditField
        OutputsPanel               matlab.ui.container.Panel
        Reflection12Label          matlab.ui.control.Label
        Transmitted12Label         matlab.ui.control.Label
        Transmitted23Label         matlab.ui.control.Label
        Reflection23Label          matlab.ui.control.Label
        Reflection1EditField       matlab.ui.control.NumericEditField
        Reflection2EditField       matlab.ui.control.NumericEditField
        Transmission2EditField     matlab.ui.control.NumericEditField
        Transmission1EditField     matlab.ui.control.NumericEditField
        Layer2AngleLabel           matlab.ui.control.Label
        Layer3AngleLabel           matlab.ui.control.Label
        Angle2EditField            matlab.ui.control.NumericEditField
        Angle3EditField            matlab.ui.control.NumericEditField
        ViewCodeButton             matlab.ui.control.Button
        ImAxe                      matlab.ui.control.UIAxes
        Layer1AirLabel             matlab.ui.control.Label
        Layer3AirLabel             matlab.ui.control.Label
        Layer2ThicknessLabel       matlab.ui.control.Label
        ImAxe2                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %Impedance of each layer
        Z1 = 7.5;
        Z2 = 2.5;
        Z3 = 5;
        
        %Incident angle and outputs
        degIn = 0;
        degOut1
        degOut2
        
        %Reflection and Transmission
        Rf12
        Tf12
        Rf23
        Tf23
        
        %Other Parameters
        Thickness = 5; %Thickness of layer 2 in wavelengths
        Frequency = 10; %Frequency of sound
        SpeedofSound2 = 1500;
        
                % Color Map Use
            jetColor = [0         0    0.5625;...
                     0         0    0.6250; ...
                     0         0    0.6875;...
                     0         0    0.7500;...
                     0         0    0.8125;...
                     0         0    0.8750;...
                     0         0    0.9375;...
                     0         0    1.0000;...
                     0    0.0625    1.0000;...
                     0    0.1250    1.0000;...
                     0    0.1875    1.0000;...
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                0.5000         0         0];
    end

    methods (Access = private)
    
        function setUpGraph(app)
            %Setup new axis and declare range (0->1, 0->1)
            plot(app.ImAxe,1,1);
            axis(app.ImAxe,'off');
            axis(app.ImAxe,[0 1 0 1]);
            
            %Draw layer boundaries
            line(app.ImAxe,[0 1],[0.35 0.35], 'LineWidth',2,'Color','k','LineStyle','--'); %line function using x parameters in first brackets, y in second
            line(app.ImAxe,[0 1], [0.65 0.65], 'LineWidth',2,'Color','k','LineStyle','--');
        end
        
        function generateCoefficients(app)
            snell12 = sind(app.degIn)/1000*app.SpeedofSound2;
            if snell12 >= 1
                snell23 = 0;
            else 
                snell23 = sind(asind(snell12))/app.SpeedofSound2*1000;
            end
            
            %If first layer critical angle exceeded
            if snell12 >= 1
                app.Rf12 = 1;
                app.Tf12 = 0;
                app.Rf23 = 0;
                app.Tf23 = 0;
                app.degOut1 = 0;
                app.degOut2 = 0;
            
            %Second Layer critical angle exceeded
            elseif snell23 >= 1
                app.Rf23 = 1;
                app.Tf23 = 0;
                %Generate first interface coefficients
                app.degOut1 = asind(snell12);
                app.degOut2 = 0;
                Z2theta1 = app.Z2*cosd( app.degIn);
                Z1theta2 = app.Z1*cosd( app.degOut1 );
                app.Rf12 = (Z2theta1 - Z1theta2).^2/(Z2theta1 + Z1theta2).^2;
                app.Tf12 = 1 - app.Rf;
            else
                %First Interface
                app.degOut1 = asind(snell12);
                Z2theta1 = app.Z2*cosd( app.degIn);
                Z1theta2 = app.Z1*cosd( app.degOut1 );
                app.Rf12 = (Z2theta1 - Z1theta2).^2/(Z2theta1 + Z1theta2).^2;
                app.Tf12 = 1 - app.Rf12;
                %Second Interface
                app.degOut2 = asind(snell23);
                Z3theta1 = app.Z3*cosd( app.degOut1 );
                Z2theta2 = app.Z2*cosd( app.degOut2 );
                app.Rf23 = (Z3theta1 - Z2theta2).^2/(Z3theta1 + Z2theta2).^2*app.Tf12;
                app.Tf23 = (1 - app.Rf23)*app.Tf12;
            end
            %Update Fields
            app.Reflection1EditField.Value = app.Rf12;
            app.Transmission1EditField.Value = app.Tf12;
            app.Reflection2EditField.Value = app.Rf23;
            app.Transmission2EditField.Value = app.Tf23;
            app.Angle2EditField.Value = app.degOut1;
            app.Angle3EditField.Value = app.degOut2;          
        end
        
        function drawArrows(app)
            %Calculate incident and reflected angle
            inc = 90+app.degIn; %Incident Angle->second quadrant
            rfl1 = 90-app.degOut1; %Reflected Angle->first quadrant
            rfr1 = 270+app.degOut1;%Refracted Angle->fourth quadrant
            rfl2 = 90-app.degOut2; %Reflected Angle->first quadrant
            rfr2 = 270+app.degOut2;%Refracted Angle->fourth quadrant
            
            rflRGB1 = app.jetColor(round(app.Rf12*63)+1,:);
            rfrRGB1 = app.jetColor(round(app.Tf12*63)+1,:);
            rflRGB2 = app.jetColor(round(app.Rf23*63)+1,:);
            rfrRGB2 = app.jetColor(round(app.Tf23*63)+1,:);
            
            hold(app.ImAxe,'on')
            %Draw Incident Arrow
            quiver(app.ImAxe, 0.15, 0.80*sind(inc)+0.1, -0.25*cosd(inc), -0.25*sind(inc), ...
                'Color',app.jetColor(end,:), ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            %Draw Reflected 1-2
            if app.Rf12 > 0.01
                quiver(app.ImAxe, 0.35, 0.66, 0.25*cosd(rfl1), 0.25*sind(rfl1), ...
                'Color',rflRGB1, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
            %Draw Transmission 1-2
            if app.Tf12 > 0.01
                quiver(app.ImAxe, 0.15, 0.62, 0.25*cosd(rfr1), 0.25*sind(rfr1), ...
                'Color',rfrRGB1, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
            %Draw Reflected 2-3
            if app.Rf23 > 0.01
                quiver(app.ImAxe, 0.35, 0.38, 0.25*cosd(rfl2), 0.25*sind(rfl2), ...
                'Color',rflRGB2, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
            %Draw Transmission 2-3
            if app.Tf23 > 0.01
                quiver(app.ImAxe, 0.15, 0.33, 0.25*cosd(rfr2), 0.25*sind(rfr2), ...
                'Color',rfrRGB2, ...
                'LineWidth',10, ...
                'MaxHeadSize',1);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.setUpGraph();
            app.generateCoefficients();
            app.drawArrows();
        end

        % Value changed function: Impedance1EditField
        function Impedance1EditFieldValueChanged(app, event)
            value = app.Impedance1EditField.Value;
            if value <= 15 && value >= 0
                app.Z1 = value;
                app.Impedance1Slider.Value = value;
            elseif value < 0
                app.Z1 = 0;
                app.Impedance1Slider.Value = 0;
                app.Impedance1EditField.Value = 0;
            else
                app.Z1 = 15;
                app.Impedance1Slider.Value = 15;
                app.Impedance1EditField.Value = 15;
            end
        end

        % Value changed function: Impedance2EditField
        function Impedance2EditFieldValueChanged(app, event)
            value = app.Impedance2EditField.Value;
            if value <= 15 && value >= 0
                app.Z2 = value;
                app.Impedance2Slider.Value = value;
            elseif value < 0
                app.Z2 = 0;
                app.Impedance2Slider.Value = 0;
                app.Impedance2EditField.Value = 0;
            else
                app.Z2 = 15;
                app.Impedance2Slider.Value = 15;
                app.Impedance2EditField.Value = 15;
            end
        end

        % Value changed function: Impedance3EditField
        function Impedance3EditFieldValueChanged(app, event)
            value = app.Impedance3EditField.Value;
            if value <= 15 && value >= 0
                app.Z3 = value;
                app.Impedance3Slider.Value = value;
            elseif value < 0
                app.Z3 = 0;
                app.Impedance3Slider.Value = 0;
                app.Impedance3EditField.Value = 0;
            else
                app.Z3 = 15;
                app.Impedance3Slider.Value = 15;
                app.Impedance3EditField.Value = 15;
            end
        end

        % Value changed function: Impedance1Slider
        function Impedance1SliderValueChanged(app, event)
            value = app.Impedance1Slider.Value;
            app.Z1 = value;
            app.Impedance1EditField.Value = value;
        end

        % Value changed function: Impedance2Slider
        function Impedance2SliderValueChanged(app, event)
            value = app.Impedance2Slider.Value;
            app.Z2 = value;
            app.Impedance2EditField.Value = value;
        end

        % Value changed function: Impedance3Slider
        function Impedance3SliderValueChanged(app, event)
            value = app.Impedance3Slider.Value;
            app.Z3 = value;
            app.Impedance3EditField.Value = value;
        end

        % Value changed function: IncidentAngleKnob
        function IncidentAngleKnobValueChanged(app, event)
            value = app.IncidentAngleKnob.Value;
            if value == 90
                app.degIn = 89.9;
                app.IncidentAngleEditField.Value = 89.9;
            else
                app.degIn = value;
                app.IncidentAngleEditField.Value = value;
            end
        end

        % Value changed function: Thickness2Knob
        function Thickness2KnobValueChanged(app, event)
            value = app.Thickness2Knob.Value;
            app.Thickness = value;
            app.Thickness2EditField.Value = value;
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            value = app.FrequencyKnob.Value;
            app.Frequency = value;
            app.FrequencyEditField.Value = value;
        end

        % Value changed function: IncidentAngleEditField
        function IncidentAngleEditFieldValueChanged(app, event)
            value = app.IncidentAngleEditField.Value;
            if value <= 90 && value >= 0
                app.degIn = value;
                app.IncidentAngleKnob.Value = value;
            elseif value < 0
                app.degIn = 0;
                app.IncidentAngleKnob.Value = 0;
                app.IncidentAngleEditField.Value = 0;
            else
                app.degIn = 90;
                app.IncidentAngleKnob.Value = 90;
                app.IncidentAngleEditField.Value = 90;
            end
        end

        % Value changed function: Thickness2EditField
        function Thickness2EditFieldValueChanged(app, event)
            value = app.Thickness2EditField.Value;
            if value <= 50 && value >= 0
                app.Thickness = value;
                app.Thickness2Knob.Value = value;
            elseif value < 0
                app.Thickness = 0;
                app.Thickness2Knob.Value = 0;
                app.Thickness2EditField.Value = 0;
            else
                app.Thickness = 50;
                app.Thickness2Knob.Value = 50;
                app.Thickness2EditField.Value = 50;
            end
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            if value <= 100 && value >= 0
                app.Frequency = value;
                app.FrequencyKnob.Value = value;
            elseif value < 0
                app.Frequency = 0;
                app.FrequencyKnob.Value = 0;
                app.FrequencyEditField.Value = 0;
            else
                app.Frequency = 100;
                app.FrequencyKnob.Value = 100;
                app.FrequencyEditField.Value = 100;
            end
        end

        % Value changed function: SpeedofSound2Slider
        function SpeedofSound2SliderValueChanged(app, event)
            value = app.SpeedofSound2Slider.Value;
            app.SpeedofSound2 = value;
            app.SpeedofSound2EditField.Value = value;
        end

        % Value changed function: SpeedofSound2EditField
        function SpeedofSound2EditFieldValueChanged(app, event)
            value = app.SpeedofSound2EditField.Value;
            if value <= 5000 && value >= 0
                app.SpeedofSound2 = value;
                app.SpeedofSound2EditField.Value = value;
            elseif value < 0
                app.SpeedofSound2 = 0;
                app.SpeedofSound2EditField.Value = 0;
            else
                app.SpeedofSound2 = 5000;
                app.SpeedofSound2EditField.Value = 5000;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.ImpedanceLayer1MRaylsSliderLabel = uilabel(app.UserInputsPanel);
            app.ImpedanceLayer1MRaylsSliderLabel.Text = {'Impedance Layer 1'; '(MRayls)                   '};
            app.Impedance1Slider = uislider(app.UserInputsPanel);
            app.Impedance1Slider.Limits = [0 15];
            app.Impedance1Slider.ValueChangedFcn = createCallbackFcn(app, @Impedance1SliderValueChanged, true);
            app.Impedance1Slider.Value = 7.5;
            app.ImpedanceLayer2MRaylsSliderLabel = uilabel(app.UserInputsPanel);
            app.ImpedanceLayer2MRaylsSliderLabel.Text = {'Impedance Layer 2'; '(MRayls)                   '};
            app.Impedance2Slider = uislider(app.UserInputsPanel);
            app.Impedance2Slider.Limits = [0 15];
            app.Impedance2Slider.ValueChangedFcn = createCallbackFcn(app, @Impedance2SliderValueChanged, true);
            app.Impedance2Slider.Value = 2.5;
            app.ImpedanceLayer3MRaylsSliderLabel = uilabel(app.UserInputsPanel);
            app.ImpedanceLayer3MRaylsSliderLabel.Text = {'Impedance Layer 3'; '(MRayls)                   '};
            app.Impedance3Slider = uislider(app.UserInputsPanel);
            app.Impedance3Slider.Limits = [0 15];
            app.Impedance3Slider.ValueChangedFcn = createCallbackFcn(app, @Impedance3SliderValueChanged, true);
            app.Impedance3Slider.Value = 5;
            app.Label = uilabel(app.UserInputsPanel);
            app.Label.Text = '';
            app.Impedance1EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.Impedance1EditField.ValueChangedFcn = createCallbackFcn(app, @Impedance1EditFieldValueChanged, true);
            app.Impedance1EditField.Value = 7.5;
            app.Impedance2EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.Impedance2EditField.ValueChangedFcn = createCallbackFcn(app, @Impedance2EditFieldValueChanged, true);
            app.Impedance2EditField.Value = 2.5;
            app.Impedance3EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.Impedance3EditField.ValueChangedFcn = createCallbackFcn(app, @Impedance3EditFieldValueChanged, true);
            app.Impedance3EditField.Value = 5;
            app.IncidentAngleKnobLabel = uilabel(app.UserInputsPanel);
            app.IncidentAngleKnobLabel.Text = 'Incident Angle';
            app.IncidentAngleKnob = uiknob(app.UserInputsPanel, 'continuous');
            app.IncidentAngleKnob.Limits = [0 90];
            app.IncidentAngleKnob.ValueChangedFcn = createCallbackFcn(app, @IncidentAngleKnobValueChanged, true);
            app.Thicness2Label = uilabel(app.UserInputsPanel);
            app.Thicness2Label.Text = {'Thickness Layer 2'; '(Wavelengths)'};
            app.Thickness2Knob = uiknob(app.UserInputsPanel, 'continuous');
            app.Thickness2Knob.Limits = [0 50];
            app.Thickness2Knob.ValueChangedFcn = createCallbackFcn(app, @Thickness2KnobValueChanged, true);
            app.Thickness2Knob.Value = 5;
            app.FrequencyLabel = uilabel(app.UserInputsPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UserInputsPanel, 'continuous');
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.FrequencyKnob.Value = 10;
            app.IncidentAngleEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.IncidentAngleEditField.ValueChangedFcn = createCallbackFcn(app, @IncidentAngleEditFieldValueChanged, true);
            app.Thickness2EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.Thickness2EditField.ValueChangedFcn = createCallbackFcn(app, @Thickness2EditFieldValueChanged, true);
            app.Thickness2EditField.Value = 5;
            app.FrequencyEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 10;
            app.SpeedofSoundLayer2msLabel = uilabel(app.UserInputsPanel);
            app.SpeedofSoundLayer2msLabel.Text = {'Speed of Sound '; 'Layer 2 (m/s)      '};
            app.SpeedofSound2Slider = uislider(app.UserInputsPanel);
            app.SpeedofSound2Slider.Limits = [0 5000];
            app.SpeedofSound2Slider.ValueChangedFcn = createCallbackFcn(app, @SpeedofSound2SliderValueChanged, true);
            app.SpeedofSound2Slider.Value = 1500;
            app.SpeedofSound2EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.SpeedofSound2EditField.ValueChangedFcn = createCallbackFcn(app, @SpeedofSound2EditFieldValueChanged, true);
            app.SpeedofSound2EditField.Value = 1500;
            app.OutputsPanel = uipanel(app.UIFigure);
            app.OutputsPanel.Title = 'Outputs';
            app.Reflection12Label = uilabel(app.OutputsPanel);
            app.Reflection12Label.Text = 'Reflection 1-2';
            app.Transmitted12Label = uilabel(app.OutputsPanel);
            app.Transmitted12Label.Text = 'Transmitted 1-2';
            app.Transmitted23Label = uilabel(app.OutputsPanel);
            app.Transmitted23Label.Text = 'Transmitted 2-3';
            app.Reflection23Label = uilabel(app.OutputsPanel);
            app.Reflection23Label.Text = 'Reflection 2-3';
            app.Reflection1EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Reflection1EditField.ValueDisplayFormat = '%.2f';
            app.Reflection1EditField.Editable = 'off';
            app.Reflection2EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Reflection2EditField.ValueDisplayFormat = '%.2f';
            app.Reflection2EditField.Editable = 'off';
            app.Transmission2EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Transmission2EditField.ValueDisplayFormat = '%.2f';
            app.Transmission2EditField.Editable = 'off';
            app.Transmission1EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Transmission1EditField.ValueDisplayFormat = '%.2f';
            app.Transmission1EditField.Editable = 'off';
            app.Layer2AngleLabel = uilabel(app.OutputsPanel);
            app.Layer2AngleLabel.Text = 'Layer 2 Angle';
            app.Layer3AngleLabel = uilabel(app.OutputsPanel);
            app.Layer3AngleLabel.Text = 'Layer 3 Angle';
            app.Angle2EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Angle2EditField.ValueDisplayFormat = '%.1f';
            app.Angle2EditField.Editable = 'off';
            app.Angle3EditField = uieditfield(app.OutputsPanel, 'numeric');
            app.Angle3EditField.ValueDisplayFormat = '%.1f';
            app.Angle3EditField.Editable = 'off';
            app.ViewCodeButton = uibutton(app.OutputsPanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
            app.ImAxe = uiaxes(app.UIFigure);
            xlabel(app.ImAxe, 'X')
            ylabel(app.ImAxe, 'Y')
            app.ImAxe.XLim = [0 1];
            app.ImAxe.YLim = [0 1];
            app.ImAxe.ZLim = [0 1];
            app.ImAxe.CLim = [0 1];
            app.ImAxe.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ImAxe.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImAxe.ZTick = [0 0.5 1];
            app.Layer1AirLabel = uilabel(app.UIFigure);
            app.Layer1AirLabel.Text = 'Layer1: Air';
            app.Layer3AirLabel = uilabel(app.UIFigure);
            app.Layer3AirLabel.Text = 'Layer3: Air';
            app.Layer2ThicknessLabel = uilabel(app.UIFigure);
            app.Layer2ThicknessLabel.Text = 'Layer2: Thickness = ';
            app.ImAxe2 = uiaxes(app.UIFigure);
            xlabel(app.ImAxe2, 'X')
            ylabel(app.ImAxe2, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = ThreeLayerPressure

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end