classdef ABCD_Matrix_Solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InputandOutputImpedancesPanel  matlab.ui.container.Panel
        InputImpedanceSliderLabel      matlab.ui.control.Label
        InputImpedanceSlider           matlab.ui.control.Slider
        OutputImpedanceSliderLabel     matlab.ui.control.Label
        OutputImpedanceSlider          matlab.ui.control.Slider
        InputImpedanceEditFieldLabel   matlab.ui.control.Label
        InputImpedanceEditField        matlab.ui.control.NumericEditField
        OutputImpedanceEditFieldLabel  matlab.ui.control.Label
        OutputImpedanceEditField       matlab.ui.control.NumericEditField
        InputImpedenceLabel            matlab.ui.control.Label
        OutputImpedenceLabel           matlab.ui.control.Label
        ABCDMatrixSelectionPanel       matlab.ui.container.Panel
        MatrixTypeDropDownLabel        matlab.ui.control.Label
        MatrixTypeDropDown             matlab.ui.control.DropDown
        SelectMatrixLabel              matlab.ui.control.Label
        Matrix1Button                  matlab.ui.control.Button
        Matrix2Button                  matlab.ui.control.Button
        Matrix3Button                  matlab.ui.control.Button
        MatrixFormulaAxes              matlab.ui.control.UIAxes
        EnterMatrixButton              matlab.ui.control.Button
        CalculateOutputButton          matlab.ui.control.Button
        ViewCodeButton                 matlab.ui.control.Button
        Matrix2Axes                    matlab.ui.control.UIAxes
        OutputAxes                     matlab.ui.control.UIAxes
        ABCDElementPropertiesPanel     matlab.ui.container.Panel
        ABCDElementLabel               matlab.ui.control.Label
        ABCDImpedenceListBox           matlab.ui.control.ListBox
        ABCDImpdenceSliderLabel        matlab.ui.control.Label
        ABCDImpedanceSlider            matlab.ui.control.Slider
        ABCDImpdenceLabel              matlab.ui.control.Label
        ZEditFieldLabel                matlab.ui.control.Label
        ZEditField                     matlab.ui.control.EditField
        TransformernEditFieldLabel     matlab.ui.control.Label
        TransformerEditField           matlab.ui.control.NumericEditField
        TransmissionLineWavelengthLabel  matlab.ui.control.Label
        TLWavelengthEditField          matlab.ui.control.NumericEditField
        TransmissionLineImpedanceSliderLabel  matlab.ui.control.Label
        TransmissionLineImpedanceSlider  matlab.ui.control.Slider
        ABCDImpdenceLabel_2            matlab.ui.control.Label
        ABCDImpedanceEditFieldLabel    matlab.ui.control.Label
        ABCDImpedanceEditField         matlab.ui.control.NumericEditField
        TransmissionLineImpedanceEditFieldLabel  matlab.ui.control.Label
        TransmissionLineImpedanceEditField  matlab.ui.control.NumericEditField
        InputImpedenceAxes             matlab.ui.control.UIAxes
        Matrix1Axes                    matlab.ui.control.UIAxes
        Matrix3Axes                    matlab.ui.control.UIAxes
        InputImpedenceAxes_2           matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Zin = 100; %Input impedence
        Zout = 100; %Output Impedence
        Zintype = 'R'; %The Type of Impedence the input is currently set to
        Zabcdtype = 'R';
        Matrixselect = '1'; %Start by given option to change first matrix
        Matrixtype = 'Se';
        Matrix1 = [1 0; 0 1]; %ABCD matrix for a wire (assume lack of matrix is just a wire)
        Matrix2 = [1 0; 0 1];
        Matrix3 = [1 0; 0 1];
        MatrixIn = [1 100;0 1];
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %On startup generate basic text and pictures
            %Default ABCD is series
            app.MatrixFormulaAxes.reset;
            I = imshow('./Images/Series_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
            %Default Input is a resistor
            app.InputImpedenceAxes.reset;
            I = imshow('./Images/InputR.JPG', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
            app.InputImpedenceAxes.XLim = [0 I.XData(2)];
            app.InputImpedenceAxes.YLim = [0 I.YData(2)];
            %Output is resistor
            app.InputImpedenceAxes_2.reset;
            I = imshow('./Images/OutputR.JPG', 'Parent', app.InputImpedenceAxes_2,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
            app.InputImpedenceAxes_2.XLim = [0 I.XData(2)];
            app.InputImpedenceAxes_2.YLim = [0 I.YData(2)];
            %Default abcd matrix element is resistor
            app.ABCDImpdenceLabel.Text = 'Ohms';
            app.ZEditField.Value = 'R';
            %Initially no matricies are selected
            app.Matrix1Axes.reset;
            app.Matrix2Axes.reset;
            app.Matrix3Axes.reset;
            app.Matrix1Axes.XAxis.TickLabels = {}; %Turn off axes tick marks
            app.Matrix1Axes.YAxis.TickLabels = {};
            app.Matrix2Axes.XAxis.TickLabels = {}; %Turn off axes tick marks
            app.Matrix2Axes.YAxis.TickLabels = {};
            app.Matrix3Axes.XAxis.TickLabels = {}; %Turn off axes tick marks
            app.Matrix3Axes.YAxis.TickLabels = {};
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix1Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix1Axes.XLim = [0 I.XData(2)];
            app.Matrix1Axes.YLim = [0 I.YData(2)];
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix2Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix2Axes.XLim = [0 I.XData(2)];
            app.Matrix2Axes.YLim = [0 I.YData(2)];
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix3Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix3Axes.XLim = [0 I.XData(2)];
            app.Matrix3Axes.YLim = [0 I.YData(2)];
            
        end

        % Callback function
        function InputImpedenceListBoxValueChanged(app, event)
          %Element no longer exists, allows user to select input element
            value = app.InputImpedenceListBox.Value;
            app.InputImpedenceAxes.XAxis.TickLabels = {}; %Turn off axes tick marks
            app.InputImpedenceAxes.YAxis.TickLabels = {};
            syms w;
            z = app.InputImpedanceSlider.Value;
            %I compare this first index of the given string because matlab doesn't like comparing strings of different len
           app.InputImpedenceAxes.reset; %We need to reset the axis to put a new image with appdesigner
           if value(1) == 'R'
                app.InputImpedenceLabel.Text = 'Ohms';
                app.Zintype = 'R'; %Keeps track of which setting selected
                %Grabs appropriate image and stretches it to fit
                I = imshow('./Images/InputR.JPG', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
                app.MatrixIn = [1 z;0 1];
           elseif value(1) == 'C'
                app.InputImpedenceLabel.Text = 'pF';
                app.Zintype = 'C';
                I = imshow('./Images/InputC.JPG', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
                app.MatrixIn = [1 -1i/(w*z*10^-12);0 1];
           else
                app.InputImpedenceLabel.Text = 'nH';
                app.Zintype = 'L';
                I = imshow('./Images/InputL.jpg', 'Parent', app.InputImpedenceAxes,'XData', [1 app.InputImpedenceAxes.Position(3)],'YData', [1 app.InputImpedenceAxes.Position(4)]); 
                app.MatrixIn = [1 1i*z*10^-9*w;0 1]; 
           end
 
            app.InputImpedenceAxes.XLim = [0 I.XData(2)];
            app.InputImpedenceAxes.YLim = [0 I.YData(2)];
        end

        % Value changed function: InputImpedanceSlider
        function InputImpedanceSliderValueChanged(app, event)
            z = app.InputImpedanceSlider.Value;
            app.Zin = z;
           if app.Zintype == 'R'
                app.MatrixIn = [1 z;0 1];
           elseif app.Zintype == 'C'
                app.MatrixIn = [1 -1i/(w*z*10^-12);0 1];
           else
                app.MatrixIn = [1 1i*z*10^-9*w;0 1]; 
           end
           app.InputImpedanceEditField.Value = z;
        end

        % Value changed function: OutputImpedanceSlider
        function OutputImpedanceSliderValueChanged(app, event)
            value = app.OutputImpedanceSlider.Value;
            app.Zout = value;
            app.OutputImpedanceEditField.Value = value;
        end

        % Value changed function: ABCDImpedenceListBox
        function ABCDImpedenceListBoxValueChanged(app, event)
            value = app.ABCDImpedenceListBox.Value;
            %I compare this first index of the given string because matlab doesn't like comparing strings of different len
            if value(1) == 'R'
                app.ABCDImpdenceLabel.Text = 'Ohms';
                app.Zabcdtype = 'R'; %Keeps track of which setting selected
                app.ZEditField.Value = 'R';
            elseif value(1) == 'C'
                app.ABCDImpdenceLabel.Text = 'pF';
                app.Zabcdtype = 'C';
                app.ZEditField.Value = '1/(iwC)';
            else
                app.ABCDImpdenceLabel.Text = 'nH';
                app.Zabcdtype = 'L';
                app.ZEditField.Value = 'iwL';
            end
        end

        % Value changed function: MatrixTypeDropDown
        function MatrixTypeDropDownValueChanged(app, event)
            value = app.MatrixTypeDropDown.Value;
            app.MatrixFormulaAxes.XAxis.TickLabels = {};
            app.MatrixFormulaAxes.YAxis.TickLabels = {};
            % Decide which image to display and stretch it to axes
            app.MatrixFormulaAxes.reset;
            if value(1:3) == 'Ser'
                I = imshow('./Images/Series_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
                app.Matrixtype = 'Se';
            elseif value(1:3) == 'Shu'
                I = imshow('./Images/Shunt_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
                app.Matrixtype = 'Sh';
            elseif value(1:3) == 'Tra'
                I = imshow('./Images/Transformer_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);
                app.Matrixtype = 'Tf';
            else
                I = imshow('./Images/TransmissionLine_ABCD.jpg', 'Parent', app.MatrixFormulaAxes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]);  
                app.Matrixtype = 'Tl';
            end
                
                % Set limits of axes
            app.MatrixFormulaAxes.XLim = [0 I.XData(2)];
            app.MatrixFormulaAxes.YLim = [0 I.YData(2)];

        end

        % Button pushed function: Matrix1Button
        function Matrix1ButtonPushed(app, event)
            cla(app.Matrix1Axes);
            app.Matrix1Axes.reset;
            app.Matrix1Axes.XAxis.TickLabels = {};
            app.Matrix1Axes.YAxis.TickLabels = {};
            app.Matrixselect = '1';
            app.Matrix1 = [1 0; 0 1];
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix1Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix1Axes.XLim = [0 I.XData(2)];
            app.Matrix1Axes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: Matrix2Button
        function Matrix2ButtonPushed(app, event)
            cla(app.Matrix2Axes);
            app.Matrix2Axes.reset;
            app.Matrix2Axes.XAxis.TickLabels = {};
            app.Matrix2Axes.YAxis.TickLabels = {};
            app.Matrixselect = '2';
            app.Matrix2 = [1 0; 0 1];
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix2Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix2Axes.XLim = [0 I.XData(2)];
            app.Matrix2Axes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: Matrix3Button
        function Matrix3ButtonPushed(app, event)
            cla(app.Matrix3Axes);
            app.Matrix3Axes.reset;
            app.Matrix3Axes.XAxis.TickLabels = {};
            app.Matrix3Axes.YAxis.TickLabels = {};
            app.Matrixselect = '3';
            app.Matrix3 = [1 0; 0 1];
            I = imshow('./Images/Empty_Element.jpg', 'Parent', app.Matrix3Axes,'XData', [1 app.MatrixFormulaAxes.Position(3)],'YData', [1 app.MatrixFormulaAxes.Position(4)]); 
            app.Matrix3Axes.XLim = [0 I.XData(2)];
            app.Matrix3Axes.YLim = [0 I.YData(2)];
        end

        % Button pushed function: EnterMatrixButton
        function EnterMatrixButtonPushed(app, event)
            %Assign axes for appropriately selected element
            syms w;
            z = app.ABCDImpedanceSlider.Value;
            n = app.TransformerEditField.Value;
            lambda = app.TLWavelengthEditField.Value;
            
            if app.Matrixselect == '1'
               axes = app.Matrix1Axes; 
               axes.reset;
                %Set appropriate picture for chosen element and orientation
                axes.XAxis.TickLabels = {}; %Turn off axes tick marks
                axes.YAxis.TickLabels = {};
                %Series Element
                if app.Zabcdtype == 'R' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix1 = [1 z;0 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix1 = [1 -1i/(w*z*10^-12);0 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix1 = [1 1i*z*10^-9*w;0 1];
                %Shunt Image
                elseif app.Zabcdtype == 'R' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix1 = [1 0;1/z 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix1 = [1 0;1/(-1i/(w*z*10^-12)) 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix1 = [1 0;1/(1i*z*10^-9*w) 1];
                %Transformer Image
                elseif app.Matrixtype == 'Tf'
                     I = imshow('./Images/Transformer.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix1 = [n 0; 0 1/n];
                else
                %Transmission Line Image
                     z = app.TransmissionLineImpedanceSlider.Value;
                     I = imshow('./Images/TransmissionLine.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix1 = [cos(2*lambda/pi*w) 1j*z*sin(2*lambda/pi*w); 1j*sin(2*lambda/pi*w)/z cos(2*lambda/pi*w)];
                end
                axes.XLim = [0 I.XData(2)];
                axes.YLim = [0 I.YData(2)];
                
                
            elseif app.Matrixselect == '2'
               axes = app.Matrix2Axes; 
               axes.reset; 
                                %Set appropriate picture for chosen element and orientation
                axes.XAxis.TickLabels = {}; %Turn off axes tick marks
                axes.YAxis.TickLabels = {};
                %Series Element
                if app.Zabcdtype == 'R' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix2 = [1 z;0 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix2 = [1 -1i/(w*z*10^-12);0 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix2 = [1 1i*z*10^-9*w;0 1];
                %Shunt Image
                elseif app.Zabcdtype == 'R' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix2 = [1 0;1/z 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix2 = [1 0;1/(-1i/(w*z*10^-12)) 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix2 = [1 0;1/(1i*z*10^-9*w) 1];
                %Transformer Image
                elseif app.Matrixtype == 'Tf'
                     I = imshow('./Images/Transformer.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix2 = [n 0; 0 1/n];
                else
                %Transmission Line Image
                     z = app.TransmissionLineImpedanceSlider.Value;
                     I = imshow('./Images/TransmissionLine.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix2 =  [cos(2*lambda/pi*w) 1j*z*sin(2*lambda/pi*w); 1j*sin(2*lambda/pi*w)/z cos(2*lambda/pi*w)];
                end
                axes.XLim = [0 I.XData(2)];
                axes.YLim = [0 I.YData(2)];


            else
               axes = app.Matrix3Axes; 
               axes.reset;
                               %Set appropriate picture for chosen element and orientation
                axes.XAxis.TickLabels = {}; %Turn off axes tick marks
                axes.YAxis.TickLabels = {};
                %Series Element
                if app.Zabcdtype == 'R' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix3 = [1 z;0 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix3 = [1 -1i/(w*z*10^-12);0 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Se'
                     I = imshow('./Images/SeriesL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix3 = [1 1i*z*10^-9*w;0 1];
                %Shunt Image
                elseif app.Zabcdtype == 'R' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntR.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix3 = [1 0;1/z 1];
                elseif app.Zabcdtype == 'C' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntC.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);    
                     app.Matrix3 = [1 0;1/(-1i/(w*z*10^-12)) 1];
                elseif app.Zabcdtype == 'L' & app.Matrixtype == 'Sh'
                     I = imshow('./Images/ShuntL.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix3 = [1 0;1/(1i*z*10^-9*w) 1];
                %Transformer Image
                elseif app.Matrixtype == 'Tf'
                     I = imshow('./Images/Transformer.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix3 = [n 0; 0 1/n];
                %Transmission Line Image
                else
                     z = app.TransmissionLineImpedanceSlider.Value;
                     I = imshow('./Images/TransmissionLine.jpg', 'Parent', axes,'XData', [1 app.Matrix1Axes.Position(3)],'YData', [1 app.Matrix1Axes.Position(4)]);
                     app.Matrix3 = [cos(2*lambda/pi*w) 1j*z*sin(2*lambda/pi*w); 1j*sin(2*lambda/pi*w)/z cos(2*lambda/pi*w)];
                end
                axes.XLim = [0 I.XData(2)];
                axes.YLim = [0 I.YData(2)];                
            end
            

        end

        % Button pushed function: CalculateOutputButton
        function CalculateOutputButtonPushed(app, event)
            new_w = 10.^(6:0.01:7);
            output = zeros(length(new_w));
            app.CalculateOutputButton.Enable = 'off';
            app.EnterMatrixButton.Enable = 'off';
            for i = 1:length(output)
               w = new_w(i);
               matin = eval(subs(app.MatrixIn,w));
               mat1 = eval(subs(app.Matrix1,w));
               mat2 = eval(subs(app.Matrix2,w));
               mat3 = eval(subs(app.Matrix3,w));
               matmul = matin*mat1*mat2*mat3;
               output(i) = (app.Zout)/((abs(matmul(1,1)*(app.Zout))+abs(matmul(1,2))));
            end
            app.OutputAxes.reset;
            plot(app.OutputAxes,new_w,output)
            ylabel(app.OutputAxes,'Vout/Vin')
            xlabel(app.OutputAxes,'frequency (MHz)')
            app.CalculateOutputButton.Enable = 'on';
            app.EnterMatrixButton.Enable = 'on';
        end

        % Value changed function: ABCDImpedanceSlider
        function ABCDImpedanceSliderValueChanged(app, event)
            value = app.ABCDImpedanceSlider.Value;
            app.ABCDImpedanceEditField.Value = value;
        end

        % Value changed function: TransmissionLineImpedanceSlider
        function TransmissionLineImpedanceSliderValueChanged(app, event)
            value = app.TransmissionLineImpedanceSlider.Value;
            app.TransmissionLineImpedanceEditField.Value = value;
        end

        % Value changed function: InputImpedanceEditField
        function InputImpedanceEditFieldValueChanged(app, event)
            value = app.InputImpedanceEditField.Value;
            app.Zin = value;
            app.InputImpedanceSlider.Value= value;
        end

        % Value changed function: ABCDImpedanceEditField
        function ABCDImpedanceEditFieldValueChanged(app, event)
            value = app.ABCDImpedanceEditField.Value;
            app.ABCDImpedanceSlider.Value = value;
        end

        % Value changed function: OutputImpedanceEditField
        function OutputImpedanceEditFieldValueChanged(app, event)
            value = app.OutputImpedanceEditField.Value;
            app.Zout = value;
            app.OutputImpedanceSlider.Value = value;
        end

        % Value changed function: TransmissionLineImpedanceEditField
        function TransmissionLineImpedanceEditFieldValueChanged(app, event)
            value = app.TransmissionLineImpedanceEditField.Value;
            app.TransmissionLineImpedanceSlider.Value = value;
        end

        % Value changing function: TransmissionLineImpedanceSlider
        function TransmissionLineImpedanceSliderValueChanging(app, event)

        end

        % Button pushed function: ViewCodeButton
        function ViewCodeButtonPushed(app, event)
            open('ABCD_Matrix_Script');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InputandOutputImpedancesPanel = uipanel(app.UIFigure);
            app.InputandOutputImpedancesPanel.Title = 'Input and Output Impedances';
            app.InputImpedanceSliderLabel = uilabel(app.InputandOutputImpedancesPanel);
            app.InputImpedanceSliderLabel.Text = {'Input'; 'Impedance'};
            app.InputImpedanceSlider = uislider(app.InputandOutputImpedancesPanel);
            app.InputImpedanceSlider.Limits = [10 1000];
            app.InputImpedanceSlider.ValueChangedFcn = createCallbackFcn(app, @InputImpedanceSliderValueChanged, true);
            app.InputImpedanceSlider.Value = 50;
            app.OutputImpedanceSliderLabel = uilabel(app.InputandOutputImpedancesPanel);
            app.OutputImpedanceSliderLabel.Text = {'Output'; 'Impedance'};
            app.OutputImpedanceSlider = uislider(app.InputandOutputImpedancesPanel);
            app.OutputImpedanceSlider.Limits = [10 1000];
            app.OutputImpedanceSlider.ValueChangedFcn = createCallbackFcn(app, @OutputImpedanceSliderValueChanged, true);
            app.OutputImpedanceSlider.Value = 50;
            app.InputImpedanceEditFieldLabel = uilabel(app.InputandOutputImpedancesPanel);
            app.InputImpedanceEditFieldLabel.Text = {'Input'; 'Impedance'};
            app.InputImpedanceEditField = uieditfield(app.InputandOutputImpedancesPanel, 'numeric');
            app.InputImpedanceEditField.Limits = [10 1000];
            app.InputImpedanceEditField.ValueDisplayFormat = '%.0f';
            app.InputImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @InputImpedanceEditFieldValueChanged, true);
            app.InputImpedanceEditField.Value = 50;
            app.OutputImpedanceEditFieldLabel = uilabel(app.InputandOutputImpedancesPanel);
            app.OutputImpedanceEditFieldLabel.Text = {'Output'; 'Impedance'};
            app.OutputImpedanceEditField = uieditfield(app.InputandOutputImpedancesPanel, 'numeric');
            app.OutputImpedanceEditField.Limits = [10 1000];
            app.OutputImpedanceEditField.ValueDisplayFormat = '%.0f';
            app.OutputImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @OutputImpedanceEditFieldValueChanged, true);
            app.OutputImpedanceEditField.Value = 50;
            app.InputImpedenceLabel = uilabel(app.UIFigure);
            app.InputImpedenceLabel.Text = 'Ohms';
            app.OutputImpedenceLabel = uilabel(app.UIFigure);
            app.OutputImpedenceLabel.Text = 'Ohms';
            app.ABCDMatrixSelectionPanel = uipanel(app.UIFigure);
            app.ABCDMatrixSelectionPanel.Title = 'ABCD Matrix Selection';
            app.MatrixTypeDropDownLabel = uilabel(app.ABCDMatrixSelectionPanel);
            app.MatrixTypeDropDownLabel.Text = 'Matrix Type';
            app.MatrixTypeDropDown = uidropdown(app.ABCDMatrixSelectionPanel);
            app.MatrixTypeDropDown.Items = {'Series', 'Shunt', 'T.Line', 'Transformer'};
            app.MatrixTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MatrixTypeDropDownValueChanged, true);
            app.MatrixTypeDropDown.Value = 'Series';
            app.SelectMatrixLabel = uilabel(app.ABCDMatrixSelectionPanel);
            app.SelectMatrixLabel.Text = 'Select Matrix';
            app.Matrix1Button = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.Matrix1Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix1ButtonPushed, true);
            app.Matrix1Button.Text = '1';
            app.Matrix2Button = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.Matrix2Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix2ButtonPushed, true);
            app.Matrix2Button.Text = '2';
            app.Matrix3Button = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.Matrix3Button.ButtonPushedFcn = createCallbackFcn(app, @Matrix3ButtonPushed, true);
            app.Matrix3Button.Text = '3';
            app.MatrixFormulaAxes = uiaxes(app.ABCDMatrixSelectionPanel);
            title(app.MatrixFormulaAxes, 'Matrix Layout')
            app.MatrixFormulaAxes.XLim = [0 1];
            app.MatrixFormulaAxes.YLim = [0 1];
            app.MatrixFormulaAxes.ZLim = [0 1];
            app.MatrixFormulaAxes.CLim = [0 1];
            app.MatrixFormulaAxes.XTick = [0 0.5 1];
            app.MatrixFormulaAxes.YTick = [0 0.5 1];
            app.MatrixFormulaAxes.ZTick = [0 0.5 1];
            app.EnterMatrixButton = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.EnterMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @EnterMatrixButtonPushed, true);
            app.EnterMatrixButton.Text = 'Enter Matrix';
            app.CalculateOutputButton = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.CalculateOutputButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateOutputButtonPushed, true);
            app.CalculateOutputButton.Text = 'Calculate Output';
            app.ViewCodeButton = uibutton(app.ABCDMatrixSelectionPanel, 'push');
            app.ViewCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ViewCodeButtonPushed, true);
            app.ViewCodeButton.Text = 'View Code';
            app.Matrix2Axes = uiaxes(app.UIFigure);
            title(app.Matrix2Axes, 'Matrix 2')
            app.Matrix2Axes.XLim = [0 1];
            app.Matrix2Axes.YLim = [0 1];
            app.Matrix2Axes.ZLim = [0 1];
            app.Matrix2Axes.CLim = [0 1];
            app.Matrix2Axes.XTick = [0 0.5 1];
            app.Matrix2Axes.YTick = [0 0.5 1];
            app.Matrix2Axes.ZTick = [0 0.5 1];
            app.OutputAxes = uiaxes(app.UIFigure);
            title(app.OutputAxes, 'Vout/Vin')
            xlabel(app.OutputAxes, 'Frequency')
            ylabel(app.OutputAxes, 'Response')
            app.OutputAxes.XLim = [0 1];
            app.OutputAxes.YLim = [0 1];
            app.OutputAxes.ZLim = [0 1];
            app.OutputAxes.CLim = [0 1];
            app.OutputAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.OutputAxes.YTick = [0 0.5 1];
            app.OutputAxes.ZTick = [0 0.5 1];
            app.ABCDElementPropertiesPanel = uipanel(app.UIFigure);
            app.ABCDElementPropertiesPanel.Title = 'ABCD Element Properties';
            app.ABCDElementLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.ABCDElementLabel.Text = 'ABCD Element';
            app.ABCDImpedenceListBox = uilistbox(app.ABCDElementPropertiesPanel);
            app.ABCDImpedenceListBox.Items = {'Resistor', 'Capacitor', 'Inductor'};
            app.ABCDImpedenceListBox.ValueChangedFcn = createCallbackFcn(app, @ABCDImpedenceListBoxValueChanged, true);
            app.ABCDImpedenceListBox.Value = 'Resistor';
            app.ABCDImpdenceSliderLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.ABCDImpdenceSliderLabel.Text = {'ABCD'; 'Impedance'};
            app.ABCDImpedanceSlider = uislider(app.ABCDElementPropertiesPanel);
            app.ABCDImpedanceSlider.Limits = [10 100];
            app.ABCDImpedanceSlider.ValueChangedFcn = createCallbackFcn(app, @ABCDImpedanceSliderValueChanged, true);
            app.ABCDImpedanceSlider.Value = 50;
            app.ABCDImpdenceLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.ABCDImpdenceLabel.Text = 'Ohms';
            app.ZEditFieldLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.ZEditFieldLabel.Text = 'Z =';
            app.ZEditField = uieditfield(app.ABCDElementPropertiesPanel, 'text');
            app.ZEditField.Editable = 'off';
            app.TransformernEditFieldLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.TransformernEditFieldLabel.Text = {'Transformer'; 'n = '};
            app.TransformerEditField = uieditfield(app.ABCDElementPropertiesPanel, 'numeric');
            app.TransformerEditField.Limits = [1 10];
            app.TransformerEditField.ValueDisplayFormat = '%.0f';
            app.TransformerEditField.Value = 1;
            app.TransmissionLineWavelengthLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.TransmissionLineWavelengthLabel.Text = {'Transmission'; 'Line Wavelength'};
            app.TLWavelengthEditField = uieditfield(app.ABCDElementPropertiesPanel, 'numeric');
            app.TLWavelengthEditField.Limits = [0.25 6.28];
            app.TLWavelengthEditField.Value = 0.25;
            app.TransmissionLineImpedanceSliderLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.TransmissionLineImpedanceSliderLabel.Text = {'Transmission'; 'Line Impedance'};
            app.TransmissionLineImpedanceSlider = uislider(app.ABCDElementPropertiesPanel);
            app.TransmissionLineImpedanceSlider.Limits = [10 100];
            app.TransmissionLineImpedanceSlider.ValueChangedFcn = createCallbackFcn(app, @TransmissionLineImpedanceSliderValueChanged, true);
            app.TransmissionLineImpedanceSlider.ValueChangingFcn = createCallbackFcn(app, @TransmissionLineImpedanceSliderValueChanging, true);
            app.TransmissionLineImpedanceSlider.Value = 50;
            app.ABCDImpdenceLabel_2 = uilabel(app.ABCDElementPropertiesPanel);
            app.ABCDImpdenceLabel_2.Text = 'Ohms';
            app.ABCDImpedanceEditFieldLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.ABCDImpedanceEditFieldLabel.Text = {'ABCD'; 'Impedance'};
            app.ABCDImpedanceEditField = uieditfield(app.ABCDElementPropertiesPanel, 'numeric');
            app.ABCDImpedanceEditField.Limits = [10 100];
            app.ABCDImpedanceEditField.ValueDisplayFormat = '%.0f';
            app.ABCDImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @ABCDImpedanceEditFieldValueChanged, true);
            app.ABCDImpedanceEditField.Value = 50;
            app.TransmissionLineImpedanceEditFieldLabel = uilabel(app.ABCDElementPropertiesPanel);
            app.TransmissionLineImpedanceEditFieldLabel.Text = {'Transmission'; 'Line Impedance'};
            app.TransmissionLineImpedanceEditField = uieditfield(app.ABCDElementPropertiesPanel, 'numeric');
            app.TransmissionLineImpedanceEditField.Limits = [10 100];
            app.TransmissionLineImpedanceEditField.ValueDisplayFormat = '%.0f';
            app.TransmissionLineImpedanceEditField.ValueChangedFcn = createCallbackFcn(app, @TransmissionLineImpedanceEditFieldValueChanged, true);
            app.TransmissionLineImpedanceEditField.Value = 50;
            app.InputImpedenceAxes = uiaxes(app.UIFigure);
            title(app.InputImpedenceAxes, 'Input Z')
            app.InputImpedenceAxes.XLim = [0 1];
            app.InputImpedenceAxes.YLim = [0 1];
            app.InputImpedenceAxes.ZLim = [0 1];
            app.InputImpedenceAxes.CLim = [0 1];
            app.InputImpedenceAxes.XTick = [0 0.5 1];
            app.InputImpedenceAxes.YTick = [0 0.5 1];
            app.InputImpedenceAxes.ZTick = [0 0.5 1];
            app.Matrix1Axes = uiaxes(app.UIFigure);
            title(app.Matrix1Axes, 'Matrix 1')
            app.Matrix1Axes.XLim = [0 1];
            app.Matrix1Axes.YLim = [0 1];
            app.Matrix1Axes.ZLim = [0 1];
            app.Matrix1Axes.CLim = [0 1];
            app.Matrix1Axes.XTick = [0 0.5 1];
            app.Matrix1Axes.YTick = [0 0.5 1];
            app.Matrix1Axes.ZTick = [0 0.5 1];
            app.Matrix3Axes = uiaxes(app.UIFigure);
            title(app.Matrix3Axes, 'Matrix 3')
            app.Matrix3Axes.XLim = [0 1];
            app.Matrix3Axes.YLim = [0 1];
            app.Matrix3Axes.ZLim = [0 1];
            app.Matrix3Axes.CLim = [0 1];
            app.Matrix3Axes.XTick = [0 0.5 1];
            app.Matrix3Axes.YTick = [0 0.5 1];
            app.Matrix3Axes.ZTick = [0 0.5 1];
            app.InputImpedenceAxes_2 = uiaxes(app.UIFigure);
            title(app.InputImpedenceAxes_2, 'Output Z')
            app.InputImpedenceAxes_2.XLim = [0 1];
            app.InputImpedenceAxes_2.YLim = [0 1];
            app.InputImpedenceAxes_2.ZLim = [0 1];
            app.InputImpedenceAxes_2.CLim = [0 1];
            app.InputImpedenceAxes_2.XTick = [0 0.5 1];
            app.InputImpedenceAxes_2.YTick = [0 0.5 1];
            app.InputImpedenceAxes_2.ZTick = [0 0.5 1];
        end
    end

    methods (Access = public)

        % Construct app
        function app = ABCD_Matrix_Solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end