classdef Fourier_Transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TimeAxes                 matlab.ui.control.UIAxes
        FreqAxes                 matlab.ui.control.UIAxes
        UserInputsPanel          matlab.ui.container.Panel
        InputSignalListBoxLabel  matlab.ui.control.Label
        InputSignalListBox       matlab.ui.control.ListBox
        ToneBurstLabel           matlab.ui.control.Label
        FreqMHzLabel             matlab.ui.control.Label
        ToneBurstFreqSlider      matlab.ui.control.Slider
        Label                    matlab.ui.control.Label
        EditField                matlab.ui.control.NumericEditField
        GaussianLabel            matlab.ui.control.Label
        VarLabel                 matlab.ui.control.Label
        GaussianVarSlider        matlab.ui.control.Slider
        EditField_2Label         matlab.ui.control.Label
        EditField_2              matlab.ui.control.NumericEditField
        SuperGaussianLabel       matlab.ui.control.Label
        VarLabel_2               matlab.ui.control.Label
        SuperGaussianVarSlider   matlab.ui.control.Slider
        EditField_3Label         matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        OrderKnobLabel           matlab.ui.control.Label
        SuperGaussianKnob        matlab.ui.control.DiscreteKnob
        DeltaLabel               matlab.ui.control.Label
        CenterTimeuSLabel        matlab.ui.control.Label
        DeltaCenterSlider        matlab.ui.control.Slider
        EditField_4Label         matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        ViewCodeButton           matlab.ui.control.Button
    end


    properties (Access = private)
        Signal = 'T'; %Signal Type Selected, default tone burst
    end

    methods (Access = private)
    
        function plot_app(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            %Note you do not want to over sample time domain as this decreases resolution in frequency domain
            %delta_t*delta_f = 2*pi/N, where N is the number of points used
            T = linspace(0,5,100); %Time domain sampling, sampled at Nyquest
            S = @(f) sin(f*T); %Tone Burst
            G = @(f) 1*exp(-(T-f).^2./(2));
            SG = @(f,o) (1*exp(-(T-f).^2./(2))).^o;

            f = app.ToneBurstFreqSlider.Value;
            F = linspace(-2,2,100); %Freq domain sampling
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,S(f),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(F,abs(Ft(S(f))), 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
        end

        % Value changed function: InputSignalListBox
        function InputSignalListBoxValueChanged(app, event)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            value = app.InputSignalListBox.Value;
            if value(1) == 'T'
                T = linspace(0,5,100); %Time domain sampling, sampled at Nyquest
                S = @(f) sin(f*T); %Tone Burst
                f = app.ToneBurstFreqSlider.Value;
                F = linspace(-2,2,100); %Freq domain sampling
            
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,S(f),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(F,abs(Ft(S(f))), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
            
            elseif value(1) == 'G'
                T = linspace(0,5,200); %Time domain sampling
                F = linspace(-2,2,200); %Freq domain sampling
                G = @(v) 1*exp(-(T-2.5).^2./(v));
                fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v); %Due to dx*du = 2*pi for FFT, cannot reasonably use it to accurately plot gaussian and transform. Just used equation derived by FT
                v = app.GaussianVarSlider.Value;
                
            
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,G(v),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(F,fG(v), 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
                
            elseif value(1) == 'S'
                T = linspace(0,5,200); %Time domain sampling, sampled at Nyquest
                SG = @(v,o) ((1*exp(-(T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
                v = app.SuperGaussianVarSlider.Value;
                o = str2num(app.SuperGaussianKnob.Value);
                
                %Fourier Transform super Gaussian = convolution of Fourier transform of each individual gaussian [FT of multiplication is Convolution]
                F = linspace(-2,2,200); %Freq domain sampling
                fSG = 1; %Declare dummy variable for FT Super Gaussian
                fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v); %Function to call guassian
                for i = 1:o
                   fSG = conv(fSG,fG(v));   
                end
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,SG(v,o),'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(fSG, 'Parent', app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
                
           elseif value(1) == 'D'
                T = linspace(0,5,1000); %Time domain sampling, sampled at Nyquest
                t = app.DeltaCenterSlider.Value; %Get center
                t = round(t/5*999)+1; %Round to closest spot in graph
                delta = zeros(size(T)); %Define zeros
                delta(t) = 1; %Assign single point to one
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(T,delta,'Parent', app.TimeAxes)
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(Ft(delta),'Parent',app.FreqAxes)
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Amplitude')
            end
        end

        % Value changed function: DeltaCenterSlider
        function DeltaCenterSliderValueChanged(app, event)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            value = app.DeltaCenterSlider.Value;
            T = linspace(0,5,1000); %Time domain sampling, sampled at Nyquest
            t = value; %Get center
            t = round(t/5*999)+1; %Round to closest spot in graph
            delta = zeros(size(T)); %Define zeros
            delta(t) = 1; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,delta,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(ones(size(T)),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.EditField_4.Value = value;
            app.InputSignalListBox.Value = 'Delta';
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            value = app.EditField_4.Value;
            T = linspace(0,5,1000); %Time domain sampling, sampled at Nyquest
            t = value; %Get center
            t = round(t/5*999)+1; %Round to closest spot in graph
            delta = zeros(size(T)); %Define zeros
            delta(t) = 1; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,delta,'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(ones(size(T)),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Slider
            app.DeltaCenterSlider.Value = value;
            app.InputSignalListBox.Value = 'Delta';
        end

        % Value changed function: ToneBurstFreqSlider
        function ToneBurstFreqSliderValueChanged(app, event)
            value = app.ToneBurstFreqSlider.Value;
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            T = linspace(0,5,200); %Time domain sampling, sampled at Nyquest
            S = @(f) sin(f*T); %Tone Burst
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,S(value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(abs(Ft(S(value))),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.EditField.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            Ft = @(x) fftshift(fft(ifftshift(x))); %fft
            T = linspace(0,5,200); %Time domain sampling, sampled at Nyquest
            S = @(f) sin(f*T); %Tone Burst
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,S(value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(abs(Ft(S(value))),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.ToneBurstFreqSlider.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
        end

        % Value changed function: GaussianVarSlider
        function GaussianVarSliderValueChanged(app, event)
            value = app.GaussianVarSlider.Value;
            T = linspace(0,5,200); %Time domain sampling
            F = linspace(-2,2,200); %Freq domain sampling
            G = @(v) 1*exp(-(T-2.5).^2./(v));
            fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,G(value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(F,fG(value),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.EditField_2.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            T = linspace(0,5,200); %Time domain sampling
            F = linspace(-2,2,200); %Freq domain sampling
            G = @(v) 1*exp(-(T-2.5).^2./(v));
            fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,G(value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(F,fG(value),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.GaussianVarSlider.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: SuperGaussianVarSlider
        function SuperGaussianVarSliderValueChanged(app, event)
            value = app.SuperGaussianVarSlider.Value;
            T = linspace(0,5,200); %Time domain sampling
            SG = @(v,o) ((1*exp(-(T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            o = str2num(app.SuperGaussianKnob.Value);
            
            %Fourier Transform super Gaussian = convolution of Fourier transform of each individual gaussian [FT of multiplication is Convolution]
            F = linspace(-2,2,200); %Freq domain sampling
            fSG = 1; %Declare dummy variable for FT Super Gaussian
            fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v); %Function to call guassian
            for i = 1:o
               fSG = conv(fSG,fG(value));   
            end
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,SG(value,o),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(fSG,'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.EditField_3.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
            T = linspace(0,5,200); %Time domain sampling
            SG = @(v,o) ((1*exp(-(T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            o = str2num(app.SuperGaussianKnob.Value);
            
            %Fourier Transform super Gaussian = convolution of Fourier transform of each individual gaussian [FT of multiplication is Convolution]
            F = linspace(-2,2,200); %Freq domain sampling
            fSG = 1; %Declare dummy variable for FT Super Gaussian
            fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v); %Function to call guassian
            for i = 1:o
               fSG = conv(fSG,fG(v));   
            end
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,SG(value,o),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(fSG(round(200*o/2)-100:round(200*o/2)+100),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            %Update Edit Field
            app.EditField_3.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianKnob
        function SuperGaussianKnobValueChanged(app, event)
            value = str2num(app.SuperGaussianKnob.Value);
            
            T = linspace(0,5,200); %Time domain sampling
            SG = @(v,o) ((1*exp(-(T-2.5).^(2*o))./(v))); %Standard gaussian to the o-th power
            v = app.SuperGaussianVarSlider.Value;
            
            %Fourier Transform super Gaussian = convolution of Fourier transform of each individual gaussian [FT of multiplication is Convolution]
            F = linspace(-2,2,200); %Freq domain sampling
            fSG = 1; %Declare dummy variable for FT Super Gaussian
            fG = @(v) sqrt(pi*v)*exp(-(pi*F).^2*v); %Function to call guassian
            for i = 1:value
               fSG = conv(fSG,fG(v));   
            end
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(T,SG(v,value),'Parent', app.TimeAxes)
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(F,fSG(round(200*value/2)-99:round((200*value/2)+100)),'Parent',app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Amplitude')
            
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeAxes = uiaxes(app.UIFigure);
            title(app.TimeAxes, 'Time Domain')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Signal')
            app.TimeAxes.XLim = [0 1];
            app.TimeAxes.YLim = [0 1];
            app.TimeAxes.ZLim = [0 1];
            app.TimeAxes.CLim = [0 1];
            app.TimeAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TimeAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimeAxes.ZTick = [0 0.5 1];
            app.FreqAxes = uiaxes(app.UIFigure);
            title(app.FreqAxes, 'Fourier Transform')
            xlabel(app.FreqAxes, 'Frequency')
            ylabel(app.FreqAxes, 'Signal')
            app.FreqAxes.XLim = [0 1];
            app.FreqAxes.YLim = [0 1];
            app.FreqAxes.ZLim = [0 1];
            app.FreqAxes.CLim = [0 1];
            app.FreqAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FreqAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FreqAxes.ZTick = [0 0.5 1];
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.InputSignalListBoxLabel = uilabel(app.UserInputsPanel);
            app.InputSignalListBoxLabel.Text = {'Input'; 'Signal'};
            app.InputSignalListBox = uilistbox(app.UserInputsPanel);
            app.InputSignalListBox.Items = {'Tone Burst', 'Gaussian', 'Super-Gaussian', 'Delta'};
            app.InputSignalListBox.ValueChangedFcn = createCallbackFcn(app, @InputSignalListBoxValueChanged, true);
            app.InputSignalListBox.Value = 'Tone Burst';
            app.ToneBurstLabel = uilabel(app.UserInputsPanel);
            app.ToneBurstLabel.Text = 'Tone Burst';
            app.FreqMHzLabel = uilabel(app.UserInputsPanel);
            app.FreqMHzLabel.Text = {'Freq.'; '(MHz)'};
            app.ToneBurstFreqSlider = uislider(app.UserInputsPanel);
            app.ToneBurstFreqSlider.Limits = [1 10];
            app.ToneBurstFreqSlider.ValueChangedFcn = createCallbackFcn(app, @ToneBurstFreqSliderValueChanged, true);
            app.ToneBurstFreqSlider.Value = 5;
            app.Label = uilabel(app.UserInputsPanel);
            app.Label.Text = '';
            app.EditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField.Limits = [1 10];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 5;
            app.GaussianLabel = uilabel(app.UserInputsPanel);
            app.GaussianLabel.Text = 'Gaussian';
            app.VarLabel = uilabel(app.UserInputsPanel);
            app.VarLabel.Text = 'Var';
            app.GaussianVarSlider = uislider(app.UserInputsPanel);
            app.GaussianVarSlider.Limits = [1 5];
            app.GaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianVarSliderValueChanged, true);
            app.GaussianVarSlider.Value = 1;
            app.EditField_2Label = uilabel(app.UserInputsPanel);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.SuperGaussianLabel = uilabel(app.UserInputsPanel);
            app.SuperGaussianLabel.Text = 'Super-Gaussian';
            app.VarLabel_2 = uilabel(app.UserInputsPanel);
            app.VarLabel_2.Text = 'Var';
            app.SuperGaussianVarSlider = uislider(app.UserInputsPanel);
            app.SuperGaussianVarSlider.Limits = [1 5];
            app.SuperGaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianVarSliderValueChanged, true);
            app.SuperGaussianVarSlider.Value = 1;
            app.EditField_3Label = uilabel(app.UserInputsPanel);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_3.Limits = [1 5];
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.OrderKnobLabel = uilabel(app.UserInputsPanel);
            app.OrderKnobLabel.Text = 'Order';
            app.SuperGaussianKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.SuperGaussianKnob.Items = {'1', '2', '3', '4', '5'};
            app.SuperGaussianKnob.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianKnobValueChanged, true);
            app.SuperGaussianKnob.Value = '1';
            app.DeltaLabel = uilabel(app.UserInputsPanel);
            app.DeltaLabel.Text = 'Delta';
            app.CenterTimeuSLabel = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel.Text = {'Center'; 'Time'; '(uS)'};
            app.DeltaCenterSlider = uislider(app.UserInputsPanel);
            app.DeltaCenterSlider.Limits = [0 5];
            app.DeltaCenterSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaCenterSliderValueChanged, true);
            app.DeltaCenterSlider.Value = 2.5;
            app.EditField_4Label = uilabel(app.UserInputsPanel);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UserInputsPanel, 'numeric');
            app.EditField_4.Limits = [0 5];
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 2.5;
            app.ViewCodeButton = uibutton(app.UserInputsPanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fourier_Transform

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end