classdef Fourier_Transform_3_DB_Scaling_and_Labels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeAxes                       matlab.ui.control.UIAxes
        FreqAxes                       matlab.ui.control.UIAxes
        UserInputsPanel                matlab.ui.container.Panel
        InputSignalListBoxLabel        matlab.ui.control.Label
        InputSignalListBox             matlab.ui.control.ListBox
        ToneBurstLabel                 matlab.ui.control.Label
        FrequencyMHzLabel              matlab.ui.control.Label
        ToneBurstFreqSlider            matlab.ui.control.Slider
        Label                          matlab.ui.control.Label
        ToneBurstFreqEditField         matlab.ui.control.NumericEditField
        GaussianLabel                  matlab.ui.control.Label
        VarianceLabel                  matlab.ui.control.Label
        GaussianVarSlider              matlab.ui.control.Slider
        EditField_2Label               matlab.ui.control.Label
        GaussianVarEditField           matlab.ui.control.NumericEditField
        SuperGaussianLabel             matlab.ui.control.Label
        VarianceLabel_2                matlab.ui.control.Label
        SuperGaussianVarSlider         matlab.ui.control.Slider
        EditField_3Label               matlab.ui.control.Label
        SuperGaussianVarEditField      matlab.ui.control.NumericEditField
        OrderKnobLabel                 matlab.ui.control.Label
        SuperGaussianKnob              matlab.ui.control.DiscreteKnob
        DeltaLabel                     matlab.ui.control.Label
        CenterTimeuSLabel              matlab.ui.control.Label
        DeltaCenterSlider              matlab.ui.control.Slider
        EditField_4Label               matlab.ui.control.Label
        DeltaCenterEditField           matlab.ui.control.NumericEditField
        ViewCodeButton                 matlab.ui.control.Button
        ModulationMHzSliderLabel       matlab.ui.control.Label
        GaussianModulationSlider       matlab.ui.control.Slider
        ModulationMHzSliderLabel_2     matlab.ui.control.Label
        SuperGaussianModulationSlider  matlab.ui.control.Slider
        GaussianModulationEditField    matlab.ui.control.NumericEditField
        SuperGaussianModulationEditField  matlab.ui.control.NumericEditField
        TimeSixDb                      matlab.ui.control.Label
        TimeTenDb                      matlab.ui.control.Label
        TimeTwentyDb                   matlab.ui.control.Label
        FreqBandWidth                  matlab.ui.control.Label
        FreqCenter                     matlab.ui.control.Label
    end

    properties (Access = public)
        L = 5; %Time in seconds
        n = 200; %Sampling
        dx;
        T;
        F;
    end

    methods (Access = private)
        %For time domain - Faster and assumes only one signal
        function [t1,t2] = FindDB(app,DB,signal)
            [NA,t1] = min(abs(signal(1:round(length(signal)/2))-DB));
            [NA,t2] = min(abs(signal(round(length(signal)/2)+1:end)-DB));
            t2 = t2 + floor(length(signal)/2)+1;
            if t2 > length(signal)
                t1 = 50;
                t2 = 50;
            end
        end
        
        %Frequency Domain, there are replicates that are created arbitrarily
        function freqpixels = FreqWidths(app,DB,signal)
            temp = signal;
            temp(temp>0) = 100; %Arbitrary high value
            freqpixels = find((signal - DB) < 10);
            for i = 2:length(freqpixels)
                if (freqpixels(i)-freqpixels(i-1)) == 1
                    freqpixels(i-1) = [];
                end
            end
        end
        
        function pbw = percentBW(app,DB,signal,freq)
           temp = signal(1:round(length(signal)/2));
           [m, index] = max(temp);
           temp(temp >= DB) = 1;
           temp(temp < DB) = 0;
           indicies = find(temp == 1);
           pbw = (freq(indicies(end))-freq(indicies(1)))/(-1*freq(index));
        end
        
        function allOff(app)
            app.ToneBurstFreqSlider.Enable = 'off';
            app.ToneBurstFreqEditField.Enable = 'off';
            app.GaussianVarSlider.Enable = 'off';
            app.GaussianVarEditField.Enable = 'off';
            app.GaussianModulationSlider.Enable = 'off';
            app.GaussianModulationEditField.Enable = 'off';
            app.SuperGaussianVarSlider.Enable = 'off';
            app.SuperGaussianVarEditField.Enable = 'off';
            app.SuperGaussianModulationSlider.Enable = 'off';
            app.SuperGaussianKnob.Enable = 'off';
            app.SuperGaussianModulationEditField.Enable = 'off';
            app.DeltaCenterSlider.Enable = 'off';
            app.DeltaCenterEditField.Enable = 'off';
        end
        
        function smoothed = smoothSideLobes(app,DB,signal,dx)
            %signal = signal(round(length(signal)/2)+1:end); %Only handle half of the signal cause symmetric
            temp = signal;
            temp(temp >= DB) = 0; %Smooth everything below a certain DB value, else value doesn't get used
            smoothed = zeros(1,length(temp)-dx+1); %Preallocate (the array is shorten due to averaging)
            for i = 1:dx %Dx chooses size of averaging window
               smoothed = smoothed+temp(i:(end-dx+i)); %Sliding summation of arrays containing values shifted by one.
            end
            smoothed = 1/dx*smoothed; %Normalize
            smoothed = smoothdata(smoothed,'gaussian',2);
            smoothed = repelem(smoothed,[ones(1,length(smoothed)-1),length(signal)-length(smoothed)+1]); %Replicate last element to make original length
            %smoothed(smoothed >= DB) = 0; %Remove smoothed gaussian
            signal(signal < DB) = 0; %Remove side lobes
            smoothed = smoothed + signal; %Add in new ones
            %smoothed
        end


    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Generate Global Properties
            app.dx = app.L/app.n;
            app.T = 0:app.dx:app.L-app.dx;
            app.F = -1/(2*app.dx):1/app.L:1/(2*app.dx)-1/app.L;
            
            Ft = @(x) ifftshift(fft(fftshift(x)))*app.dx;
            %Note you do not want to over sample time domain as this decreases resolution in frequency domain
            %delta_t*delta_f = 2*pi/N, where N is the number of points used
            S = @(f) cos(f*app.T); %Tone Burst

            f = app.ToneBurstFreqSlider.Value;
            app.TimeAxes.reset  
            %Plot Time Domain with two y axes -> One linear scaling, the other in DB for the envelop
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,S(f),'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            set(app.TimeAxes,{'ycolor'},{'b'})
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(S(f)))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            %line(app.TimeAxes,[0,5],[-6,-6],'Color','k','LineStyle','--')
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            legend(app.TimeAxes,'Signal', 'Envelope')
            
            %Determine -6db,-10db,-20db points
            app.TimeSixDb.Text = ' ';
            app.TimeTenDb.Text = ' ';
            app.TimeTwentyDb.Text = ' ';
            
            app.FreqAxes.reset
            %Plot Freq Domain -> CFT
            transform = zeros(1,app.n)-60;
            [minValPos, ClosestIndexPos] = min(abs(app.F-f*ones(size(app.F))));
            [minValNeg, ClosestIndexNeg] = min(abs(app.F+f*ones(size(app.F))));
            transform(1,[ClosestIndexNeg,ClosestIndexPos]) = mag2db(1);
            
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,50],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            set(app.FreqAxes,{'ycolor'},{'b'})
            legend(app.FreqAxes,'Transform','-6DB')
            
            %Determine Percent Bandwidth and Center Frequency
            app.FreqBandWidth.Text = 'Bandwidth: 0%';
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Turn on only relavent parameters
            app.allOff();
            app.ToneBurstFreqSlider.Enable = 'on';
            app.ToneBurstFreqEditField.Enable = 'on';
        end

        % Value changed function: InputSignalListBox
        function InputSignalListBoxValueChanged(app, event)
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx; %fft. Note I pad the FFT by twice the length b/c fft is a circular convolution. This prevents issues by wrapping
            value = app.InputSignalListBox.Value;
            if value(1) == 'T'
                S = @(f) cos(f*app.T); %Tone Burst
                f = app.ToneBurstFreqSlider.Value;
                
                %Generate signal, fft and scale to db
                signal = S(f);
                transform = mag2db(abs(Ft(S(f))));
                
                app.TimeAxes.reset  
                %Plot Time Domain
                yyaxis(app.TimeAxes,'left')
                plot(app.TimeAxes,app.T,signal,'b');
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                yyaxis(app.TimeAxes,'right')
                plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
                ylabel(app.TimeAxes,'DB','Color','r')
                set(app.TimeAxes,'YLim',[-60 0])
                set(app.TimeAxes,{'ycolor'},{'r'})
                
                %Determine -6db,-10db,-20db points
                app.TimeSixDb.Text = ' ';
                app.TimeTenDb.Text = ' ';
                app.TimeTwentyDb.Text = ' ';
                    
                app.FreqAxes.reset
                %Plot Freq Domain
                transform = zeros(1,app.n)-60;
                [minValPos, ClosestIndexPos] = min(abs(app.F-f*ones(size(app.F))));
                [minValNeg, ClosestIndexNeg] = min(abs(app.F+f*ones(size(app.F))));
                transform(1,[ClosestIndexNeg,ClosestIndexPos]) = mag2db(1);
                plot(app.F,transform, 'Parent', app.FreqAxes)
                line(app.FreqAxes,[-50,50],[-6,-6],'Color','k','LineStyle','--')
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Decibel')
                
                %Determine Percent Bandwidth and Center Frequency
                app.FreqBandWidth.Text = 'Bandwidth: 0%';
                app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
                
                %Turn on only relavent parameters
                app.allOff();
                app.ToneBurstFreqSlider.Enable = 'on';
                app.ToneBurstFreqEditField.Enable = 'on';
                
            elseif value(1) == 'G'
                G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*app.T);
                v = app.GaussianVarSlider.Value;
                f = app.GaussianModulationSlider.Value;
                %Generate signal, fft and scale to db
                signal = G(v,f);
                transform = abs(Ft(G(v,f)));
                %transform = app.smoothSideLobes(max(transform)/1000,transform,2);
                transform = mag2db(transform./max(transform));
                %transform = app.smoothSideLobes(-70,transform,2);
                
                app.TimeAxes.reset  
                %Plot Time Domain
                yyaxis(app.TimeAxes,'left')
                plot(app.TimeAxes,app.T,signal,'b');
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                
                yyaxis(app.TimeAxes,'right')
                plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
                ylabel(app.TimeAxes,'DB','Color','r')
                set(app.TimeAxes,'YLim',[-60 0])
                set(app.TimeAxes,{'ycolor'},{'r'})
                
                %Determine -6db,-10db,-20db points
                [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
                app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
                app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
                app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
                line(app.FreqAxes,[-30,24],[-6,-6],'Color','k','LineStyle','--')
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Decibel')
                
                %Determine Percent Bandwidth and Center Frequency
                pbw = app.percentBW(-6,transform,app.F);
                app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
                app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
                
                %Turn on relevant parameters
                app.allOff();
                app.GaussianVarSlider.Enable = 'on';
                app.GaussianVarEditField.Enable = 'on';
                app.GaussianModulationSlider.Enable = 'on';
                app.GaussianModulationEditField.Enable = 'on';
                
            elseif value(1) == 'S'
                SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
                v = app.SuperGaussianVarSlider.Value;
                o = str2num(app.SuperGaussianKnob.Value);
                f = app.SuperGaussianModulationSlider.Value;
                
                %Generate signal, fft and scale to db
                signal = SG(v,o,f);
                transform = mag2db(abs(Ft(SG(v,o,f))));
                transform = transform(floor(n/2)+2:floor(3*n/2)+1);
                
                app.TimeAxes.reset  
                %Plot Time Domain
                yyaxis(app.TimeAxes,'left')
                plot(app.TimeAxes,app.T,signal,'b');
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                
                yyaxis(app.TimeAxes,'right')
                plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
                ylabel(app.TimeAxes,'DB','Color','r')
                set(app.TimeAxes,'YLim',[-60 0])
                set(app.TimeAxes,{'ycolor'},{'r'})
                
                %Determine -6db,-10db,-20db points
                [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
                app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
                app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
                app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
                line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Decibel')
                
                %Determine Percent Bandwidth and Center Frequency
                pbw = app.percentBW(-6,transform,app.F);
                app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
                app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
                
                %Relevant Parameter
                app.allOff();
                app.SuperGaussianVarSlider.Enable = 'on';
                app.SuperGaussianVarEditField.Enable = 'on';
                app.SuperGaussianModulationSlider.Enable = 'on';
                app.SuperGaussianKnob.Enable = 'on';
                app.SuperGaussianModulationEditField.Enable = 'on';
                
           elseif value(1) == 'D'
                t = app.DeltaCenterSlider.Value; %Get center
                [minVal, minIndex] = min(abs(t-app.T));
                delta = zeros(size(app.T)); %Define zeros
                delta(minIndex) = 1; %Assign single point to one
                
                app.TimeAxes.reset  
                %Plot Time Domain
                plot(app.T,delta,'Parent', app.TimeAxes,'Color','b')
                title(app.TimeAxes,'Time Domain')
                xlabel(app.TimeAxes,'Time (uS)')
                ylabel(app.TimeAxes,'Amplitude')
                
                %Determine -6db,-10db,-20db points
                app.TimeSixDb.Text = ' ';
                app.TimeTenDb.Text = ' ';
                app.TimeTwentyDb.Text = ' ';
                
                app.FreqAxes.reset
                %Plot Freq Domain
                plot(app.F,zeros(1,n),'Parent',app.FreqAxes,'Color','b')
                title(app.FreqAxes,'Fourier Transform')
                xlabel(app.FreqAxes,'Frequency (MHz)')
                ylabel(app.FreqAxes,'Decibel')
                
                %Determine Percent Bandwidth and Center Frequency
                app.FreqBandWidth.Text = 'Bandwidth: inf';
                app.FreqCenter.Text = '';
                
                %Relevant Parameters
                app.allOff();
                app.DeltaCenterSlider.Enable = 'on';
                app.DeltaCenterEditField.Enable = 'on';
            end
        end

        % Value changed function: DeltaCenterSlider
        function DeltaCenterSliderValueChanged(app, event)
            n = 200;
            value = app.DeltaCenterSlider.Value;
            [minVal, minIndex] = min(abs(value-app.T));
            delta = zeros(size(app.T)); %Define zeros
            delta(minIndex) = 1; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,delta,'Parent', app.TimeAxes,'Color','b')
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            %Determine -6db,-10db,-20db points
            app.TimeSixDb.Text = ' ';
            app.TimeTenDb.Text = ' ';
            app.TimeTwentyDb.Text = ' ';
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,zeros(1,n),'Parent',app.FreqAxes,'Color','b')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            app.FreqBandWidth.Text = 'Bandwidth: inf';
            app.FreqCenter.Text = '';
            
            %Update Edit Field
            app.DeltaCenterEditField.Value = value;
            app.InputSignalListBox.Value = 'Delta';
        end

        % Value changed function: DeltaCenterEditField
        function DeltaCenterEditFieldValueChanged(app, event)
            n = 200;
            value = app.DeltaCenterEditField.Value;
            [minVal, minIndex] = min(abs(value-app.T));
            delta = zeros(size(app.T)); %Define zeros
            delta(minIndex) = 1; %Assign single point to one
            
            app.TimeAxes.reset  
            %Plot Time Domain
            plot(app.T,delta,'Parent', app.TimeAxes,'Color','b')
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            %Determine -6db,-10db,-20db points
            app.TimeSixDb.Text = ' ';
            app.TimeTenDb.Text = ' ';
            app.TimeTwentyDb.Text = ' ';
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,zeros(1,n),'Parent',app.FreqAxes,'Color','b')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            app.FreqBandWidth.Text = 'Bandwidth: inf';
            app.FreqCenter.Text = ' ';
            
            %Update Slider
            app.DeltaCenterSlider.Value = value;
            app.InputSignalListBox.Value = 'Delta';
            
            app.FreqBandWidth.Text = 'Bandwidth: inf';
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
        end

        % Value changed function: ToneBurstFreqSlider
        function ToneBurstFreqSliderValueChanged(app, event)
            n = 200;
            value = app.ToneBurstFreqSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx; 
            S = @(f) cos(f*app.T); %Tone Burst
            f = value;
            
            %Generate signal, fft and scale to db
            signal = S(f);
            transform = mag2db(abs(Ft(S(f))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            app.TimeSixDb.Text = ' ';
            app.TimeTenDb.Text = ' ';
            app.TimeTwentyDb.Text = ' ';
            
            app.FreqAxes.reset
            %Plot Freq Domain -> CFT
            transform = zeros(1,n)-60;
            [minValPos, ClosestIndexPos] = min(abs(app.F-f*ones(size(app.F))));
            [minValNeg, ClosestIndexNeg] = min(abs(app.F+f*ones(size(app.F))));
            transform(1,[ClosestIndexNeg,ClosestIndexPos]) = mag2db(1);
            
            plot(app.F,transform, 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            line(app.FreqAxes,[-50,50],[-6,-6],'Color','k','LineStyle','--')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Update Edit Field
            app.ToneBurstFreqEditField.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
            
            app.FreqBandWidth.Text = 'Bandwidth: 0%';
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
        end

        % Value changed function: ToneBurstFreqEditField
        function ToneBurstFreqEditFieldValueChanged(app, event)
            n = 200;
            value = app.ToneBurstFreqSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx; 
            S = @(f) sin(f*app.T); %Tone Burst
            f = value;
            
            %Generate signal, fft and scale to db
            signal = S(f);
            transform = mag2db(abs(Ft(S(f))));
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            app.FreqAxes.reset
            transform = zeros(1,n)-60;
            [minValPos, ClosestIndexPos] = min(abs(app.F-f*ones(size(app.F))));
            [minValNeg, ClosestIndexNeg] = min(abs(app.F+f*ones(size(app.F))));
            transform(1,[ClosestIndexNeg,ClosestIndexPos]) = mag2db(1);
            
            %Determine -6db,-10db,-20db points
            app.TimeSixDb.Text = ' ';
            app.TimeTenDb.Text = ' ';
            app.TimeTwentyDb.Text = ' ';
            
            plot(app.F,transform, 'Parent', app.FreqAxes)
            title(app.FreqAxes,'Fourier Transform')
            line(app.FreqAxes,[-50,50],[-6,-6],'Color','k','LineStyle','--')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Update Edit Field
            app.ToneBurstFreqSlider.Value = value;
            app.InputSignalListBox.Value = 'Tone Burst';
            
            app.FreqBandWidth.Text = 'Bandwidth: 0%';
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
        end

        % Value changed function: GaussianVarSlider
        function GaussianVarSliderValueChanged(app, event)
            n = 200;
            value = app.GaussianVarSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*app.T);
            v = value;
            f = app.GaussianModulationSlider.Value;
            %Generate signal, fft and scale to db
            signal = G(v,f);
            transform = mag2db(abs(Ft(G(v,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);

            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
        
            %Update Edit Field
            app.GaussianVarEditField.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: GaussianVarEditField
        function GaussianVarEditFieldValueChanged(app, event)
            n = 200;
            value = app.GaussianVarEditField.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*app.T);
            v = value;
            f = app.GaussianModulationSlider.Value;
            %Generate signal, fft and scale to db
            signal = G(v,f);
            transform = mag2db(abs(Ft(G(v,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')   
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
        
            %Update Slider
            app.GaussianVarSlider.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: SuperGaussianVarSlider
        function SuperGaussianVarSliderValueChanged(app, event)
            n = 200;
            value = app.SuperGaussianVarSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
            v = value;
            o = str2num(app.SuperGaussianKnob.Value);
            f = app.SuperGaussianModulationSlider.Value;
            
            %Generate signal, fft and scale to db
            signal = SG(v,o,f);
            transform = mag2db(abs(Ft(SG(v,o,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,50],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.SuperGaussianVarEditField.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianVarEditField
        function SuperGaussianVarEditFieldValueChanged(app, event)
            n = 200;
            value = app.SuperGaussianVarEditField.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
            v = value;
            o = str2num(app.SuperGaussianKnob.Value);
            f = app.SuperGaussianModulationSlider.Value;
            
            %Generate signal, fft and scale to db
            signal = SG(v,o,f);
            transform = mag2db(abs(Ft(SG(v,o,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.SuperGaussianVarSlider.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianKnob
        function SuperGaussianKnobValueChanged(app, event)
            n = 200;
            value = str2num(app.SuperGaussianKnob.Value);
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
            v = app.SuperGaussianVarSlider.Value;
            o = value;
            f = app.SuperGaussianModulationSlider.Value;
            
            %Generate signal, fft and scale to db
            signal = SG(v,o,f);
            transform = mag2db(abs(Ft(SG(v,o,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: GaussianModulationSlider
        function GaussianModulationSliderValueChanged(app, event)
            n = 200;
            value = app.GaussianModulationSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*app.T);
            v = app.GaussianVarSlider.Value;
            f = value;
            %Generate signal, fft and scale to db
            signal = G(v,f);
            transform = mag2db(abs(Ft(G(v,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.GaussianModulationEditField.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: GaussianModulationEditField
        function GaussianModulationEditFieldValueChanged(app, event)
            n = 200;
            value = app.GaussianModulationEditField.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            G = @(v,f) 1*exp(-((app.T-2.5)./sqrt(v)).^2).*cos(f*app.T);
            v = app.GaussianVarSlider.Value;
            f = value;
            %Generate signal, fft and scale to db
            signal = G(v,f);
            transform = mag2db(abs(Ft(G(v,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.GaussianModulationSlider.Value = value;
            app.InputSignalListBox.Value = 'Gaussian';
        end

        % Value changed function: SuperGaussianModulationSlider
        function SuperGaussianModulationSliderValueChanged(app, event)
            n = 200;
            value = app.SuperGaussianModulationSlider.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
            v = app.SuperGaussianVarEditField.Value;
            o = str2num(app.SuperGaussianKnob.Value);
            f = value;
            
            %Generate signal, fft and scale to db
            signal = SG(v,o,f);
            transform = mag2db(abs(Ft(SG(v,o,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.SuperGaussianModulationEditField.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end

        % Value changed function: SuperGaussianModulationEditField
        function SuperGaussianModulationEditFieldValueChanged(app, event)
            n = 200;
            value = app.SuperGaussianModulationEditField.Value;
            Ft = @(x ) ifftshift(fft(fftshift(x)))*app.dx;
            SG = @(v,o,f) (1*exp(-((app.T-2.5)./sqrt(v)).^(2*o))).*cos(f*app.T); %Standard gaussian to the o-th power
            v = app.SuperGaussianVarEditField.Value;
            o = str2num(app.SuperGaussianKnob.Value);
            f = value;
            
            %Generate signal, fft and scale to db
            signal = SG(v,o,f);
            transform = mag2db(abs(Ft(SG(v,o,f))));
            transform = transform(floor(n/2)+2:floor(3*n/2)+1);
            
            app.TimeAxes.reset  
            %Plot Time Domain
            yyaxis(app.TimeAxes,'left')
            plot(app.TimeAxes,app.T,signal,'b');
            title(app.TimeAxes,'Time Domain')
            xlabel(app.TimeAxes,'Time (uS)')
            ylabel(app.TimeAxes,'Amplitude')
            
            yyaxis(app.TimeAxes,'right')
            plot(app.T,mag2db(abs(hilbert(signal))),'Color','r','LineStyle','--','Parent',app.TimeAxes);
            ylabel(app.TimeAxes,'DB','Color','r')
            set(app.TimeAxes,'YLim',[-60 0])
            set(app.TimeAxes,{'ycolor'},{'r'})
            
            %Determine -6db,-10db,-20db points
            [t1,t2] = app.FindDB(-6,mag2db(abs(hilbert(signal))));
            app.TimeSixDb.Text = ['-6DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-10,mag2db(abs(hilbert(signal))));
            app.TimeTenDb.Text = ['-10DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            [t1,t2] = app.FindDB(-20,mag2db(abs(hilbert(signal))));
            app.TimeTwentyDb.Text = ['-20DB Wid: ' num2str(round((app.T(t2)-app.T(t1)),1)) ' sec'];
            
            app.FreqAxes.reset
            %Plot Freq Domain
            plot(app.F,transform, 'Parent', app.FreqAxes,'Color','b')
            line(app.FreqAxes,[-50,24],[-6,-6],'Color','k','LineStyle','--')
            title(app.FreqAxes,'Fourier Transform')
            xlabel(app.FreqAxes,'Frequency (MHz)')
            ylabel(app.FreqAxes,'Decibel')
            
            %Determine Percent Bandwidth and Center Frequency
            pbw = app.percentBW(-6,transform,app.F);
            app.FreqBandWidth.Text = ['Bandwidth: ' num2str(pbw*100) '%'];
            app.FreqCenter.Text = ['Center Freq: +- ' num2str(f) ' MHz'];
            
            %Update Edit Field
            app.SuperGaussianModulationSlider.Value = value;
            app.InputSignalListBox.Value = 'Super-Gaussian';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TimeAxes = uiaxes(app.UIFigure);
            title(app.TimeAxes, 'Time Domain')
            xlabel(app.TimeAxes, 'Time')
            ylabel(app.TimeAxes, 'Signal')
            app.TimeAxes.XLim = [0 1];
            app.TimeAxes.YLim = [0 1];
            app.TimeAxes.ZLim = [0 1];
            app.TimeAxes.CLim = [0 1];
            app.TimeAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TimeAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.TimeAxes.ZTick = [0 0.5 1];
            app.FreqAxes = uiaxes(app.UIFigure);
            title(app.FreqAxes, 'Fourier Transform')
            xlabel(app.FreqAxes, 'Frequency')
            ylabel(app.FreqAxes, 'Signal')
            app.FreqAxes.XLim = [0 1];
            app.FreqAxes.YLim = [0 1];
            app.FreqAxes.ZLim = [0 1];
            app.FreqAxes.CLim = [0 1];
            app.FreqAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.FreqAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FreqAxes.ZTick = [0 0.5 1];
            app.UserInputsPanel = uipanel(app.UIFigure);
            app.UserInputsPanel.Title = 'User Inputs';
            app.InputSignalListBoxLabel = uilabel(app.UserInputsPanel);
            app.InputSignalListBoxLabel.Text = {'Input'; 'Signal'};
            app.InputSignalListBox = uilistbox(app.UserInputsPanel);
            app.InputSignalListBox.Items = {'Tone Burst', 'Gaussian', 'Super-Gaussian', 'Delta'};
            app.InputSignalListBox.ValueChangedFcn = createCallbackFcn(app, @InputSignalListBoxValueChanged, true);
            app.InputSignalListBox.Value = 'Tone Burst';
            app.ToneBurstLabel = uilabel(app.UserInputsPanel);
            app.ToneBurstLabel.Text = 'Tone Burst';
            app.FrequencyMHzLabel = uilabel(app.UserInputsPanel);
            app.FrequencyMHzLabel.Text = {'Frequency'; '(MHz)'};
            app.ToneBurstFreqSlider = uislider(app.UserInputsPanel);
            app.ToneBurstFreqSlider.Limits = [1 10];
            app.ToneBurstFreqSlider.ValueChangedFcn = createCallbackFcn(app, @ToneBurstFreqSliderValueChanged, true);
            app.ToneBurstFreqSlider.Value = 5;
            app.Label = uilabel(app.UserInputsPanel);
            app.Label.Text = '';
            app.ToneBurstFreqEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.ToneBurstFreqEditField.Limits = [1 10];
            app.ToneBurstFreqEditField.ValueChangedFcn = createCallbackFcn(app, @ToneBurstFreqEditFieldValueChanged, true);
            app.ToneBurstFreqEditField.Value = 5;
            app.GaussianLabel = uilabel(app.UserInputsPanel);
            app.GaussianLabel.Text = 'Gaussian';
            app.VarianceLabel = uilabel(app.UserInputsPanel);
            app.VarianceLabel.Text = 'Variance';
            app.GaussianVarSlider = uislider(app.UserInputsPanel);
            app.GaussianVarSlider.Limits = [0.1 3];
            app.GaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianVarSliderValueChanged, true);
            app.GaussianVarSlider.Value = 1;
            app.EditField_2Label = uilabel(app.UserInputsPanel);
            app.EditField_2Label.Text = '';
            app.GaussianVarEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.GaussianVarEditField.Limits = [1 5];
            app.GaussianVarEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianVarEditFieldValueChanged, true);
            app.GaussianVarEditField.Value = 1;
            app.SuperGaussianLabel = uilabel(app.UserInputsPanel);
            app.SuperGaussianLabel.Text = 'Super-Gaussian';
            app.VarianceLabel_2 = uilabel(app.UserInputsPanel);
            app.VarianceLabel_2.Text = 'Variance';
            app.SuperGaussianVarSlider = uislider(app.UserInputsPanel);
            app.SuperGaussianVarSlider.Limits = [1 5];
            app.SuperGaussianVarSlider.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianVarSliderValueChanged, true);
            app.SuperGaussianVarSlider.Value = 1;
            app.EditField_3Label = uilabel(app.UserInputsPanel);
            app.EditField_3Label.Text = '';
            app.SuperGaussianVarEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.SuperGaussianVarEditField.Limits = [1 5];
            app.SuperGaussianVarEditField.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianVarEditFieldValueChanged, true);
            app.SuperGaussianVarEditField.Value = 1;
            app.OrderKnobLabel = uilabel(app.UserInputsPanel);
            app.OrderKnobLabel.Text = 'Order';
            app.SuperGaussianKnob = uiknob(app.UserInputsPanel, 'discrete');
            app.SuperGaussianKnob.Items = {'1', '2', '3', '4', '5'};
            app.SuperGaussianKnob.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianKnobValueChanged, true);
            app.SuperGaussianKnob.Value = '1';
            app.DeltaLabel = uilabel(app.UserInputsPanel);
            app.DeltaLabel.Text = 'Delta';
            app.CenterTimeuSLabel = uilabel(app.UserInputsPanel);
            app.CenterTimeuSLabel.Text = {'Center'; 'Time'; '(uS)'};
            app.DeltaCenterSlider = uislider(app.UserInputsPanel);
            app.DeltaCenterSlider.Limits = [0 5];
            app.DeltaCenterSlider.ValueChangedFcn = createCallbackFcn(app, @DeltaCenterSliderValueChanged, true);
            app.DeltaCenterSlider.Value = 2.5;
            app.EditField_4Label = uilabel(app.UserInputsPanel);
            app.EditField_4Label.Text = '';
            app.DeltaCenterEditField = uieditfield(app.UserInputsPanel, 'numeric');
            app.DeltaCenterEditField.Limits = [0 5];
            app.DeltaCenterEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaCenterEditFieldValueChanged, true);
            app.DeltaCenterEditField.Value = 2.5;
            app.ViewCodeButton = uibutton(app.UserInputsPanel, 'push');
            app.ViewCodeButton.Text = 'View Code';
            app.ModulationMHzSliderLabel = uilabel(app.UserInputsPanel);
            app.ModulationMHzSliderLabel.Text = {'Modulation'; '(MHz)'};
            app.GaussianModulationSlider = uislider(app.UserInputsPanel);
            app.GaussianModulationSlider.Limits = [5 10];
            app.GaussianModulationSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianModulationSliderValueChanged, true);
            app.GaussianModulationSlider.Value = 5;
            app.ModulationMHzSliderLabel_2 = uilabel(app.UserInputsPanel);
            app.ModulationMHzSliderLabel_2.Text = {'Modulation'; '(MHz)'};
            app.SuperGaussianModulationSlider = uislider(app.UserInputsPanel);
            app.SuperGaussianModulationSlider.Limits = [5 10];
            app.SuperGaussianModulationSlider.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianModulationSliderValueChanged, true);
            app.SuperGaussianModulationSlider.Value = 5;
            app.GaussianModulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussianModulationEditField.Limits = [5 10];
            app.GaussianModulationEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianModulationEditFieldValueChanged, true);
            app.GaussianModulationEditField.Value = 5;
            app.SuperGaussianModulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuperGaussianModulationEditField.Limits = [5 10];
            app.SuperGaussianModulationEditField.ValueChangedFcn = createCallbackFcn(app, @SuperGaussianModulationEditFieldValueChanged, true);
            app.SuperGaussianModulationEditField.Value = 5;
            app.TimeSixDb = uilabel(app.UIFigure);
            app.TimeTenDb = uilabel(app.UIFigure);
            app.TimeTwentyDb = uilabel(app.UIFigure);
            app.FreqBandWidth = uilabel(app.UIFigure);
            app.FreqCenter = uilabel(app.UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fourier_Transform_3_DB_Scaling_and_Labels

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end